classdef T1mappR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        T1mappUIFigure               matlab.ui.Figure
        Hyperlink                    matlab.ui.control.Hyperlink
        CreditsLabel                 matlab.ui.control.Label
        StatusLabel                  matlab.ui.control.Label
        StatusImage                  matlab.ui.control.Image
        BackgroundBox                matlab.ui.control.TextArea
        MessageBox                   matlab.ui.control.TextArea
        TabGroup4                    matlab.ui.container.TabGroup
        SequenceParametersTab        matlab.ui.container.Tab
        NrTRsViewField               matlab.ui.control.NumericEditField
        NrTRsViewFieldLabel          matlab.ui.control.Label
        TRsEditField                 matlab.ui.control.NumericEditField
        TRsEditFieldLabel            matlab.ui.control.Label
        SLTViewField                 matlab.ui.control.NumericEditField
        SLTViewFieldLabel            matlab.ui.control.Label
        AspectRatioViewField         matlab.ui.control.NumericEditField
        AspectRatioViewFieldLabel    matlab.ui.control.Label
        SlicesViewField              matlab.ui.control.NumericEditField
        SlicesViewFieldLabel         matlab.ui.control.Label
        NrDynamicsViewField          matlab.ui.control.NumericEditField
        NrDynamicsViewFieldLabel     matlab.ui.control.Label
        NrTIsViewField               matlab.ui.control.NumericEditField
        NrTIsViewFieldLabel          matlab.ui.control.Label
        dimyViewField                matlab.ui.control.NumericEditField
        dimyViewFieldLabel           matlab.ui.control.Label
        TIsEditField                 matlab.ui.control.NumericEditField
        TIsEditFieldLabel            matlab.ui.control.Label
        TI0EditField                 matlab.ui.control.NumericEditField
        TI0EditFieldLabel            matlab.ui.control.Label
        dimxViewField                matlab.ui.control.NumericEditField
        dimxViewFieldLabel           matlab.ui.control.Label
        TR0ViewField                 matlab.ui.control.NumericEditField
        TR0ViewFieldLabel            matlab.ui.control.Label
        FOVViewField                 matlab.ui.control.NumericEditField
        FOVViewFieldLabel            matlab.ui.control.Label
        DatafileEditField            matlab.ui.control.EditField
        DatafileEditFieldLabel       matlab.ui.control.Label
        TabGroup3                    matlab.ui.container.TabGroup
        T1mapTab                     matlab.ui.container.Tab
        ExportRoiDataButton          matlab.ui.control.Button
        T1ViewField2                 matlab.ui.control.EditField
        T1ViewField2Label            matlab.ui.control.Label
        T1ROIAveragedLabel           matlab.ui.control.Label
        T1ViewField1                 matlab.ui.control.EditField
        T1ViewField1Label            matlab.ui.control.Label
        T1PixelValueLabel            matlab.ui.control.Label
        YEditField                   matlab.ui.control.NumericEditField
        YEditFieldLabel              matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        XEditFieldLabel              matlab.ui.control.Label
        BrushEditField               matlab.ui.control.NumericEditField
        BrushEditFieldLabel          matlab.ui.control.Label
        ROIbutton                    matlab.ui.control.Button
        EraseButton                  matlab.ui.control.Button
        T1ColormapButtonGroup        matlab.ui.container.ButtonGroup
        lipariButton                 matlab.ui.control.RadioButton
        parulaButton                 matlab.ui.control.RadioButton
        winterButton                 matlab.ui.control.RadioButton
        T1ScaleAutoButton            matlab.ui.control.Button
        autumnButton                 matlab.ui.control.RadioButton
        T1ScaleEditField             matlab.ui.control.NumericEditField
        grayButton                   matlab.ui.control.RadioButton
        T1ScaleMaxLabel              matlab.ui.control.Label
        T1ImageSelectionPanel        matlab.ui.container.Panel
        DynamicsSlider2              matlab.ui.control.Slider
        DynamicsEditField2           matlab.ui.control.NumericEditField
        DynamicsEditField2Label      matlab.ui.control.Label
        SlicesSlider2                matlab.ui.control.Slider
        SlicesEditField2             matlab.ui.control.NumericEditField
        SlicesEditField2Label        matlab.ui.control.Label
        T1Image                      matlab.ui.control.Image
        T1MapLabel                   matlab.ui.control.Label
        T1linearPlot                 matlab.ui.control.UIAxes
        T1MapFig                     matlab.ui.control.UIAxes
        M0R2mapTab                   matlab.ui.container.Tab
        R2ColorMapButtonGroup        matlab.ui.container.ButtonGroup
        lipariButton3                matlab.ui.control.RadioButton
        parulaButton3                matlab.ui.control.RadioButton
        winterButton3                matlab.ui.control.RadioButton
        autumnButton3                matlab.ui.control.RadioButton
        grayButton3                  matlab.ui.control.RadioButton
        BrushEditField2              matlab.ui.control.NumericEditField
        BrushEditField2Label         matlab.ui.control.Label
        R2Image                      matlab.ui.control.Image
        ROIbutton2                   matlab.ui.control.Button
        EraseButton2                 matlab.ui.control.Button
        R2MapLabel                   matlab.ui.control.Label
        M0ColorMapButtonGroup        matlab.ui.container.ButtonGroup
        lipariButton2                matlab.ui.control.RadioButton
        parulaButton2                matlab.ui.control.RadioButton
        winterButton2                matlab.ui.control.RadioButton
        autumnButton2                matlab.ui.control.RadioButton
        grayButton2                  matlab.ui.control.RadioButton
        M0ImageSelectionPanel        matlab.ui.container.Panel
        DynamicsSlider3              matlab.ui.control.Slider
        DynamicsEditField3           matlab.ui.control.NumericEditField
        DynamicsEditField3Label      matlab.ui.control.Label
        SlicesSlider3                matlab.ui.control.Slider
        SlicesEditField3             matlab.ui.control.NumericEditField
        SlicesEditField3Label        matlab.ui.control.Label
        M0Image                      matlab.ui.control.Image
        M0MapLabel                   matlab.ui.control.Label
        R2MapFig                     matlab.ui.control.UIAxes
        M0MapFig                     matlab.ui.control.UIAxes
        TabGroup2                    matlab.ui.container.TabGroup
        RawImagesTab                 matlab.ui.container.Tab
        LevelSlider                  matlab.ui.control.Slider
        WindowSlider                 matlab.ui.control.Slider
        AutoLWButton                 matlab.ui.control.StateButton
        LevelEditField               matlab.ui.control.NumericEditField
        LevelEditFieldLabel          matlab.ui.control.Label
        WindowEditField              matlab.ui.control.NumericEditField
        WindowEditFieldLabel         matlab.ui.control.Label
        MaskSettingsPanel            matlab.ui.container.Panel
        OpenButton                   matlab.ui.control.Button
        CloseButton                  matlab.ui.control.Button
        DilateButton                 matlab.ui.control.Button
        ErodeButton                  matlab.ui.control.Button
        ThresholdSlider              matlab.ui.control.Slider
        thresholdEditField           matlab.ui.control.NumericEditField
        thresholdEditFieldLabel      matlab.ui.control.Label
        ImageSelectionPanel          matlab.ui.container.Panel
        DynamicsSlider1              matlab.ui.control.Slider
        DynamicsEditField1           matlab.ui.control.NumericEditField
        DynamicsEditField1Label      matlab.ui.control.Label
        InversionSlider              matlab.ui.control.Slider
        InversionEditField           matlab.ui.control.NumericEditField
        InversionEditFieldLabel      matlab.ui.control.Label
        SlicesSlider1                matlab.ui.control.Slider
        SlicesEditField1             matlab.ui.control.NumericEditField
        SlicesEditField1Label        matlab.ui.control.Label
        RawImage                     matlab.ui.control.Image
        ImageLabel                   matlab.ui.control.Label
        RawDataFig                   matlab.ui.control.UIAxes
        RecoParametersTab            matlab.ui.container.Tab
        CSregularizationPanel        matlab.ui.container.Panel
        TVdynEditField               matlab.ui.control.NumericEditField
        TVdynEditFieldLabel          matlab.ui.control.Label
        TVtiEditField                matlab.ui.control.NumericEditField
        TVtiEditFieldLabel           matlab.ui.control.Label
        LLRxyzEditField              matlab.ui.control.NumericEditField
        LLRxyzEditFieldLabel         matlab.ui.control.Label
        TVxyzEditField               matlab.ui.control.NumericEditField
        TVxyzEditFieldLabel          matlab.ui.control.Label
        WVxyzEditField               matlab.ui.control.NumericEditField
        WVxyzEditFieldLabel          matlab.ui.control.Label
        FilteringPanel               matlab.ui.container.Panel
        DeRingCheckBox               matlab.ui.control.CheckBox
        DeRingCheckBoxLabel          matlab.ui.control.Label
        DeNoiseCheckBox              matlab.ui.control.CheckBox
        DeNoiseWindowEditField       matlab.ui.control.NumericEditField
        DeNoiseCheckBoxLabel         matlab.ui.control.Label
        FitParametersTab             matlab.ui.container.Tab
        InversionTimeSelectionPanel  matlab.ui.container.Panel
        CheckBox_96                  matlab.ui.control.CheckBox
        CheckBox_88                  matlab.ui.control.CheckBox
        CheckBox_80                  matlab.ui.control.CheckBox
        CheckBox_72                  matlab.ui.control.CheckBox
        CheckBox_64                  matlab.ui.control.CheckBox
        CheckBox_56                  matlab.ui.control.CheckBox
        CheckBox_48                  matlab.ui.control.CheckBox
        CheckBox_40                  matlab.ui.control.CheckBox
        CheckBox_32                  matlab.ui.control.CheckBox
        CheckBox_24                  matlab.ui.control.CheckBox
        CheckBox_16                  matlab.ui.control.CheckBox
        CheckBox_8                   matlab.ui.control.CheckBox
        CheckBox_95                  matlab.ui.control.CheckBox
        CheckBox_87                  matlab.ui.control.CheckBox
        CheckBox_79                  matlab.ui.control.CheckBox
        CheckBox_71                  matlab.ui.control.CheckBox
        CheckBox_63                  matlab.ui.control.CheckBox
        CheckBox_55                  matlab.ui.control.CheckBox
        CheckBox_47                  matlab.ui.control.CheckBox
        CheckBox_39                  matlab.ui.control.CheckBox
        CheckBox_31                  matlab.ui.control.CheckBox
        CheckBox_23                  matlab.ui.control.CheckBox
        CheckBox_15                  matlab.ui.control.CheckBox
        CheckBox_7                   matlab.ui.control.CheckBox
        CheckBox_94                  matlab.ui.control.CheckBox
        CheckBox_86                  matlab.ui.control.CheckBox
        CheckBox_78                  matlab.ui.control.CheckBox
        CheckBox_70                  matlab.ui.control.CheckBox
        CheckBox_62                  matlab.ui.control.CheckBox
        CheckBox_54                  matlab.ui.control.CheckBox
        CheckBox_46                  matlab.ui.control.CheckBox
        CheckBox_38                  matlab.ui.control.CheckBox
        CheckBox_30                  matlab.ui.control.CheckBox
        CheckBox_22                  matlab.ui.control.CheckBox
        CheckBox_14                  matlab.ui.control.CheckBox
        CheckBox_6                   matlab.ui.control.CheckBox
        CheckBox_93                  matlab.ui.control.CheckBox
        CheckBox_85                  matlab.ui.control.CheckBox
        CheckBox_77                  matlab.ui.control.CheckBox
        CheckBox_69                  matlab.ui.control.CheckBox
        CheckBox_61                  matlab.ui.control.CheckBox
        CheckBox_53                  matlab.ui.control.CheckBox
        CheckBox_45                  matlab.ui.control.CheckBox
        CheckBox_37                  matlab.ui.control.CheckBox
        CheckBox_29                  matlab.ui.control.CheckBox
        CheckBox_21                  matlab.ui.control.CheckBox
        CheckBox_13                  matlab.ui.control.CheckBox
        CheckBox_5                   matlab.ui.control.CheckBox
        CheckBox_92                  matlab.ui.control.CheckBox
        CheckBox_84                  matlab.ui.control.CheckBox
        CheckBox_76                  matlab.ui.control.CheckBox
        CheckBox_68                  matlab.ui.control.CheckBox
        CheckBox_60                  matlab.ui.control.CheckBox
        CheckBox_52                  matlab.ui.control.CheckBox
        CheckBox_44                  matlab.ui.control.CheckBox
        CheckBox_36                  matlab.ui.control.CheckBox
        CheckBox_28                  matlab.ui.control.CheckBox
        CheckBox_20                  matlab.ui.control.CheckBox
        CheckBox_12                  matlab.ui.control.CheckBox
        CheckBox_4                   matlab.ui.control.CheckBox
        CheckBox_91                  matlab.ui.control.CheckBox
        CheckBox_83                  matlab.ui.control.CheckBox
        CheckBox_75                  matlab.ui.control.CheckBox
        CheckBox_67                  matlab.ui.control.CheckBox
        CheckBox_59                  matlab.ui.control.CheckBox
        CheckBox_51                  matlab.ui.control.CheckBox
        CheckBox_43                  matlab.ui.control.CheckBox
        CheckBox_35                  matlab.ui.control.CheckBox
        CheckBox_27                  matlab.ui.control.CheckBox
        CheckBox_19                  matlab.ui.control.CheckBox
        CheckBox_11                  matlab.ui.control.CheckBox
        CheckBox_3                   matlab.ui.control.CheckBox
        CheckBox_90                  matlab.ui.control.CheckBox
        CheckBox_82                  matlab.ui.control.CheckBox
        CheckBox_74                  matlab.ui.control.CheckBox
        CheckBox_66                  matlab.ui.control.CheckBox
        CheckBox_58                  matlab.ui.control.CheckBox
        CheckBox_50                  matlab.ui.control.CheckBox
        CheckBox_42                  matlab.ui.control.CheckBox
        CheckBox_34                  matlab.ui.control.CheckBox
        CheckBox_26                  matlab.ui.control.CheckBox
        CheckBox_18                  matlab.ui.control.CheckBox
        CheckBox_10                  matlab.ui.control.CheckBox
        CheckBox_2                   matlab.ui.control.CheckBox
        CheckBox_89                  matlab.ui.control.CheckBox
        CheckBox_81                  matlab.ui.control.CheckBox
        CheckBox_73                  matlab.ui.control.CheckBox
        CheckBox_65                  matlab.ui.control.CheckBox
        CheckBox_57                  matlab.ui.control.CheckBox
        CheckBox_49                  matlab.ui.control.CheckBox
        CheckBox_41                  matlab.ui.control.CheckBox
        CheckBox_33                  matlab.ui.control.CheckBox
        CheckBox_25                  matlab.ui.control.CheckBox
        CheckBox_17                  matlab.ui.control.CheckBox
        CheckBox_9                   matlab.ui.control.CheckBox
        CheckBox_1                   matlab.ui.control.CheckBox
        GoodnessOfFitPanel           matlab.ui.container.Panel
        Rsquare                      matlab.ui.control.NumericEditField
        RsquareLabel                 matlab.ui.control.Label
        DynamicsSelectionPanel       matlab.ui.container.Panel
        LastDynamicEditField         matlab.ui.control.NumericEditField
        LastDynamicEditFieldLabel    matlab.ui.control.Label
        FirstDynamicEditField        matlab.ui.control.NumericEditField
        FirstDynamicEditFieldLabel   matlab.ui.control.Label
        SliceSelectionPanel          matlab.ui.container.Panel
        LastSliceEditField           matlab.ui.control.NumericEditField
        LastSliceEditFieldLabel      matlab.ui.control.Label
        FirstSliceEditField          matlab.ui.control.NumericEditField
        FirstSliceEditFieldLabel     matlab.ui.control.Label
        AppSettingsTab               matlab.ui.container.Tab
        ImportSettingsPanel          matlab.ui.container.Panel
        ToggleReorderCheckBox        matlab.ui.control.CheckBox
        ToggleReorderCheckBoxLabel   matlab.ui.control.Label
        SoundsPanel                  matlab.ui.container.Panel
        AutoSoundsCheckBox           matlab.ui.control.CheckBox
        AutoSoundsCheckBoxLabel      matlab.ui.control.Label
        ColorsPanel                  matlab.ui.container.Panel
        ColorsButtonGroup            matlab.ui.container.ButtonGroup
        BlackWhiteButton             matlab.ui.control.RadioButton
        ClassicButton                matlab.ui.control.RadioButton
        TabGroup1                    matlab.ui.container.TabGroup
        DashboardTab                 matlab.ui.container.Tab
        ExitButton                   matlab.ui.control.Button
        Lamp7                        matlab.ui.control.Lamp
        ExportGIFButton              matlab.ui.control.Button
        Lamp6                        matlab.ui.control.Lamp
        ExportDCMButton              matlab.ui.control.Button
        AbortFitButton               matlab.ui.control.Button
        EstimatedFitTimeViewField    matlab.ui.control.EditField
        ModelBasedCheckBox           matlab.ui.control.CheckBox
        ModelBasedCheckBoxLabel      matlab.ui.control.Label
        Lamp5                        matlab.ui.control.Lamp
        FitT1Button                  matlab.ui.control.Button
        FitProgressGauge             matlab.ui.control.LinearGauge
        FitProgressLabel             matlab.ui.control.Label
        Lamp4                        matlab.ui.control.Lamp
        MaskButton                   matlab.ui.control.Button
        AbortRegButton               matlab.ui.control.Button
        EstimatedRegTimeViewField    matlab.ui.control.EditField
        RegistrationDropDown         matlab.ui.control.DropDown
        Lamp3                        matlab.ui.control.Lamp
        RegisterButton               matlab.ui.control.Button
        RegProgressGauge             matlab.ui.control.LinearGauge
        RegProgressLabel             matlab.ui.control.Label
        CSrecoCheckBox               matlab.ui.control.CheckBox
        CSrecoCheckBoxLabel          matlab.ui.control.Label
        Lamp2                        matlab.ui.control.Lamp
        RecoImagesButton             matlab.ui.control.Button
        Lamp1                        matlab.ui.control.Lamp
        LoadMRDfileButton            matlab.ui.control.Button
        T1Label                      matlab.ui.control.Label
        TulpLogo                     matlab.ui.control.Image
        TMappingLabel                matlab.ui.control.Label
        MRSImage                     matlab.ui.control.Image
    end


    % --------------------------------------------------------------------------------------
    %
    %   Inversion recovery T1 mapping tool
    %   
    %   Gustav Strijkers
    %   Amsterdam UMC
    %   May 2026
    %
    %
    %
    %   Version 1.0; aug 2022
    %   - intial version
    %   - model-based reco
    %   - CS reconstruction 
    %
    %   Version 1.1; Feb 2023
    %   - some bug fixes
    %
    %   Version 1.2; Apr 2023
    %   - bug fix import segmented k-space data
    %   - deRing and deNoise options
    %   - Improved GIF, DICOM export (sept 23)
    %
    %   Version 1.3; Sept 2023
    %   - Lots of improvements and bug fixes
    %
    %   Version 1.4: Oct 2023
    %   - CS reco parameters menu
    %
    %   Version 1.5: Dec 2023
    %   - Color schemes
    %   - Bug fixes
    %   - Removed dependencies on fitting toolbox
    %   - Version control improvements
    %   - GPU bart, if available
    %
    %   Version 1.6: July 2025
    %   - Some bug fixing, minor adjustements
    %   - Matlab 2025a version
    %
    %   Version 1.7: July 2025
    %   - Support for variable TR
    %   - lipari color map
    %   - bug fix multislice T1 map
    %
    
    
    %#ok<*ST2NM> 
    
    properties (Access = public)

        % App version
        appVersion = "1.7.7"        % App version 
        bartVersion                 % Bart version
       
        % File variables
        appDnir                      % Application directory
        oldDir                      % Starting directory
        mrdImportPath               % Dicom import path
        dicomExportPath             % Dicom export path
        gifExportPath               % gif export path
        roiExportPath               % roi export path
        exportDir                   % Dicom/Nifti export path   
        dcmPath                     % dcm path
        dataFile                    % raw data file
        data                        % raw k-space data
        tag                         % file tag for export of dicom files
        
        % Flags
        GPUpresentFlag = false      % GPU present true/false
        validDataFlag               % valid data file true/false
        validRecoFlag               % valid reco true/false
        validFitFlag                % valid fit true/false
        validRegFlag                % valid registration true/false
        mrdFlag                     % standard mrd import path already defined true or false
        multiCoilFlag               % multi-coil data
        bartDetectedFlag            % bart toolbox available true/false
        mobaFlag                    % model-based reco button on/off true/false
        roiFlag                     % roi drawing true or false
        abortRegFlag                % abort registration
        abortFitFlag                % abort fit

        % Scan properties
        dimensions                  % image dimensions
        parameters                  % image paramters
        dimx                        % nr points readout
        dimy                        % nt points phase encoding
        fov                         % field of view
        aspectRatio                 % field of view ratio
        sl                          % slice thickness
        ns                          % number of slices
        nd                          % number of dynamics
        tr                          % repetition time
        trs                         % all repetition times
        ntr                         % number of repetition times
        ti                          % inversion time
        tis                         % all inversion times
        nir                         % number of inversion times
        esp                         % inversion time spacing
        orientation                 % phase orientation
        nrCoils                     % number of RF receiver coils
        dataType                    % type of data, e.g. spin-echo or grad-echo
        oversample                  % readout oversample factor
      
        % Images & plots
        images                      % reconstructed raw images (inversion, x, y, slice, dynamic)
        threshold                   % segmentation threshold (ns,nd,1)
        mask                        % binary image with segmentation (x, y, slice, dynamic)
        norm                        % image normalization factor
        scalefactor                 % image intensity scale factor
        txt1                        % legend label
        txt2                        % legend label
        crossT1                     % cross-hair
        crossM0                     % cross-hair
        roiPoints                   % roi point coordinates
        roiT1                       % roi drawing object
        roiM0                       % roi drawing object
        structuringElement2D        % 2D structuring element

        % Fitting
        t1map                       % resulting t1map (x, y, slice, dynamic)
        t1cmap                      % T1 color mapping
        m0map                       % resulting m0map (x, y, slice, dynamic)
        m0cmap                      % M0 color mapping
        r2map                       % resulting R2 map
        r2cmap                      % R2 color mapping
        tiSelection                 % TE selection array
        roiIntData                  % roi intensities as function of TI
        roiIntDataSigned            % roi intensities as function of TI signed
        
        % Colors
        brightred                   % red
        red                         % red
        brightgreen                 % green
        green                       % green
        yellow                      % yellow
        orange                      % orange
        blue                        % blue
        grey                        % grey
        lightblue                   % lightblue
        guiBlack                    % app black
        guiGrey                     % app grey
        guiMiddleGrey               % app middle grey
        guiDarkGrey                 % app dark grey
        guiWhite                    % app white
        guiBlackBlue                % app black/blueish

        % Sounds
        warningSound                % warning sound
        errorSound                  % error sound
        
    end


    methods (Access = private)
        
        
        %------------------------------------------------
        %
        %  GUI layout
        %
        %------------------------------------------------


        % Traffic lights
        function SetLights(app,code)
            
            if code(1) == 0 app.Lamp1.Color = app.red; end %#ok<*SEPEX> 
            if code(2) == 0 app.Lamp2.Color = app.red; end
            if code(3) == 0 app.Lamp3.Color = app.red; end
            if code(4) == 0 app.Lamp4.Color = app.red; end
            if code(5) == 0 app.Lamp5.Color = app.red; end
            if code(6) == 0 app.Lamp6.Color = app.red; end
            if code(7) == 0 app.Lamp7.Color = app.red; end
                
            if code(1) == 1 app.Lamp1.Color = app.green; end
            if code(2) == 1 app.Lamp2.Color = app.green; end
            if code(3) == 1 app.Lamp3.Color = app.green; end
            if code(4) == 1 app.Lamp4.Color = app.green; end
            if code(5) == 1 app.Lamp5.Color = app.green; end
            if code(6) == 1 app.Lamp6.Color = app.green; end
            if code(7) == 1 app.Lamp7.Color = app.green; end
            
            drawnow;
            
        end
        
        
        % Buttons on/off
        function SetButtons(app,code)
        
            if code(1) == 0 app.LoadMRDfileButton.Enable = 'off';   else app.LoadMRDfileButton.Enable = 'on';   end
            if code(2) == 0 app.RecoImagesButton.Enable = 'off';          else app.RecoImagesButton.Enable = 'on';          end
            if code(3) == 0
                app.RegisterButton.Enable = 'off';
                app.RegistrationDropDown.Enable = 'off';
            else
                app.RegisterButton.Enable = 'on';
                app.RegistrationDropDown.Enable = 'on';
            end
            if code(4) == 0 app.MaskButton.Enable = 'off';     else app.MaskButton.Enable = 'on';     end
            if code(5) == 0 app.FitT1Button.Enable = 'off';         else app.FitT1Button.Enable = 'on';         end
            if code(6) == 0 app.ExportDCMButton.Enable = 'off';   else app.ExportDCMButton.Enable = 'on';   end
            if code(7) == 0 app.ExportGIFButton.Enable = 'off';     else app.ExportGIFButton.Enable = 'on';     end
            if code(8) == 0 app.ExitButton.Enable = 'off';          else app.ExitButton.Enable = 'on';          end
        
            drawnow;
            
        end


        % Set application colors
        function SetAppColorsFcn(app, guiColors)

            backgroundColor = guiColors{1}; 
            fontColor = guiColors{2}; 
            % boxColor = guiColors{3}; 
            gaugeColor = guiColors{4}; 
            gaugeBackgroundColor = guiColors{5}; 
            figureColor = guiColors{6}; 
            movieFigureColor = guiColors{7}; 
            % gridColor = guiColors{8}; 
         
            % Panels
            app.T1mappUIFigure.Color = backgroundColor;
            app.DashboardTab.BackgroundColor = backgroundColor;
            app.RawImagesTab.BackgroundColor = backgroundColor;
            app.RecoParametersTab.BackgroundColor = backgroundColor;
            app.FitParametersTab.BackgroundColor = backgroundColor;
            app.AppSettingsTab.BackgroundColor = backgroundColor;
            app.SequenceParametersTab.BackgroundColor = backgroundColor;
            app.T1mapTab.BackgroundColor = backgroundColor;
            app.M0R2mapTab.BackgroundColor = backgroundColor;
            app.ImageSelectionPanel.BackgroundColor = backgroundColor;
            app.MaskSettingsPanel.BackgroundColor = backgroundColor;
            app.SliceSelectionPanel.BackgroundColor = backgroundColor;
            app.DynamicsSelectionPanel.BackgroundColor = backgroundColor;
            app.GoodnessOfFitPanel.BackgroundColor = backgroundColor;
            app.InversionTimeSelectionPanel.BackgroundColor = backgroundColor;
            app.ColorsButtonGroup.BackgroundColor = backgroundColor;
            app.T1ImageSelectionPanel.BackgroundColor = backgroundColor;
            app.T1ColormapButtonGroup.BackgroundColor = backgroundColor;
            app.M0ImageSelectionPanel.BackgroundColor = backgroundColor;
            app.M0ColorMapButtonGroup.BackgroundColor = backgroundColor;
            app.R2ColorMapButtonGroup.BackgroundColor = backgroundColor;
            app.CSregularizationPanel.BackgroundColor = backgroundColor;
            app.FilteringPanel.BackgroundColor = backgroundColor;
            app.ColorsPanel.BackgroundColor = backgroundColor;
            app.SoundsPanel.BackgroundColor = backgroundColor;
            app.ImportSettingsPanel.BackgroundColor = backgroundColor;

            % Gauges
            app.RegProgressGauge.FontColor = gaugeColor;
            app.RegProgressGauge.BackgroundColor = gaugeBackgroundColor;
            app.FitProgressGauge.FontColor = gaugeColor;
            app.FitProgressGauge.BackgroundColor = gaugeBackgroundColor;

            % Texts
            app.CSrecoCheckBoxLabel.FontColor = fontColor;
            app.ModelBasedCheckBoxLabel.FontColor = fontColor;
            app.SlicesEditField1Label.FontColor = fontColor;
            app.InversionEditFieldLabel.FontColor = fontColor;
            app.DynamicsEditField1Label.FontColor = fontColor;
            app.thresholdEditFieldLabel.FontColor = fontColor;
            app.WindowEditFieldLabel.FontColor = fontColor;
            app.LevelEditFieldLabel.FontColor = fontColor;
            app.DeNoiseCheckBoxLabel.FontColor = fontColor;
            app.DeRingCheckBoxLabel.FontColor = fontColor;
            app.WVxyzEditFieldLabel.FontColor = fontColor;
            app.TVxyzEditFieldLabel.FontColor = fontColor;
            app.LLRxyzEditFieldLabel.FontColor = fontColor;
            app.TVtiEditFieldLabel.FontColor = fontColor;
            app.TVdynEditFieldLabel.FontColor = fontColor;
            app.FirstSliceEditFieldLabel.FontColor = fontColor;
            app.LastSliceEditFieldLabel.FontColor = fontColor;
            app.FirstDynamicEditFieldLabel.FontColor = fontColor;
            app.LastDynamicEditFieldLabel.FontColor = fontColor;
            app.RsquareLabel.FontColor = fontColor;
            app.AutoSoundsCheckBoxLabel.FontColor = fontColor;
            app.ToggleReorderCheckBoxLabel.FontColor = fontColor;
            app.BlackWhiteButton.FontColor = fontColor;
            app.ClassicButton.FontColor = fontColor;
            app.DatafileEditFieldLabel.FontColor = fontColor;
            app.TR0ViewFieldLabel.FontColor = fontColor;
            app.TRsEditFieldLabel.FontColor = fontColor;
            app.NrTRsViewFieldLabel.FontColor = fontColor;
            app.TI0EditFieldLabel.FontColor = fontColor;
            app.TIsEditFieldLabel.FontColor = fontColor;
            app.NrTIsViewFieldLabel.FontColor = fontColor;
            app.NrDynamicsViewFieldLabel.FontColor = fontColor;
            app.AspectRatioViewFieldLabel.FontColor = fontColor;
            app.FOVViewFieldLabel.FontColor = fontColor;
            app.dimxViewFieldLabel.FontColor = fontColor;
            app.dimyViewFieldLabel.FontColor = fontColor;
            app.SlicesViewFieldLabel.FontColor = fontColor;
            app.SLTViewFieldLabel.FontColor = fontColor;
            app.SlicesEditField2Label.FontColor = fontColor;
            app.DynamicsEditField2Label.FontColor = fontColor;
            app.T1ScaleMaxLabel.FontColor = fontColor;
            app.grayButton.FontColor = fontColor;
            app.autumnButton.FontColor = fontColor;
            app.winterButton.FontColor = fontColor;
            app.parulaButton.FontColor = fontColor;
            app.lipariButton.FontColor = fontColor;
            app.BrushEditFieldLabel.FontColor = fontColor;
            app.XEditFieldLabel.FontColor = fontColor;
            app.YEditFieldLabel.FontColor = fontColor;
            app.T1ViewField1Label.FontColor = fontColor;
            app.T1ViewField2Label.FontColor = fontColor;
            app.T1PixelValueLabel.FontColor = fontColor;
            app.T1ROIAveragedLabel.FontColor = fontColor;
            app.ImageLabel.FontColor = fontColor;
            app.T1MapLabel.FontColor = fontColor;
            app.M0MapLabel.FontColor = fontColor;
            app.R2MapLabel.FontColor = fontColor;
            app.SlicesEditField3Label.FontColor = fontColor;
            app.DynamicsEditField3Label.FontColor = fontColor;
            app.grayButton2.FontColor = fontColor;
            app.autumnButton2.FontColor = fontColor;
            app.winterButton2.FontColor = fontColor;
            app.parulaButton2.FontColor = fontColor;
            app.lipariButton2.FontColor = fontColor;
            app.BrushEditField2Label.FontColor = fontColor;
            app.grayButton3.FontColor = fontColor;
            app.autumnButton3.FontColor = fontColor;
            app.winterButton3.FontColor = fontColor;
            app.parulaButton3.FontColor = fontColor;
            app.lipariButton3.FontColor = fontColor;

            % Inversion buttons
            app.CheckBox_1.FontColor = fontColor;    app.CheckBox_17.FontColor = fontColor;   app.CheckBox_33.FontColor = fontColor;   app.CheckBox_49.FontColor = fontColor;   app.CheckBox_65.FontColor = fontColor;   app.CheckBox_81.FontColor = fontColor;
            app.CheckBox_2.FontColor = fontColor;    app.CheckBox_18.FontColor = fontColor;   app.CheckBox_34.FontColor = fontColor;   app.CheckBox_50.FontColor = fontColor;   app.CheckBox_66.FontColor = fontColor;   app.CheckBox_82.FontColor = fontColor;
            app.CheckBox_3.FontColor = fontColor;    app.CheckBox_19.FontColor = fontColor;   app.CheckBox_35.FontColor = fontColor;   app.CheckBox_51.FontColor = fontColor;   app.CheckBox_67.FontColor = fontColor;   app.CheckBox_83.FontColor = fontColor;
            app.CheckBox_4.FontColor = fontColor;    app.CheckBox_20.FontColor = fontColor;   app.CheckBox_36.FontColor = fontColor;   app.CheckBox_52.FontColor = fontColor;   app.CheckBox_68.FontColor = fontColor;   app.CheckBox_84.FontColor = fontColor;
            app.CheckBox_5.FontColor = fontColor;    app.CheckBox_21.FontColor = fontColor;   app.CheckBox_37.FontColor = fontColor;   app.CheckBox_53.FontColor = fontColor;   app.CheckBox_69.FontColor = fontColor;   app.CheckBox_85.FontColor = fontColor;
            app.CheckBox_6.FontColor = fontColor;    app.CheckBox_22.FontColor = fontColor;   app.CheckBox_38.FontColor = fontColor;   app.CheckBox_54.FontColor = fontColor;   app.CheckBox_70.FontColor = fontColor;   app.CheckBox_86.FontColor = fontColor;
            app.CheckBox_7.FontColor = fontColor;    app.CheckBox_23.FontColor = fontColor;   app.CheckBox_39.FontColor = fontColor;   app.CheckBox_55.FontColor = fontColor;   app.CheckBox_71.FontColor = fontColor;   app.CheckBox_87.FontColor = fontColor;
            app.CheckBox_8.FontColor = fontColor;    app.CheckBox_24.FontColor = fontColor;   app.CheckBox_40.FontColor = fontColor;   app.CheckBox_56.FontColor = fontColor;   app.CheckBox_72.FontColor = fontColor;   app.CheckBox_88.FontColor = fontColor;
            app.CheckBox_9.FontColor = fontColor;    app.CheckBox_25.FontColor = fontColor;   app.CheckBox_41.FontColor = fontColor;   app.CheckBox_57.FontColor = fontColor;   app.CheckBox_73.FontColor = fontColor;   app.CheckBox_89.FontColor = fontColor;
            app.CheckBox_10.FontColor = fontColor;   app.CheckBox_26.FontColor = fontColor;   app.CheckBox_42.FontColor = fontColor;   app.CheckBox_58.FontColor = fontColor;   app.CheckBox_74.FontColor = fontColor;   app.CheckBox_90.FontColor = fontColor;
            app.CheckBox_11.FontColor = fontColor;   app.CheckBox_27.FontColor = fontColor;   app.CheckBox_43.FontColor = fontColor;   app.CheckBox_59.FontColor = fontColor;   app.CheckBox_75.FontColor = fontColor;   app.CheckBox_91.FontColor = fontColor;
            app.CheckBox_12.FontColor = fontColor;   app.CheckBox_28.FontColor = fontColor;   app.CheckBox_44.FontColor = fontColor;   app.CheckBox_60.FontColor = fontColor;   app.CheckBox_76.FontColor = fontColor;   app.CheckBox_92.FontColor = fontColor;
            app.CheckBox_13.FontColor = fontColor;   app.CheckBox_29.FontColor = fontColor;   app.CheckBox_45.FontColor = fontColor;   app.CheckBox_61.FontColor = fontColor;   app.CheckBox_77.FontColor = fontColor;   app.CheckBox_93.FontColor = fontColor;
            app.CheckBox_14.FontColor = fontColor;   app.CheckBox_30.FontColor = fontColor;   app.CheckBox_46.FontColor = fontColor;   app.CheckBox_62.FontColor = fontColor;   app.CheckBox_78.FontColor = fontColor;   app.CheckBox_94.FontColor = fontColor;
            app.CheckBox_15.FontColor = fontColor;   app.CheckBox_31.FontColor = fontColor;   app.CheckBox_47.FontColor = fontColor;   app.CheckBox_63.FontColor = fontColor;   app.CheckBox_79.FontColor = fontColor;   app.CheckBox_95.FontColor = fontColor;
            app.CheckBox_16.FontColor = fontColor;   app.CheckBox_32.FontColor = fontColor;   app.CheckBox_48.FontColor = fontColor;   app.CheckBox_64.FontColor = fontColor;   app.CheckBox_80.FontColor = fontColor;   app.CheckBox_96.FontColor = fontColor;

            % Images and figures
            app.RawDataFig.Color = movieFigureColor;
            app.RawDataFig.XColor = movieFigureColor;
            app.RawDataFig.YColor = movieFigureColor;

            app.T1MapFig.Color = movieFigureColor;
            app.T1MapFig.XColor = movieFigureColor;
            app.T1MapFig.YColor = movieFigureColor;

            app.M0MapFig.Color = movieFigureColor;
            app.M0MapFig.XColor = movieFigureColor;
            app.M0MapFig.YColor = movieFigureColor;

            app.R2MapFig.Color = movieFigureColor;
            app.R2MapFig.XColor = movieFigureColor;
            app.R2MapFig.YColor = movieFigureColor;

            app.T1linearPlot.Color = figureColor;
            app.T1linearPlot.XColor = fontColor;
            app.T1linearPlot.YColor = fontColor;


        end % SetAppColorsFcn


    function CheckVersionFnc(app)

    try
        currentVersion = string(app.appVersion);
        newestVersions = {"2.7","2.6","2.5","2.4","2.3","2.2","2.1","2.0","1.9","1.8","1.7","1.6","1.5","1.4","1.3","1.2","1.1","1.0"};

        webTxt = "";
        ver = 0;

        opts = weboptions( ...
            'Timeout', 5, ...
            'ContentType', 'text', ...
            'CharacterEncoding', 'UTF-8');

        while webTxt == "" && ver < numel(newestVersions)
            ver = ver + 1;
            newVersionFile = "https://github.com/Moby1971/T1mappingR-v" + ...
                string(newestVersions{ver}) + ...
                "/blob/main/appversion.txt";
            try
                tStart = tic;
                webTxt = strtrim(string(webread(char(newVersionFile), opts)));
                app.TextMessage("Response received in " + string(round(toc(tStart),2)) + " s");
            catch ME1
                webTxt = "";
            end
        end

        if webTxt == ""
            ME = MException('T1mappR:error','Could not retrieve version file ...');
            throw(ME);
        end

        tok = regexp(webTxt, 'T1mappR_version\s*=\s*([0-9]+(?:\.[0-9]+){1,3})', 'tokens', 'once','ignorecase');
        if isempty(tok)
            ME = MException('T1mappR:error','Could not parse version string from file ...');
            throw(ME);
        end

        newVersion = string(tok{1});

        app.TextMessage("Current version = " + currentVersion);
        app.TextMessage("Newest version = " + newVersion);

        if isVersionNewer(newVersion, currentVersion)
            app.TextMessage("A new version of the T1 mapping app is available ...");
            app.TextMessage("Visit https://github.com/Moby1971/ to update ...");
        else
            app.TextMessage("The T1 mapping app is up to date ...");
        end

    catch ME

        app.TextMessage(ME.message);
        app.TextMessage("Could not check T1 mapping app version ...");

    end

    function tf = isVersionNewer(newVersion, currentVersion)
        a = sscanf(char(newVersion), '%d.%d.%d').';
        b = sscanf(char(currentVersion), '%d.%d.%d').';

        a(end+1:3) = 0;
        b(end+1:3) = 0;

        tf = false;
        for k = 1:3
            if a(k) > b(k)
                tf = true;
                return
            elseif a(k) < b(k)
                return
            end
        end
    end

end % CheckVersionFnc


        % Switch fit parameters buttons on/off
        function SetFitButtons(app,code)
            
            if code == 0
                
                app.FirstSliceEditField.Enable = 'off';
                app.FirstSliceEditFieldLabel.Enable = 'off';
                app.LastSliceEditField.Enable = 'off';
                app.LastSliceEditFieldLabel.Enable = 'off';
                app.FirstDynamicEditField.Enable = 'off';
                app.FirstDynamicEditFieldLabel.Enable = 'off';
                app.LastDynamicEditField.Enable = 'off';
                app.LastDynamicEditFieldLabel.Enable = 'off';
                app.Rsquare.Enable = 'off';
                app.RsquareLabel.Enable = 'off';
                app.AbortFitButton.Enable = 'off';
                app.CSrecoCheckBox.Enable = 'off';
                app.CSrecoCheckBoxLabel.Enable = 'off';

                app.DeNoiseCheckBox.Enable = 'off';
                app.DeNoiseCheckBoxLabel.Enable = 'off';
                app.DeNoiseWindowEditField.Enable = 'off';
                app.DeRingCheckBox.Enable = 'off';
                app.DeRingCheckBoxLabel.Enable = 'off';

                app.WVxyzEditField.Enable = 'off';
                app.WVxyzEditFieldLabel.Enable = 'off';
                app.TVxyzEditField.Enable = 'off';
                app.TVxyzEditFieldLabel.Enable = 'off';
                app.LLRxyzEditField.Enable = 'off';
                app.LLRxyzEditFieldLabel.Enable = 'off';
                app.TVtiEditField.Enable = 'off';
                app.TVtiEditFieldLabel.Enable = 'off';
                app.TVdynEditField.Enable = 'off';
                app.TVdynEditFieldLabel.Enable = 'off';

                app.ModelBasedCheckBox.Enable = 'off';
                app.ModelBasedCheckBoxLabel.Enable = 'off';

                app.CheckBox_1.Enable = 'off';  app.CheckBox_2.Enable = 'off';  app.CheckBox_3.Enable = 'off';  app.CheckBox_4.Enable = 'off';
                app.CheckBox_5.Enable = 'off';  app.CheckBox_6.Enable = 'off';  app.CheckBox_7.Enable = 'off';  app.CheckBox_8.Enable = 'off';
                app.CheckBox_9.Enable = 'off';  app.CheckBox_10.Enable = 'off'; app.CheckBox_11.Enable = 'off'; app.CheckBox_12.Enable = 'off';
                app.CheckBox_13.Enable = 'off'; app.CheckBox_14.Enable = 'off'; app.CheckBox_15.Enable = 'off'; app.CheckBox_16.Enable = 'off';
                app.CheckBox_17.Enable = 'off'; app.CheckBox_18.Enable = 'off'; app.CheckBox_19.Enable = 'off'; app.CheckBox_20.Enable = 'off';
                app.CheckBox_21.Enable = 'off'; app.CheckBox_22.Enable = 'off'; app.CheckBox_23.Enable = 'off'; app.CheckBox_24.Enable = 'off';
                app.CheckBox_25.Enable = 'off'; app.CheckBox_26.Enable = 'off'; app.CheckBox_27.Enable = 'off'; app.CheckBox_28.Enable = 'off';
                app.CheckBox_29.Enable = 'off'; app.CheckBox_30.Enable = 'off'; app.CheckBox_31.Enable = 'off'; app.CheckBox_32.Enable = 'off';
                app.CheckBox_33.Enable = 'off'; app.CheckBox_34.Enable = 'off'; app.CheckBox_35.Enable = 'off'; app.CheckBox_36.Enable = 'off';
                app.CheckBox_37.Enable = 'off'; app.CheckBox_38.Enable = 'off'; app.CheckBox_39.Enable = 'off'; app.CheckBox_40.Enable = 'off';
                app.CheckBox_41.Enable = 'off'; app.CheckBox_42.Enable = 'off'; app.CheckBox_43.Enable = 'off'; app.CheckBox_44.Enable = 'off';
                app.CheckBox_45.Enable = 'off'; app.CheckBox_46.Enable = 'off'; app.CheckBox_47.Enable = 'off'; app.CheckBox_48.Enable = 'off';
                app.CheckBox_49.Enable = 'off'; app.CheckBox_50.Enable = 'off'; app.CheckBox_51.Enable = 'off'; app.CheckBox_52.Enable = 'off';
                app.CheckBox_53.Enable = 'off'; app.CheckBox_54.Enable = 'off'; app.CheckBox_55.Enable = 'off'; app.CheckBox_56.Enable = 'off';
                app.CheckBox_57.Enable = 'off'; app.CheckBox_58.Enable = 'off'; app.CheckBox_59.Enable = 'off'; app.CheckBox_60.Enable = 'off';
                app.CheckBox_61.Enable = 'off'; app.CheckBox_62.Enable = 'off'; app.CheckBox_63.Enable = 'off'; app.CheckBox_64.Enable = 'off';
                app.CheckBox_65.Enable = 'off'; app.CheckBox_66.Enable = 'off'; app.CheckBox_67.Enable = 'off'; app.CheckBox_68.Enable = 'off';
                app.CheckBox_69.Enable = 'off'; app.CheckBox_70.Enable = 'off'; app.CheckBox_71.Enable = 'off'; app.CheckBox_72.Enable = 'off';
                app.CheckBox_73.Enable = 'off'; app.CheckBox_74.Enable = 'off'; app.CheckBox_75.Enable = 'off'; app.CheckBox_76.Enable = 'off';
                app.CheckBox_77.Enable = 'off'; app.CheckBox_78.Enable = 'off'; app.CheckBox_79.Enable = 'off'; app.CheckBox_80.Enable = 'off';
                app.CheckBox_81.Enable = 'off'; app.CheckBox_82.Enable = 'off'; app.CheckBox_83.Enable = 'off'; app.CheckBox_84.Enable = 'off';
                app.CheckBox_85.Enable = 'off'; app.CheckBox_86.Enable = 'off'; app.CheckBox_87.Enable = 'off'; app.CheckBox_88.Enable = 'off';
                app.CheckBox_89.Enable = 'off'; app.CheckBox_90.Enable = 'off'; app.CheckBox_91.Enable = 'off'; app.CheckBox_92.Enable = 'off';
                app.CheckBox_93.Enable = 'off'; app.CheckBox_94.Enable = 'off'; app.CheckBox_95.Enable = 'off'; app.CheckBox_96.Enable = 'off';
             
            else
            
                if app.ns > 1
                    app.FirstSliceEditField.Enable = 'on';
                    app.FirstSliceEditFieldLabel.Enable = 'on';
                    app.LastSliceEditField.Enable = 'on';
                    app.LastSliceEditFieldLabel.Enable = 'on';
                else
                    app.FirstSliceEditField.Enable = 'off';
                    app.FirstSliceEditFieldLabel.Enable = 'off';
                    app.LastSliceEditField.Enable = 'off';
                    app.LastSliceEditFieldLabel.Enable = 'off';
                end

                if app.nd > 1
                    app.FirstDynamicEditField.Enable = 'on';
                    app.FirstDynamicEditFieldLabel.Enable = 'on';
                    app.LastDynamicEditField.Enable = 'on';
                    app.LastDynamicEditFieldLabel.Enable = 'on';
                else
                    app.FirstDynamicEditField.Enable = 'off';
                    app.FirstDynamicEditFieldLabel.Enable = 'off';
                    app.LastDynamicEditField.Enable = 'off';
                    app.LastDynamicEditFieldLabel.Enable = 'off';
                end

                app.Rsquare.Enable = 'on';
                app.RsquareLabel.Enable = 'on';
                app.AbortFitButton.Enable = 'on';

                if app.bartDetectedFlag && app.validDataFlag
                    app.CSrecoCheckBox.Enable = 'on';
                    app.CSrecoCheckBoxLabel.Enable = 'on';
                    app.WVxyzEditField.Enable = 'on';
                    app.WVxyzEditFieldLabel.Enable = 'on';
                    app.TVxyzEditField.Enable = 'on';
                    app.TVxyzEditFieldLabel.Enable = 'on';
                    app.LLRxyzEditField.Enable = 'on';
                    app.LLRxyzEditFieldLabel.Enable = 'on';
                    app.TVtiEditField.Enable = 'on';
                    app.TVtiEditFieldLabel.Enable = 'on';
                    app.TVdynEditField.Enable = 'on';
                    app.TVdynEditFieldLabel.Enable = 'on';
                else
                    app.CSrecoCheckBox.Enable = 'off';
                    app.CSrecoCheckBoxLabel.Enable = 'off';
                    app.WVxyzEditField.Enable = 'off';
                    app.WVxyzEditFieldLabel.Enable = 'off';
                    app.TVxyzEditField.Enable = 'off';
                    app.TVxyzEditFieldLabel.Enable = 'off';
                    app.LLRxyzEditField.Enable = 'off';
                    app.LLRxyzEditFieldLabel.Enable = 'off';
                    app.TVtiEditField.Enable = 'off';
                    app.TVtiEditFieldLabel.Enable = 'off';
                    app.TVdynEditField.Enable = 'off';
                    app.TVdynEditFieldLabel.Enable = 'off';
                end

                app.DeNoiseCheckBox.Enable = 'on';
                app.DeNoiseCheckBoxLabel.Enable = 'on';
                app.DeNoiseWindowEditField.Enable = 'on';
                app.DeRingCheckBox.Enable = 'on';
                app.DeRingCheckBoxLabel.Enable = 'on';

                if app.bartDetectedFlag && app.validRecoFlag && app.dataType == "IR"
                    app.ModelBasedCheckBox.Enable = 'on';
                    app.ModelBasedCheckBoxLabel.Enable = 'on';
                else
                    app.ModelBasedCheckBox.Enable = 'off';
                    app.ModelBasedCheckBoxLabel.Enable = 'off';
                    app.ModelBasedCheckBox.Value = 0;
                end
                
                app.CheckBox_1.Enable = 'on';
                if app.nir > 1 app.CheckBox_2.Enable = 'on'; end;        if app.nir > 32 app.CheckBox_33.Enable = 'on'; end
                if app.nir > 2 app.CheckBox_3.Enable = 'on'; end;        if app.nir > 33 app.CheckBox_34.Enable = 'on'; end
                if app.nir > 3 app.CheckBox_4.Enable = 'on'; end;        if app.nir > 34 app.CheckBox_35.Enable = 'on'; end
                if app.nir > 4 app.CheckBox_5.Enable = 'on'; end;        if app.nir > 35 app.CheckBox_36.Enable = 'on'; end
                if app.nir > 5 app.CheckBox_6.Enable = 'on'; end;        if app.nir > 36 app.CheckBox_37.Enable = 'on'; end
                if app.nir > 6 app.CheckBox_7.Enable = 'on'; end;        if app.nir > 37 app.CheckBox_38.Enable = 'on'; end
                if app.nir > 7 app.CheckBox_8.Enable = 'on'; end;        if app.nir > 38 app.CheckBox_39.Enable = 'on'; end
                if app.nir > 8 app.CheckBox_9.Enable = 'on'; end;        if app.nir > 39 app.CheckBox_40.Enable = 'on'; end
                if app.nir > 9 app.CheckBox_10.Enable = 'on'; end;       if app.nir > 40 app.CheckBox_41.Enable = 'on'; end
                if app.nir > 10 app.CheckBox_11.Enable = 'on'; end;      if app.nir > 41 app.CheckBox_42.Enable = 'on'; end
                if app.nir > 11 app.CheckBox_12.Enable = 'on'; end;      if app.nir > 42 app.CheckBox_43.Enable = 'on'; end
                if app.nir > 12 app.CheckBox_13.Enable = 'on'; end;      if app.nir > 43 app.CheckBox_44.Enable = 'on'; end
                if app.nir > 13 app.CheckBox_14.Enable = 'on'; end;      if app.nir > 44 app.CheckBox_45.Enable = 'on'; end
                if app.nir > 14 app.CheckBox_15.Enable = 'on'; end;      if app.nir > 45 app.CheckBox_46.Enable = 'on'; end
                if app.nir > 15 app.CheckBox_16.Enable = 'on'; end;      if app.nir > 46 app.CheckBox_47.Enable = 'on'; end
                if app.nir > 16 app.CheckBox_17.Enable = 'on'; end;      if app.nir > 47 app.CheckBox_48.Enable = 'on'; end
                if app.nir > 17 app.CheckBox_18.Enable = 'on'; end;      if app.nir > 48 app.CheckBox_49.Enable = 'on'; end
                if app.nir > 18 app.CheckBox_19.Enable = 'on'; end;      if app.nir > 49 app.CheckBox_50.Enable = 'on'; end
                if app.nir > 19 app.CheckBox_20.Enable = 'on'; end;      if app.nir > 50 app.CheckBox_51.Enable = 'on'; end
                if app.nir > 20 app.CheckBox_21.Enable = 'on'; end;      if app.nir > 51 app.CheckBox_52.Enable = 'on'; end
                if app.nir > 21 app.CheckBox_22.Enable = 'on'; end;      if app.nir > 52 app.CheckBox_53.Enable = 'on'; end
                if app.nir > 22 app.CheckBox_23.Enable = 'on'; end;      if app.nir > 53 app.CheckBox_54.Enable = 'on'; end
                if app.nir > 23 app.CheckBox_24.Enable = 'on'; end;      if app.nir > 54 app.CheckBox_55.Enable = 'on'; end
                if app.nir > 24 app.CheckBox_25.Enable = 'on'; end;      if app.nir > 55 app.CheckBox_56.Enable = 'on'; end
                if app.nir > 25 app.CheckBox_26.Enable = 'on'; end;      if app.nir > 56 app.CheckBox_57.Enable = 'on'; end
                if app.nir > 26 app.CheckBox_27.Enable = 'on'; end;      if app.nir > 57 app.CheckBox_58.Enable = 'on'; end
                if app.nir > 27 app.CheckBox_28.Enable = 'on'; end;      if app.nir > 58 app.CheckBox_59.Enable = 'on'; end
                if app.nir > 28 app.CheckBox_29.Enable = 'on'; end;      if app.nir > 59 app.CheckBox_60.Enable = 'on'; end
                if app.nir > 29 app.CheckBox_30.Enable = 'on'; end;      if app.nir > 60 app.CheckBox_61.Enable = 'on'; end
                if app.nir > 30 app.CheckBox_31.Enable = 'on'; end;      if app.nir > 61 app.CheckBox_62.Enable = 'on'; end
                if app.nir > 31 app.CheckBox_32.Enable = 'on'; end;      if app.nir > 62 app.CheckBox_63.Enable = 'on'; end
                if app.nir > 71 app.CheckBox_64.Enable = 'on'; end;      if app.nir > 63 app.CheckBox_64.Enable = 'on'; end
                if app.nir > 72 app.CheckBox_64.Enable = 'on'; end;      if app.nir > 64 app.CheckBox_64.Enable = 'on'; end
                if app.nir > 73 app.CheckBox_64.Enable = 'on'; end;      if app.nir > 65 app.CheckBox_64.Enable = 'on'; end
                if app.nir > 74 app.CheckBox_64.Enable = 'on'; end;      if app.nir > 66 app.CheckBox_64.Enable = 'on'; end
                if app.nir > 75 app.CheckBox_64.Enable = 'on'; end;      if app.nir > 67 app.CheckBox_64.Enable = 'on'; end
                if app.nir > 76 app.CheckBox_64.Enable = 'on'; end;      if app.nir > 68 app.CheckBox_64.Enable = 'on'; end
                if app.nir > 77 app.CheckBox_64.Enable = 'on'; end;      if app.nir > 69 app.CheckBox_64.Enable = 'on'; end
                if app.nir > 78 app.CheckBox_64.Enable = 'on'; end;      if app.nir > 70 app.CheckBox_64.Enable = 'on'; end
                if app.nir > 79 app.CheckBox_64.Enable = 'on'; end;      if app.nir > 86 app.CheckBox_64.Enable = 'on'; end
                if app.nir > 80 app.CheckBox_64.Enable = 'on'; end;      if app.nir > 87 app.CheckBox_64.Enable = 'on'; end
                if app.nir > 81 app.CheckBox_64.Enable = 'on'; end;      if app.nir > 88 app.CheckBox_64.Enable = 'on'; end
                if app.nir > 82 app.CheckBox_64.Enable = 'on'; end;      if app.nir > 89 app.CheckBox_64.Enable = 'on'; end
                if app.nir > 83 app.CheckBox_64.Enable = 'on'; end;      if app.nir > 90 app.CheckBox_64.Enable = 'on'; end
                if app.nir > 84 app.CheckBox_64.Enable = 'on'; end;      if app.nir > 91 app.CheckBox_64.Enable = 'on'; end
                if app.nir > 85 app.CheckBox_64.Enable = 'on'; end;      if app.nir > 92 app.CheckBox_64.Enable = 'on'; end
                if app.nir > 93 app.CheckBox_64.Enable = 'on'; end;      if app.nir > 95 app.CheckBox_64.Enable = 'on'; end
                if app.nir > 94 app.CheckBox_64.Enable = 'on'; end;      if app.nir > 96 app.CheckBox_64.Enable = 'on'; end     
            
            end
                                   
            drawnow;
            
        end
        
        
        % Switch image selection buttons on/off
        function SetImageSelectionButtons(app,code)
            
            if code == 0

                app.SlicesEditField1.Enable = 'off';
                app.SlicesEditField1Label.Enable = 'off';
                app.SlicesSlider1.Enable = 'off';
                app.InversionEditField.Enable = 'off';
                app.InversionEditFieldLabel.Enable = 'off';
                app.InversionSlider.Enable = 'off';
                app.DynamicsEditField1.Enable = 'off';
                app.DynamicsEditField1Label.Enable = 'off';
                app.DynamicsSlider1.Enable = 'off';

                app.WindowEditField.Enable = 'off';
                app.WindowEditFieldLabel.Enable = 'off';
                app.WindowSlider.Enable = 'off';
                app.LevelEditField.Enable = 'off';
                app.LevelEditFieldLabel.Enable = 'off';
                app.LevelSlider.Enable = 'off';
                app.AutoLWButton.Enable = 'off';

            else

                if app.ns > 1
                    app.SlicesEditField1.Enable = 'on';
                    app.SlicesEditField1Label.Enable = 'on';
                    app.SlicesSlider1.Enable = 'on';
                else
                    app.SlicesEditField1.Enable = 'off';
                    app.SlicesEditField1Label.Enable = 'off';
                    app.SlicesSlider1.Enable = 'off';
                end
                if app.nd > 1
                    app.DynamicsEditField1.Enable = 'on';
                    app.DynamicsEditField1Label.Enable = 'on';
                    app.DynamicsSlider1.Enable = 'on';
                else
                    app.DynamicsEditField1.Enable = 'off';
                    app.DynamicsEditField1Label.Enable = 'off';
                    app.DynamicsSlider1.Enable = 'off';
                end
                app.InversionEditField.Enable = 'on';
                app.InversionEditFieldLabel.Enable = 'on';
                app.InversionSlider.Enable = 'on';

                app.WindowEditField.Enable = 'on';
                app.WindowEditFieldLabel.Enable = 'on';
                app.WindowSlider.Enable = 'on';
                app.LevelEditField.Enable = 'on';
                app.LevelEditFieldLabel.Enable = 'on';
                app.LevelSlider.Enable = 'on';
                app.AutoLWButton.Enable = 'on';

            end

            drawnow;
            
        end
        
        
        % Switch image segmentation buttons on/off
        function SetSegmentationButtons(app,code)
            
            if code == 0
                app.thresholdEditField.Enable = 'off';
                app.thresholdEditFieldLabel.Enable = 'off';
                app.ThresholdSlider.Enable = 'off';
                app.ErodeButton.Enable = 'off';
                app.DilateButton.Enable = 'off';
                app.CloseButton.Enable = 'off';
                app.OpenButton.Enable = 'off';
            else
                app.thresholdEditField.Enable = 'on';
                app.thresholdEditFieldLabel.Enable = 'on';
                app.ThresholdSlider.Enable = 'on';
                app.ErodeButton.Enable = 'on';
                app.DilateButton.Enable = 'on';
                app.CloseButton.Enable = 'on';
                app.OpenButton.Enable = 'on';
            end
            
            drawnow;
            
        end
        
        
        % Switch T1 map buttons on/off
        function SetMapButtons(app,code)
            
            if code == 0

                app.SlicesEditField2.Enable = 'off';
                app.SlicesEditField2Label.Enable = 'off';
                app.SlicesSlider2.Enable = 'off';
                app.SlicesEditField3.Enable = 'off';
                app.SlicesEditField3Label.Enable = 'off';
                app.SlicesSlider3.Enable = 'off';
                app.T1ScaleEditField.Enable = 'off';
                app.T1ScaleMaxLabel.Enable = 'off';
                app.T1ScaleAutoButton.Enable = 'off';
                app.DynamicsEditField2.Enable = 'off';
                app.DynamicsEditField2Label.Enable = 'off';
                app.DynamicsEditField3.Enable = 'off';
                app.DynamicsEditField3Label.Enable = 'off';
                app.DynamicsSlider2.Enable = 'off';
                app.DynamicsSlider3.Enable = 'off';
                app.grayButton.Enable = 'off';
                app.autumnButton.Enable = 'off';
                app.winterButton.Enable = 'off';
                app.parulaButton.Enable = 'off';
                app.lipariButton.Enable = 'off';
                app.AbortFitButton.Enable = 'off';
                app.EraseButton.Enable = 'off';
                app.ROIbutton.Enable = 'off';
                app.BrushEditField.Enable = 'off';
                app.BrushEditFieldLabel.Enable = 'off';
                app.ExportRoiDataButton.Enable = 'off';
                app.grayButton2.Enable = 'off';
                app.autumnButton2.Enable = 'off';
                app.winterButton2.Enable = 'off';
                app.parulaButton2.Enable = 'off';
                app.lipariButton2.Enable = 'off';
                app.EraseButton2.Enable = 'off';
                app.ROIbutton2.Enable = 'off';
                app.BrushEditField2.Enable = 'off';
                app.BrushEditField2Label.Enable = 'off';
                app.grayButton3.Enable = 'off';
                app.autumnButton3.Enable = 'off';
                app.winterButton3.Enable = 'off';
                app.parulaButton3.Enable = 'off';
                app.lipariButton3.Enable = 'off';

            else

                if app.ns > 1
                    app.SlicesEditField2.Enable = 'on';
                    app.SlicesEditField2Label.Enable = 'on';
                    app.SlicesSlider2.Enable = 'on';
                    app.SlicesEditField3.Enable = 'on';
                    app.SlicesEditField3Label.Enable = 'on';
                    app.SlicesSlider3.Enable = 'on';
                else
                    app.SlicesEditField2.Enable = 'off';
                    app.SlicesEditField2Label.Enable = 'off';
                    app.SlicesSlider2.Enable = 'off';
                    app.SlicesEditField3.Enable = 'off';
                    app.SlicesEditField3Label.Enable = 'off';
                    app.SlicesSlider3.Enable = 'off';
                end
                if app.nd > 1
                    app.DynamicsEditField2.Enable = 'on';
                    app.DynamicsEditField2Label.Enable = 'on';
                    app.DynamicsEditField3.Enable = 'on';
                    app.DynamicsEditField3Label.Enable = 'on';
                    app.DynamicsSlider2.Enable = 'on';
                    app.DynamicsSlider3.Enable = 'on';
                else
                    app.DynamicsEditField2.Enable = 'off';
                    app.DynamicsEditField2Label.Enable = 'off';
                    app.DynamicsEditField3.Enable = 'off';
                    app.DynamicsEditField3Label.Enable = 'off';
                    app.DynamicsSlider2.Enable = 'off';
                    app.DynamicsSlider3.Enable = 'off';
                end
                app.T1ScaleEditField.Enable = 'on';
                app.T1ScaleMaxLabel.Enable = 'on';
                app.T1ScaleAutoButton.Enable = 'on';
                app.grayButton.Enable = 'on';
                app.autumnButton.Enable = 'on';
                app.winterButton.Enable = 'on';
                app.parulaButton.Enable = 'on';
                app.lipariButton.Enable = 'on';
                app.AbortFitButton.Enable = 'on';
                app.EraseButton.Enable = 'on';
                app.ROIbutton.Enable = 'on';
                app.BrushEditField.Enable = 'on';
                app.BrushEditFieldLabel.Enable = 'on';
                app.ExportRoiDataButton.Enable = 'on';
                app.grayButton2.Enable = 'on';
                app.autumnButton2.Enable = 'on';
                app.winterButton2.Enable = 'on';
                app.parulaButton2.Enable = 'on';
                app.lipariButton2.Enable = 'on';
                app.EraseButton2.Enable = 'on';
                app.ROIbutton2.Enable = 'on';
                app.BrushEditField2.Enable = 'on';
                app.BrushEditField2Label.Enable = 'on';
                app.grayButton3.Enable = 'on';
                app.autumnButton3.Enable = 'on';
                app.winterButton3.Enable = 'on';
                app.parulaButton3.Enable = 'on';
                app.lipariButton3.Enable = 'on';

            end
           
            drawnow;
            
        end
        
        
        % Initialize the GUI
        function InitializeUI(app)
            
            % Message
            app.TextMessage('Initializing UI ...');

            % App version
            app.CreditsLabel.Text = strcat("Gustav Strijkers, Amsterdam UMC, Version ",app.appVersion);
            
            % Define app colors
            app.brightred = 'Red';
            app.red = '#D95319';
            app.brightgreen = 'Green'; 
            app.green = '#6CB557';
            app.yellow = 'Yellow';
            app.orange = '#FDA400';
            app.blue = '#0073BE';
            app.grey = '#787878'; 
            app.lightblue = '#DEE6EB';
            app.guiBlack = [0.15,0.15,0.15];
            app.guiGrey = [0.94,0.94,0.94];
            app.guiMiddleGrey = [0.85,0.85,0.85];
            app.guiDarkGrey = [0.5,0.5,0.5];
            app.guiWhite = [1.0,1.0,1.0];
            app.guiBlackBlue = [0.02,0.13,0.3];
            
            % Logo's
            app.TulpLogo.ImageSource = 'AmsterdamUMC.png';
            app.MRSImage.ImageSource = 'MRSolutionsLogo.gif';

             % Load sounds
            [app.warningSound,~] = audioread('Glass.aiff');
            [app.errorSound,~] = audioread('Ping.aiff');
            
            % Icons
            app.LoadMRDfileButton.Icon = 'disk.png';
            app.RecoImagesButton.Icon = 'reconstruct.gif';
            app.RegisterButton.Icon = 'sort.gif';
            app.MaskButton.Icon = 'threshold.gif';
            app.FitT1Button.Icon = 'exponential.gif';
            app.ExportDCMButton.Icon = 'disk.png';
            app.ExportGIFButton.Icon = 'disk.png';
            app.ExitButton.Icon = 'exit.png';
            app.StatusImage.ImageSource = 'statusokay.gif';
            app.T1Image.ImageSource = 'square.gif';
            app.RawImage.ImageSource = 'square.gif';
            app.M0Image.ImageSource = 'square.gif';
            app.R2Image.ImageSource = 'square.gif';
            
            % set lights and buttons
            app.SetLights([0,0,0,0,0,0,0]);
            app.SetButtons([0,0,0,0,0,0,0,1]);
            app.SetFitButtons(0);
            app.SetImageSelectionButtons(0);
            app.SetSegmentationButtons(0);
            app.SetMapButtons(0);
            
            % Set some plot properties
            app.RawDataFig.Toolbar.Visible = 'off';
            app.T1MapFig.Toolbar.Visible = 'off';
            app.M0MapFig.Toolbar.Visible = 'off';
            app.T1linearPlot.Toolbar.Visible = 'off';
            app.t1cmap = lipariColorMap();
            app.m0cmap = gray;
            app.r2cmap = lipariColorMap();
            app.t1cmap(1,:) = [0 0 0];
            app.m0cmap(1,:) = [0 0 0];
            app.r2cmap(1,:) = [0 0 0];
            app.lipariButton.Value = 1;
            app.grayButton2.Value = 1;
            app.lipariButton3.Value = 1;
            
            % Switch off plot interactivity
            app.RawDataFig.Interactions = [];
            app.T1MapFig.Interactions = [];
            app.M0MapFig.Interactions = [];
            app.T1linearPlot.Interactions = [];
            
            % ROis and cross-hair
            app.roiFlag = false;
            app.roiPoints = [];
            delete(app.roiT1);

            app.SetStatus(3);
            
            drawnow;
            
        end


        %------------------------------------------------
        %
        %  Figures and Plots
        %
        %------------------------------------------------


        % Displays the mask in the raw image data
        function PlotImageFig(app)

            if app.validRecoFlag

                cla(app.RawDataFig);
                hold(app.RawDataFig,'on');

                % Define the colormap
                % Since it is not possible to have 2 colormaps in 1 figure, the
                % colormap is split in a grayscale part [0 1] and a red-color overlay part [1 2]
                cmap1 = [linspace(0,1,128)',linspace(0,1,128)',linspace(0,1,128)'];
                cmap2 = [linspace(0,1,128)', zeros(128,2)];
                cmap = [cmap1;cmap2];
                cmap(cmap > 1) = 1;

                % Background anatomy image
                im = squeeze(app.images(app.InversionEditField.Value,:,:,app.SlicesEditField1.Value,app.DynamicsEditField1.Value));
                if app.parameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end

                % Scale
                if app.AutoLWButton.Value == 1
                    maxIm = max(im(:));
                    app.WindowEditField.Value = maxIm;
                    app.WindowSlider.Value = maxIm;
                    app.LevelEditField.Value = maxIm/2;
                    app.LevelSlider.Value = maxIm/2;
                end

                % Window and Level
                im = (1/app.WindowEditField.Value)*(im - app.LevelEditField.Value + app.WindowEditField.Value/2);
                im(im<0) = 0;
                im(im>0.9999) = 0.9999;

                % Plot the background im
                xlim(app.RawDataFig,[0, size(im,1) + 1]);
                ylim(app.RawDataFig,[0, size(im,2) + 1]);
                background = imshow(rot90(im),[0,2],'Colormap',cmap,'InitialMagnification','fit','Parent',app.RawDataFig); %#ok<NASGU>

                % Overlay image with the mask
                imo = squeeze(2-app.mask(:,:,app.SlicesEditField1.Value,app.DynamicsEditField1.Value));
                alpha = squeeze(0.7-0.7*app.mask(:,:,app.SlicesEditField1.Value,app.DynamicsEditField1.Value));
                if app.parameters.PHASE_ORIENTATION
                    imo = rot90(imo,-1);
                    alpha = rot90(alpha,-1);
                end
                overlay = imshow(rot90(imo),[0 2],'Colormap',cmap,'InitialMagnification','fit','Parent',app.RawDataFig);
                set(overlay,'AlphaData',rot90(alpha));

                % Image aspect ratio
                if app.parameters.PHASE_ORIENTATION
                    aratio = app.AspectRatioViewField.Value*size(im,2)/size(im,1);
                    daspect(app.RawDataFig,[1 aratio 1]);
                else
                    aratio = app.AspectRatioViewField.Value*size(im,1)/size(im,2);
                    daspect(app.RawDataFig,[aratio 1 1]);
                end
              
                hold(app.RawDataFig,'off');
                drawnow;

                % Set some limits now
                maxim = app.images; %(1,:,:,app.SlicesEditField1.Value,app.DynamicsEditField1.Value);
                app.thresholdEditField.Limits = [0 max(maxim(:))+1];
                app.thresholdEditField.Value = app.threshold(app.SlicesEditField1.Value,app.DynamicsEditField1.Value);
                app.ThresholdSlider.Limits = [0 max(maxim(:))+1];
                app.ThresholdSlider.Value = app.threshold(app.SlicesEditField1.Value,app.DynamicsEditField1.Value);

            end

        end % PlotRawImages
        
        
        % Plots the maps for the current slice
        function PlotMaps(app)

            if app.validFitFlag

                hold(app.T1MapFig,'on');
                hold(app.M0MapFig,'on');
                hold(app.R2MapFig,'on');

                % Plot the T1 map
                im = squeeze(app.t1map(:,:,app.SlicesEditField2.Value,app.DynamicsEditField2.Value));
                if app.parameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end
                xlim(app.T1MapFig,[0, size(im,1) + 1]);
                ylim(app.T1MapFig,[0, size(im,2) + 1]);
                imshow(rot90(im),[0 app.T1ScaleEditField.Value],'Colormap',app.t1cmap,'InitialMagnification','fit','Parent',app.T1MapFig);
                if app.parameters.PHASE_ORIENTATION
                    aratio = app.AspectRatioViewField.Value*size(im,2)/size(im,1);
                    daspect(app.T1MapFig,[1 aratio 1]);
                else
                    aratio = app.AspectRatioViewField.Value*size(im,1)/size(im,2);
                    daspect(app.T1MapFig,[aratio 1 1]);
                end
                colorbar(app.T1MapFig,'east','Ticks',[]);

                % ROI display if present
                if app.roiFlag && ~isempty(app.roiPoints)
                    app.roiPoints = unique(app.roiPoints,'rows');
                    zColumn = app.roiPoints(:,3);
                    plotPoints = app.roiPoints(zColumn==app.SlicesEditField2.Value, :);
                    if app.parameters.PHASE_ORIENTATION
                        ysize = app.dimx/app.oversample;
                    else
                        ysize = app.dimy;
                    end
                    app.PlotPoint(plotPoints(:,1),plotPoints(:,2),ysize);
                    app.CalculateRoiT1;
                end

                % Plot the M0 map
                m0scale = round(2*mean(nonzeros(squeeze(app.m0map(:,:,app.SlicesEditField3.Value,app.DynamicsEditField3.Value)))));
                if isnan(m0scale) m0scale = 100; end
                im = squeeze(app.m0map(:,:,app.SlicesEditField3.Value,app.DynamicsEditField3.Value));
                if app.parameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end
                xlim(app.M0MapFig,[0, size(im,1) + 1]);
                ylim(app.M0MapFig,[0, size(im,2) + 1]);
                imshow(rot90(im),[0 m0scale],'Colormap',app.m0cmap,'InitialMagnification','fit','Parent',app.M0MapFig);
                if app.parameters.PHASE_ORIENTATION
                    aratio = app.AspectRatioViewField.Value*size(im,2)/size(im,1);
                    daspect(app.M0MapFig,[1 aratio 1]);
                else
                    aratio = app.AspectRatioViewField.Value*size(im,1)/size(im,2);
                    daspect(app.M0MapFig,[aratio 1 1]);
                end

                % Plot the R2 map
                im = squeeze(app.r2map(:,:,app.SlicesEditField3.Value,app.DynamicsEditField3.Value));
                if app.parameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end
                xlim(app.R2MapFig,[0, size(im,1) + 1]);
                ylim(app.R2MapFig,[0, size(im,2) + 1]);
                imshow(rot90(im),[app.Rsquare.Value-0.01 1],'Colormap',app.r2cmap,'InitialMagnification','fit','Parent',app.R2MapFig);
                if app.parameters.PHASE_ORIENTATION
                    aratio = app.AspectRatioViewField.Value*size(im,2)/size(im,1);
                    daspect(app.R2MapFig,[1 aratio 1]);
                else
                    aratio = app.AspectRatioViewField.Value*size(im,1)/size(im,2);
                    daspect(app.R2MapFig,[aratio 1 1]);
                end
                colorbar(app.R2MapFig,'east','Ticks',[app.Rsquare.Value-0.01, 1]);
                delete(app.txt1);
                delete(app.txt2);
                app.txt1 = text(app.R2MapFig,0.8,0.95,'1.0','Units','normalized','Color',app.grey,'FontSize',14);
                app.txt2 = text(app.R2MapFig,0.8,0.05,num2str(app.Rsquare.Value),'Units','normalized','Color',app.grey,'FontSize',14);

                hold(app.T1MapFig,'off');
                hold(app.M0MapFig,'off');
                hold(app.R2MapFig,'off');
                drawnow;

            end
            
        end % PlotMaps


        % Draw a cross-hair on the T1 map
        function PlotCross(app,xcor,ycor,ysize)

            if app.validFitFlag

                delete(app.crossT1);
                delete(app.crossM0);

                clr1 = 'r';
                if app.autumnButton.Value == 1 || app.lipariButton.Value == 1
                    clr1 = 'g';
                end

                clr2 = 'r';
                if app.autumnButton2.Value == 1 || app.lipariButton2.Value == 1
                    clr2 = 'g';
                end

                app.crossT1 = drawcrosshair(app.T1MapFig, 'Position', [xcor ysize-ycor+1], 'Color', clr1, 'LineWidth', 1);
                app.crossM0 = drawcrosshair(app.M0MapFig, 'Position', [xcor ysize-ycor+1], 'Color', clr2, 'LineWidth', 1);

            end
        
        end % PlotCross


        % Draw points on the T1 map
        function PlotPoint(app,xcor,ycor,ysize)

            if app.validFitFlag

                clr1 = 'r';
                if app.autumnButton.Value == 1 || app.lipariButton.Value == 1
                    clr1 = 'g';
                end

                clr2 = 'r';
                if app.autumnButton2.Value == 1 || app.lipariButton2.Value == 1
                    clr2 = 'g';
                end

                hold(app.T1MapFig,'on');
                delete(app.roiT1);
                app.roiT1 = plot(app.T1MapFig, xcor, ysize-ycor+1, 's', 'MarkerSize', 4, 'Color', clr1, 'MarkerFaceColor', clr1);
                hold(app.T1MapFig,'off');

                hold(app.M0MapFig,'on');
                delete(app.roiM0);
                app.roiM0 = plot(app.M0MapFig, xcor, ysize-ycor+1, 's', 'MarkerSize', 4, 'Color', clr2, 'MarkerFaceColor', clr2);
                hold(app.M0MapFig,'off');

            end

        end % PlotPoint
        

        % Calculate and show T1 values of ROI
        function CalculateRoiT1(app)

            if app.validFitFlag

                try

                    % ysize needed
                    if app.parameters.PHASE_ORIENTATION
                        ysize = app.dimx/app.oversample;
                    else
                        ysize = app.dimy;
                    end

                    % Mask the raw data
                    maskedImages = zeros(size(app.images));
                    for i = 1:size(app.images,1)
                        maskedImages(i,:,:,:,:) = app.images(i,:,:,:,:).*permute(app.mask(:,:,:,:),[5 1 2 3 4]);
                    end

                    % Extract T1, M0 and data values from the map
                    for i = 1:size(app.roiPoints,1)
                        if app.parameters.PHASE_ORIENTATION
                            t1values(i) = app.t1map(ysize-app.roiPoints(i,2)+1,app.roiPoints(i,1),app.roiPoints(i,3),app.DynamicsEditField2.Value); %#ok<AGROW>
                            m0values(i) = app.m0map(ysize-app.roiPoints(i,2)+1,app.roiPoints(i,1),app.roiPoints(i,3),app.DynamicsEditField2.Value); %#ok<AGROW>
                            rawdatas(i,:) = maskedImages(:,ysize-app.roiPoints(i,2)+1,app.roiPoints(i,1),app.roiPoints(i,3),app.DynamicsEditField2.Value)'; %#ok<AGROW>
                        else
                            t1values(i) = app.t1map(app.roiPoints(i,1),app.roiPoints(i,2),app.roiPoints(i,3),app.DynamicsEditField2.Value); %#ok<AGROW>
                            m0values(i) = app.m0map(app.roiPoints(i,1),app.roiPoints(i,2),app.roiPoints(i,3),app.DynamicsEditField2.Value); %#ok<AGROW>
                            rawdatas(i,:) = maskedImages(:,app.roiPoints(i,1),app.roiPoints(i,2),app.roiPoints(i,3),app.DynamicsEditField2.Value)'; %#ok<AGROW>
                        end
                    end

                    % Averages in ROI or cross-hair value
                    r1values = 1./t1values;
                    r1values = nonzeros(r1values);
                    r1values(isnan(r1values)) = [];
                    r1values(isinf(r1values)) = [];
                    t1value = 1/mean(r1values);                         % T1 = 1 / mean(R1 values in pixels)
                    stdt1value = t1value*std(r1values)/mean(r1values);  % error propagation with division
                    if size(rawdatas,1)>1
                        rawdatas(rawdatas==0) = nan;
                        app.roiIntData = mean(rawdatas,'omitnan');             % trick to exclude ROI pixels in background
                    else
                        app.roiIntData = rawdatas;
                    end

                    % TI selection
                    app.MakeTiArray;
                    nrTIs = length(find(app.tiSelection(1:app.nir) == 1));

                    % Do a fit of the ROI-averaged pixel intensities
                    if nrTIs>1
                        switch app.dataType
                            case "IR"
                                [A,B,m0roi,t1roi,~] = dotheT1fit_single(app.roiIntData, app.tis, app.Rsquare.Value, app.tiSelection);
                            case "SR"
                                [A,B,m0roi,t1roi,~] = dotheSRT1fit_single(app.roiIntData, app.tis, app.Rsquare.Value, app.tiSelection);
                        end
                    else
                        t1roi = 0;
                    end

                    % Plot the results of the fits in the chosen pixel
                    app.PlotFits(app.tis,app.roiIntData,t1value,t1roi,stdt1value,m0roi,A,B);

                catch

                    app.TextMessage('Error determining T1 in ROI ...')

                end

            end

        end % CalculateRoiT1

        
        % Plots the fitresults in the 2 fit-windows
        function PlotFits(app,irTimes,data,t1,t1roi,stdt1,m0,Aroi,Broi)
            
          if app.validRecoFlag  
              
            cla(app.T1linearPlot);
            hold(app.T1linearPlot,'on');
            
            % Colormap for datapoints
            cmap = [hex2rgb(app.red) ; hex2rgb(app.blue)];
            ticolorflag = cmap(app.tiSelection + 1,:);
            ticolorflag = ticolorflag(1:app.nir,:);
            
            if (t1roi>0) && (m0>0)

                switch app.dataType

                    case "IR"

                        % Fit
                        x = 0 : 1 : max(irTimes)+5;
                        t1RoiStar = t1roi/(Broi/Aroi-1);
                        s = Aroi - Broi.*exp(-x./t1RoiStar);

                        % Determine negative signal intensities
                        datac = data;
                        factor = -log(Aroi/Broi)*t1RoiStar;
                        for cnt = 1:length(data)
                            if app.tis(cnt) < factor
                                datac(cnt) = -datac(cnt);
                            end
                        end

                        % Save the sign corrected data
                        app.roiIntDataSigned = datac;

                        % Plot
                        dataPoints = scatter(app.T1linearPlot,app.tis,datac,[],ticolorflag,'filled');
                        plot(app.T1linearPlot,x,s,'LineWidth',3);
                        uistack(dataPoints, 'top');
                        xlim(app.T1linearPlot,[0, max(irTimes)+5]);
                        ylim(app.T1linearPlot,[1.1*min(s),1.2*max(s)]);

                    case "SR"

                        % Fit
                        x = 0 : 1 : max(irTimes)+5;
                        s = Aroi.*(1-exp(-x./t1roi));

                        % Plot
                        dataPoints = scatter(app.T1linearPlot,app.tis,data,[],ticolorflag,'filled');
                        plot(app.T1linearPlot,x,s,'LineWidth',3);
                        uistack(dataPoints, 'top');
                        xlim(app.T1linearPlot,[0, max(irTimes)+5]);
                        ylim(app.T1linearPlot,[1.1*min(s),1.2*max(s)]);
                        app.roiIntDataSigned = data;

                end


                % Show T1 value in app
                if stdt1 > 0
                    app.T1ViewField1.Value = [num2str(t1,'% 10.1f'),' ',char(177),' ',num2str(stdt1,'% 10.1f')];
                else
                    app.T1ViewField1.Value = num2str(t1,'% 10.1f');
                end

                app.T1ViewField2.Value = num2str(t1roi,'% 10.1f');
            
            else

                app.T1ViewField1.Value = " ";
                app.T1ViewField2.Value = " ";

            end
            
            hold(app.T1linearPlot,'off');
            
          end
           
        end % PlotFits


        %------------------------------------------------
        %
        %  Parameters and settings
        %
        %------------------------------------------------


        % Sets global parameters
        function SetParameters(app)
           
            % Message
            app.TextMessage('Setting scan parameters ...');
            
            % sequence parameters
            app.dimx = app.parameters.NO_SAMPLES;
            app.dimy = app.parameters.NO_VIEWS;
            app.fov = app.parameters.FOV;
            app.ns = app.parameters.NO_SLICES;
            app.sl = app.parameters.SLICE_THICKNESS;

            switch app.dataType

                case "IR"
                    app.tr = app.parameters.tr;
                    app.ti = app.parameters.ti;
                    app.nir = app.parameters.NO_ECHOES;
                    app.tis = app.ti + app.tr*app.parameters.lines_per_segment*linspace(0,app.nir-1,app.nir)'; % inversion times
                    app.TextMessage(strcat("Inversion times = ",num2str(app.tis')," ..."));
                    app.nd = app.parameters.EXPERIMENT_ARRAY;
                    app.TR0ViewField.Value = app.tr;
                    app.TRsEditField.Value = 0;
                    app.NrTRsViewField.Value = 1;
                    app.TI0EditField.Value = app.ti;
                    app.TIsEditField.Value = app.tis(end);
                    app.NrTIsViewField.Value = app.nir;

                case "SR"
                    app.trs = app.parameters.TR_array(1:app.parameters.TR_array_size)';
                    [~, idx] = sort(app.trs, 'ascend');
                    for i=1:app.nrCoils
                        app.data{i} = app.data{i}(idx,:,:,:);
                    end
                    app.trs = app.trs(idx);
                    app.TextMessage(strcat("Repetition times = ",num2str(app.trs')," ..."));
                    app.ntr = app.parameters.TR_array_size;
                    app.tis = app.trs;
                    app.nir = app.ntr;
                    app.nd = 1;
                    app.TR0ViewField.Value = app.trs(1);
                    app.TRsEditField.Value = app.trs(end);
                    app.NrTRsViewField.Value = app.ntr;
                    app.TI0EditField.Value = 0;
                    app.TIsEditField.Value = 0;
                    app.NrTIsViewField.Value = 0;

            end
            
            app.oversample = 1;
            if app.parameters.oversample == 1
                app.oversample = 2;
            end
            
            % show other parameters & set limits
            app.NrDynamicsViewField.Value = app.nd;
            app.dimxViewField.Value = app.dimx;
            app.dimyViewField.Value = app.dimy;
            app.SlicesViewField.Value = app.ns;
            app.SlicesEditField1.Value = 1;
            app.SlicesSlider1.Value = 1;
            app.SlicesEditField2.Value = 1;
            app.SlicesSlider2.Value = 1;
            app.SlicesEditField3.Value = 1;
            app.SlicesSlider3.Value = 1;
            app.FirstSliceEditField.Value = 1;
            app.LastSliceEditField.Value = 1;
            if app.ns > 1
                app.SlicesEditField1.Limits = [1, app.ns];
                app.SlicesSlider1.Limits = [1, app.ns];
                app.SlicesEditField2.Limits = [1, app.ns];
                app.SlicesSlider2.Limits = [1, app.ns];
                app.SlicesEditField3.Limits = [1, app.ns];
                app.SlicesSlider3.Limits = [1, app.ns];
                app.FirstSliceEditField.Limits = [1, app.ns];
                app.LastSliceEditField.Limits = [1, app.ns];
            else
                app.SlicesEditField1.Limits = [1, 2];
                app.SlicesSlider1.Limits = [1, 2];
                app.SlicesEditField2.Limits = [1, 2];
                app.SlicesSlider2.Limits = [1, 2];
                app.SlicesEditField3.Limits = [1, 2];
                app.SlicesSlider3.Limits = [1, 2];
                app.FirstSliceEditField.Limits = [1, 2];
                app.LastSliceEditField.Limits = [1, 2];
            end
            app.DynamicsEditField1.Value = 1;
            app.DynamicsEditField2.Value = 1;
            app.DynamicsEditField3.Value = 1;
            app.DynamicsSlider1.Value = 1;
            app.DynamicsSlider2.Value = 1;
            app.DynamicsSlider3.Value = 1;
            if app.nd > 1
                app.DynamicsEditField1.Limits  = [1, app.nd];
                app.DynamicsEditField2.Limits  = [1, app.nd];
                app.DynamicsEditField3.Limits  = [1, app.nd];
                app.DynamicsSlider1.Limits  = [1, app.nd];
                app.DynamicsSlider2.Limits  = [1, app.nd];
                app.DynamicsSlider3.Limits  = [1, app.nd];
            else
                app.DynamicsEditField1.Limits  = [1, 2];
                app.DynamicsEditField2.Limits  = [1, 2];
                app.DynamicsEditField3.Limits  = [1, 2];
                app.DynamicsSlider1.Limits  = [1, 2];
                app.DynamicsSlider2.Limits  = [1, 2];
                app.DynamicsSlider3.Limits  = [1, 2];
            end

            app.InversionEditField.Value = 1;
            app.InversionEditField.Limits = [1, app.nir];
            app.InversionSlider.Value = 1;
            app.InversionSlider.Limits = [1, app.nir];
            switch app.dataType
                case "IR"
                    app.InversionEditFieldLabel.Text = "inversion";
                case "SR"
                    app.InversionEditFieldLabel.Text = "TR";
            end

            app.SLTViewField.Value = app.sl;
            app.FOVViewField.Value = app.fov;
            app.AspectRatioViewField.Value = app.parameters.FOVf/8;
            app.FirstSliceEditField.Value = 1;
            app.LastSliceEditField.Value = app.ns;
            app.FirstDynamicEditField.Value = 1;
            app.LastDynamicEditField.Value = app.nd;
            app.abortFitFlag = false;

            % Uncheck/reset some options
            % app.CSrecoCheckBox.Value = 0;
            % app.DeNoiseCheckBox.Value = 0;
            % app.DeNoiseWindowEditField.Value = 8;
            % app.DeRingCheckBox.Value = 0;
            app.ModelBasedCheckBox.Value = 0;

            % File tag
            [~,app.tag,~] = fileparts(app.dataFile);

            % Window and level
            app.WindowEditField.Value = 32767;
            app.LevelEditField.Value = 16384;
            app.WindowSlider.Value = 32767;
            app.LevelSlider.Value = 16384;
            app.AutoLWButton.Value = 1;

            % Segmentation
            app.structuringElement2D = strel("disk",1);
            app.roiPoints = [];
            
            % Define empty m0, t1map, r2map
            app.m0map = zeros(app.dimx/app.oversample,app.dimy,app.ns,app.nd);
            app.t1map = zeros(app.dimx/app.oversample,app.dimy,app.ns,app.nd);
            app.r2map = zeros(app.dimx/app.oversample,app.dimy,app.ns,app.nd);
                        
            % Define mask initially with ones
            app.mask = ones(app.dimx/app.oversample,app.dimy,app.ns,app.nd);

            % Reset treshold values
            app.threshold = zeros(app.ns,app.nd);
            
        end % SetParameters
    
        
        % make TE array
        function MakeTiArray(app)
            
            app.tiSelection = [...
                app.CheckBox_1.Value  app.CheckBox_2.Value  app.CheckBox_3.Value  app.CheckBox_4.Value ...
                app.CheckBox_5.Value  app.CheckBox_6.Value  app.CheckBox_7.Value  app.CheckBox_8.Value ...
                app.CheckBox_9.Value  app.CheckBox_10.Value app.CheckBox_11.Value app.CheckBox_12.Value ...
                app.CheckBox_13.Value app.CheckBox_14.Value app.CheckBox_15.Value app.CheckBox_16.Value ...
                app.CheckBox_17.Value app.CheckBox_18.Value app.CheckBox_19.Value app.CheckBox_20.Value ...
                app.CheckBox_21.Value app.CheckBox_22.Value app.CheckBox_23.Value app.CheckBox_24.Value ...
                app.CheckBox_25.Value app.CheckBox_26.Value app.CheckBox_27.Value app.CheckBox_28.Value ...
                app.CheckBox_29.Value app.CheckBox_30.Value app.CheckBox_31.Value app.CheckBox_32.Value ...
                app.CheckBox_33.Value app.CheckBox_34.Value app.CheckBox_35.Value app.CheckBox_36.Value ...
                app.CheckBox_37.Value app.CheckBox_38.Value app.CheckBox_39.Value app.CheckBox_40.Value ...
                app.CheckBox_41.Value app.CheckBox_42.Value app.CheckBox_43.Value app.CheckBox_44.Value ...
                app.CheckBox_45.Value app.CheckBox_46.Value app.CheckBox_47.Value app.CheckBox_48.Value ...
                app.CheckBox_49.Value app.CheckBox_50.Value app.CheckBox_51.Value app.CheckBox_52.Value ...
                app.CheckBox_53.Value app.CheckBox_54.Value app.CheckBox_55.Value app.CheckBox_56.Value ...
                app.CheckBox_57.Value app.CheckBox_58.Value app.CheckBox_59.Value app.CheckBox_60.Value ...
                app.CheckBox_61.Value app.CheckBox_62.Value app.CheckBox_63.Value app.CheckBox_64.Value ...
                app.CheckBox_65.Value app.CheckBox_66.Value app.CheckBox_67.Value app.CheckBox_68.Value ...
                app.CheckBox_69.Value app.CheckBox_70.Value app.CheckBox_71.Value app.CheckBox_72.Value ...
                app.CheckBox_73.Value app.CheckBox_74.Value app.CheckBox_75.Value app.CheckBox_76.Value ...
                app.CheckBox_77.Value app.CheckBox_78.Value app.CheckBox_79.Value app.CheckBox_80.Value ...
                app.CheckBox_81.Value app.CheckBox_82.Value app.CheckBox_83.Value app.CheckBox_84.Value ...
                app.CheckBox_85.Value app.CheckBox_86.Value app.CheckBox_87.Value app.CheckBox_88.Value ...
                app.CheckBox_89.Value app.CheckBox_90.Value app.CheckBox_91.Value app.CheckBox_92.Value ...
                app.CheckBox_93.Value app.CheckBox_94.Value app.CheckBox_95.Value app.CheckBox_96.Value ...
                ];
            app.tiSelection = [app.tiSelection ones(1,1024)];
            
        end
        
        % Set default values for which echoes to include in fitting 
        function SetInversionRecovery(app)
            
                app.CheckBox_1.Value = 0; app.CheckBox_2.Value = 1; app.CheckBox_3.Value = 1; app.CheckBox_4.Value = 1; 
                app.CheckBox_5.Value = 1; app.CheckBox_6.Value = 1; app.CheckBox_7.Value = 1; app.CheckBox_8.Value = 1; 
                app.CheckBox_9.Value = 1; app.CheckBox_10.Value = 1; app.CheckBox_11.Value = 1; app.CheckBox_12.Value = 1; 
                app.CheckBox_13.Value = 1; app.CheckBox_14.Value = 1; app.CheckBox_15.Value = 1; app.CheckBox_16.Value = 1; 
                app.CheckBox_17.Value = 1; app.CheckBox_18.Value = 1; app.CheckBox_19.Value = 1; app.CheckBox_20.Value = 1; 
                app.CheckBox_21.Value = 1; app.CheckBox_22.Value = 1; app.CheckBox_23.Value = 1; app.CheckBox_24.Value = 1; 
                app.CheckBox_25.Value = 1; app.CheckBox_26.Value = 1; app.CheckBox_27.Value = 1; app.CheckBox_28.Value = 1; 
                app.CheckBox_29.Value = 1; app.CheckBox_30.Value = 1; app.CheckBox_31.Value = 1; app.CheckBox_32.Value = 1; 
                app.CheckBox_33.Value = 1; app.CheckBox_34.Value = 1; app.CheckBox_35.Value = 1; app.CheckBox_36.Value = 1; 
                app.CheckBox_37.Value = 1; app.CheckBox_38.Value = 1; app.CheckBox_39.Value = 1; app.CheckBox_40.Value = 1; 
                app.CheckBox_41.Value = 1; app.CheckBox_42.Value = 1; app.CheckBox_43.Value = 1; app.CheckBox_44.Value = 1; 
                app.CheckBox_45.Value = 1; app.CheckBox_46.Value = 1; app.CheckBox_47.Value = 1; app.CheckBox_48.Value = 1; 
                app.CheckBox_49.Value = 1; app.CheckBox_50.Value = 1; app.CheckBox_51.Value = 1; app.CheckBox_52.Value = 1; 
                app.CheckBox_53.Value = 1; app.CheckBox_54.Value = 1; app.CheckBox_55.Value = 1; app.CheckBox_56.Value = 1; 
                app.CheckBox_57.Value = 1; app.CheckBox_58.Value = 1; app.CheckBox_59.Value = 1; app.CheckBox_60.Value = 1; 
                app.CheckBox_61.Value = 1; app.CheckBox_62.Value = 1; app.CheckBox_63.Value = 1; app.CheckBox_64.Value = 1;
                app.CheckBox_65.Value = 1; app.CheckBox_66.Value = 1; app.CheckBox_67.Value = 1; app.CheckBox_68.Value = 1; 
                app.CheckBox_69.Value = 1; app.CheckBox_70.Value = 1; app.CheckBox_71.Value = 1; app.CheckBox_72.Value = 1; 
                app.CheckBox_73.Value = 1; app.CheckBox_74.Value = 1; app.CheckBox_75.Value = 1; app.CheckBox_76.Value = 1; 
                app.CheckBox_77.Value = 1; app.CheckBox_78.Value = 1; app.CheckBox_79.Value = 1; app.CheckBox_80.Value = 1; 
                app.CheckBox_81.Value = 1; app.CheckBox_82.Value = 1; app.CheckBox_83.Value = 1; app.CheckBox_84.Value = 1; 
                app.CheckBox_85.Value = 1; app.CheckBox_86.Value = 1; app.CheckBox_87.Value = 1; app.CheckBox_88.Value = 1; 
                app.CheckBox_89.Value = 1; app.CheckBox_90.Value = 1; app.CheckBox_91.Value = 1; app.CheckBox_92.Value = 1; 
                app.CheckBox_93.Value = 1; app.CheckBox_94.Value = 1; app.CheckBox_95.Value = 1; app.CheckBox_96.Value = 1;
        end


    end % methods


    % ----------------------------------------------
    %
    % Public functions, available outside the app
    %
    % ----------------------------------------------


    methods (Access = public)


        % Text messages in the app
        function TextMessage(app, message)
            
            try
                app.MessageBox.Value = [message ; app.MessageBox.Value];
                app.MessageBox.Value = app.MessageBox.Value(1:50,1);
                drawnow;
            catch
            end
            
        end % TextMessage
        
        

        % Status icon
        function SetStatus(app,status)
            
            switch status
                
                case 0
                    app.StatusImage.ImageSource = 'statusokay.gif';
                    app.StatusLabel.FontColor = app.green;
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 14;
                    app.StatusLabel.Text = 'OK';

                case 1
                    app.StatusImage.ImageSource = 'statuswarning.gif';
                    app.StatusLabel.FontColor = app.orange;
                    if app.AutoSoundsCheckBox.Value
                        sound(app.warningSound,48000);
                    end
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 11;
                    app.StatusLabel.Text = 'WARNING';

                case 2
                    app.StatusImage.ImageSource = 'statuserror.gif';
                    app.StatusLabel.FontColor = app.brightred;
                    if app.AutoSoundsCheckBox.Value
                        sound(app.errorSound,48000);
                    end
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 12;
                    app.StatusLabel.Text = 'ERROR';

                case 3
                    app.StatusImage.ImageSource = 'busy.gif';
                    app.StatusLabel.FontColor = app.blue;
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 12;
                    app.StatusLabel.Text = 'BUSY';

            end
            
            drawnow;
            
        end


    end % methods (Access = public)


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            
            % disable unnecessary warnings
            clc;
            warning off;

            % Set app theme to light
            theme(app.T1mappUIFigure,"light")
            
            % main window noname
            app.T1mappUIFigure.Name = '';
      
            % empty file import and export paths
            app.mrdImportPath = '';
            app.dicomExportPath = '';
            app.gifExportPath = '';
            app.roiExportPath = '';
            app.mrdFlag = true;
            app.mobaFlag = false;
            app.abortFitFlag = false;
            app.abortRegFlag = false;
                      
            % add subfolders to search path
            if (~isdeployed)
                app.oldDir = pwd;
                info = dir(which('T1mappR.mlapp'));
                app.appDnir = info.folder;
                cd(app.appDnir);
                addpath(genpath(strcat(pwd,filesep,"functions")));
                addpath(genpath(strcat(pwd,filesep,"gui")));
            end

            % Initialize user interface
            app.InitializeUI;

            % Color scheme
            app.ColorsButtonGroupSelectionChanged;
            
            % Add the bart path to the environment variable if available
            try
                if ismac()
                    [~,result] = system('uname -v');
                    is_m1_mac = any(strfind(result,'ARM64'));
                    if is_m1_mac
                        app.TextMessage('Apple arm64 version ...');
                    else
                        app.TextMessage('Apple Intel version ...');
                    end
                else
                    app.TextMessage('PC Intel version ...');
                end
            catch ME
                app.TextMessage(ME.message);
            end

            app.bartDetectedFlag = false;
            try
                bart(app,'version');
                if ~contains(app.bartVersion,'none')
                    app.bartDetectedFlag = true;
                end
            catch ME
                app.TextMessage(ME.message);
            end

            % Report whether BART is available or not
            if app.bartDetectedFlag
                app.TextMessage('BART toolbox detected ...');
            else
                app.TextMessage('WARNING: BART toolbox not available ...');
                app.SetStatus(1);
            end

            % Check if Bart was compiled with GPU support
            app.GPUpresentFlag = false;
            if app.bartDetectedFlag
                try
                    rec = bart(app,'pics -l2 -g ',[0 1 0 ; 1 1 1 ; 0 1 0],[1 1 1 ; 1 1 1 ; 1 1 1]); %#ok<NASGU>
                    app.TextMessage('BART compiled with GPU support ...');
                    app.GPUpresentFlag = true;
                catch
                    app.TextMessage('BART compiled without GPU support ...');
                end
            end

            % Elastix
            try
                if ismac || isunix
                    [~, result] = system('echo -n $PATH');
                    if ~contains(result,':/usr/local/bin:/usr/local/lib')
                        result = [result ':/usr/local/bin:/usr/local/lib'];
                        setenv('PATH', result);
                    end
                else
                    [elastixDir, ~] = fileparts(which('elastix.exe'));
                    result = getenv('PATH');
                    if ~contains(result,elastixDir)
                        result = [result ';' elastixDir];
                        setenv('PATH', result);
                    end
                end
            catch
            end

            % Check version
            app.CheckVersionFnc;

            % Start the parallel pool if it is not running already
            try
                app.TextMessage("Starting the parallel pool ...");
                numCores = feature('numcores');
                p = gcp('nocreate');
                if isempty(p)
                    if numCores >= 8
                        parpool(8);
                    else
                        parpool(numCores);
                    end
                end
            catch ME
                app.TextMessage(ME.message);
            end
            
            % OK
            app.SetStatus(0);
            app.SetButtons([1,0,0,0,0,0,0,1]);
            app.TextMessage("Ready ...");

        end

        % Selection changed function: ColorsButtonGroup
        function ColorsButtonGroupSelectionChanged(app, event)

            selectedButton = app.ColorsButtonGroup.SelectedObject;

            switch lower(selectedButton.Text)

                % COLORS:           backgroundColor, fontColor, boxColor, gaugeColor, gaugeBackgroundColor, figureColor, movieFigureColor, gridColor, autoRecoColor, manualRecoColor

                case "classic"

                    guiColors = {app.guiGrey, app.guiBlack, app.guiDarkGrey, app.blue, app.guiWhite, app.guiWhite, app.guiMiddleGrey, app.guiDarkGrey, app.lightblue, app.guiGrey};

                case "black/white"

                    guiColors = {app.guiBlack, app.guiWhite, app.guiDarkGrey, app.blue, app.guiDarkGrey, app.guiGrey, app.guiDarkGrey, app.guiDarkGrey, app.guiBlackBlue, app.guiBlack};

            end

            app.SetAppColorsFcn(guiColors);

        end

        % Button pushed function: LoadMRDfileButton
        function LoadMRDfileButtonPushed(app, event)
            
            % Load the inversion recovery T1 data file
            
            % Indicate busy
            app.SetLights([0,0,0,0,0,0,0]);
            app.SetButtons([0,0,0,0,0,0,0,0]);
            app.SetFitButtons(0);
            app.SetImageSelectionButtons(0);
            app.SetSegmentationButtons(0);
            app.SetMapButtons(0);
            app.SetStatus(3);
            
            % check standard smis directory first if platform is pc, only 1x per session
            if ispc
                try
                    folder_name = winqueryreg('HKEY_LOCAL_MACHINE','Software\MRS\MRSClinicalUI\Scan','MrdDirectory');
                    folder_name = [folder_name,filesep];
                    app.TextMessage('MRD directory found in Windows registry ...');
                    app.TextMessage(strcat("MRD directory = ",folder_name," ..."));
                catch 
                    app.TextMessage('Trying standard MRD directory ...');
                    folder_name = 'c:\smis\dev\MRD\';
                end
                if exist(folder_name, 'dir') && app.mrdFlag
                    app.mrdImportPath = folder_name;
                    app.mrdFlag = false;
                end
            end

            try

                % Reset flags
                app.validDataFlag = false;
                app.validRecoFlag = false;
                app.validRegFlag = false;
                app.validFitFlag = false;
                app.multiCoilFlag = false;

                % Select a MRD file
                fg = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetfile in front
                [mrdfile,app.mrdImportPath] = uigetfile(strcat(app.mrdImportPath,filesep,'*.MRD'));
                delete(fg);
                figure(app.T1mappUIFigure);

                if ~ischar(mrdfile)
                    ME = MException('TLL1mapp:UserCancel','User cancel ...');
                    throw(ME);
                end

                % MRD file selected
                app.dataFile = [app.mrdImportPath,mrdfile];
                mrdstart = extractBefore(mrdfile,'_');
                mrdfile = [app.mrdImportPath,mrdfile];
                flist = dir(fullfile(app.mrdImportPath,[mrdstart,'*.MRD']));

                % check wether the filelist contains already a reconstructed MRD file, remove this one from the list
                if length(flist) > 1
                    for fn = length(flist):-1:1
                        if contains(flist(fn).name,'retro') || contains(flist(fn).name,'p2roud')
                            flist(fn) = [];
                        end
                    end
                end

                if isempty(flist)
                    ME = MException('TLL1mapp:NoValidFile','ERROR: Please select a valid Look-Locker data file ...');
                    throw(ME);
                end

                % check for mulit-receiver-coil data
                app.nrCoils = length(flist);
                if app.nrCoils > 1
                    app.multiCoilFlag = true;
                    app.TextMessage('Multi receiver coil data detected ...');
                end

                % load the data
                app.TextMessage('Loading k-space data ...');
                data1 = {};
                for coil = 1:app.nrCoils
                    app.TextMessage(strcat("Loading coil ",num2str(coil)," ..."));
                    [data1{coil},app.dimensions,app.parameters] = Get_mrd_3D4(fullfile(flist(coil).folder,flist(coil).name),'seq','cen'); %#ok<AGROW>
                end

                % Check for valid file
                app.dataType = "ND";
                if isfield(app.parameters,'NO_ECHOES') && isfield(app.parameters,'prep_on') && isfield(app.parameters,'ti')
                    if app.parameters.NO_ECHOES > 1 && app.parameters.prep_on == 1 && app.parameters.ti > 0
                        app.TextMessage('Multiple inversion-time images detected ...');
                        app.DatafileEditField.Value = mrdfile;
                        app.dataType = "IR";
                    end
                end

                if isfield(app.parameters,'TR_array_size') && isfield(app.parameters,'TR_array')
                    if app.parameters.TR_array_size > 1
                        app.TextMessage('Multiple repetition-time images detected ...');
                        app.DatafileEditField.Value = mrdfile;
                        app.dataType = "SR";
                    end
                end

                if app.dataType == "ND"
                    ME = MException('TLL1mapp:NoValidFile','ERROR: Please select a valid data file ...');
                    throw(ME);
                end
            
                switch app.dataType

                    case "IR"

                        % Data = (echoes, dimx, dimy, dimz, dynamics)
                        app.data = {};
                        for coil = 1:app.nrCoils

                            if ndims(data1{coil}) == 3 && app.parameters.EXPERIMENT_ARRAY == 1
                                % Single slice, single dynamic data
                                app.data{coil}(:,:,:,1,1) = permute(data1{coil}(:,:,:),[1 3 2]);

                            elseif ndims(data1{coil}) == 4 && app.parameters.EXPERIMENT_ARRAY > 1
                                % Single slice, multi dynamic data
                                app.data{coil}(:,:,:,1,:) = permute(data1{coil}(:,:,:,:),[2 4 3 1]);

                            elseif ndims(data1{coil}) == 4 && app.parameters.EXPERIMENT_ARRAY == 1
                                % Multi slice data, single dynamic data
                                app.data{coil}(:,:,:,:,1) = permute(data1{coil},[1,4,3,2]);

                            elseif ndims(data1{coil}) == 5 && app.parameters.EXPERIMENT_ARRAY > 1
                                % Multi slice data, single dynamic data
                                app.data{coil}(:,:,:,:,:) = permute(data1{coil},[2,5,4,3,1]);

                            else
                                ME = MException('TLL1mapp:NoValidFile','ERROR: Please select a valid Look-Locker data file ...');
                                throw(ME);
                            end

                        end

                        % Segmented k-space
                        if isfield(app.parameters,'lines_per_segment')
                            if app.parameters.lines_per_segment > 1
                                app.TextMessage('Segmented k-space ... ')
                                for coil=1:app.nrCoils
                                    app.data{coil} = sort2DsegmKspaceMRD(app.data{coil},app.parameters,app.ToggleReorderCheckBox.Value);
                                end
                            end
                        end

                    case "SR"

                        % Data = (TRs, dimx, dimy, dimz, dynamics)
                        app.data = {};
                        for coil = 1:app.nrCoils

                            if ndims(data1{coil}) == 3
                                % Single slice, single dynamic data
                                app.data{coil}(:,:,:,1,1) = permute(data1{coil}(:,:,:),[1 3 2]);
                        
                            elseif ndims(data1{coil}) == 4 
                                % Multi slice data
                                app.data{coil}(:,:,:,:,1) = permute(data1{coil},[1,4,3,2]);

                            else
                                ME = MException('TLL1mapp:NoValidFile','ERROR: Please select a valid saturation-recovery data file ...');
                                throw(ME);
                            end

                            app.data{coil} = flip(app.data{coil},2);
                            app.parameters.NO_ECHOES = 1;
                            app.parameters.ti = 0;
                            app.parameters.lines_per_segment = 0;

                        end

                        % Segmented k-space
                        if isfield(app.parameters,'VIEWS_PER_SEGMENT')
                            if app.parameters.VIEWS_PER_SEGMENT > 1
                                app.TextMessage('Segmented k-space ... ')
                                app.parameters.rtable = [];
                                tableFilename{1} = strcat(app.mrdImportPath,filesep,'rtable.rtv');
                                tableFilename{2} = strcat(app.mrdImportPath,filesep,'Recon_001',filesep,'rtable.rtv');
                                for fc = 1:length(tableFilename)
                                    if isfile(tableFilename{fc})
                                        app.parameters.rtable = tableFilename{fc};
                                        break;
                                    end
                                end
                                for coil = 1:app.nrCoils
                                    app.data{coil} = sort2DsegmKspaceMRD(app.data{coil},app.parameters,0);
                                end
                            end
                        end

                end

                % Orientation
                if isfield(app.parameters,'PHASE_ORIENTATION')
                    if app.parameters.PHASE_ORIENTATION
                        app.TextMessage('INFO: phase orientation = 1 / horizontal ... ');
                    else
                        app.TextMessage('INFO: phase orientation = 0 / vertical ... ')
                    end
                end

                % 3D data not supported
                if isfield(app.parameters,'NO_VIEWS_2')
                    if app.parameters.NO_VIEWS_2 > 1
                        ME = MException('TLL1mapp:NoValidFile','ERROR: 3D data currently not supported ...');
                        throw(ME);
                    end
                end

                % Success
                app.validDataFlag = true;
                app.SetParameters;
                app.RecoImagesButtonPushed;

            catch ME

                % Failure
                app.TextMessage(ME.message);
                app.mrdImportPath = '';
                app.validDataFlag = false;
                app.SetFitButtons(0);
                app.SetLights([0,0,0,0,0,0,0]);
                app.SetButtons([1,0,0,0,0,0,0,1]);
                app.SetStatus(2);

            end

        end

        % Button pushed function: RecoImagesButton
        function RecoImagesButtonPushed(app, event)
            
            if app.validDataFlag

                % Indicate busy
                app.SetLights([1,0,0,0,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);
                app.TabGroup2.SelectedTab = app.RawImagesTab;

                % reset some parameters
                app.SlicesEditField1.Value = 1;
                app.SlicesSlider1.Value = 1;
                app.InversionEditField.Value = 1;
                app.InversionSlider.Value = 1;
                if app.dataType == "SR"
                    app.InversionEditField.Value = round(app.nir/2);
                    app.InversionSlider.Value = round(app.nir/2);
                end
                app.thresholdEditField.Value = 1;
                app.ThresholdSlider.Value = 1;
                app.SlicesEditField2.Value = 1;
                app.SlicesSlider2.Value = 1;
                app.SlicesEditField3.Value = 1;
                app.SlicesSlider3.Value = 1;
                app.DynamicsEditField1.Value = 1;
                app.DynamicsEditField2.Value = 1;
                app.DynamicsEditField3.Value = 1;
                app.DynamicsSlider1.Value = 1;
                app.DynamicsSlider2.Value = 1;
                app.DynamicsSlider3.Value = 1;

                % reset m0 and t1map
                app.m0map = zeros(app.dimx/app.oversample,app.dimy,app.ns,app.nd);
                app.t1map = zeros(app.dimx/app.oversample,app.dimy,app.ns,app.nd);
                app.r2map = zeros(app.dimx/app.oversample,app.dimy,app.ns,app.nd);
                app.mask = ones(app.dimx/app.oversample,app.dimy,app.ns,app.nd);

                try

                    if app.bartDetectedFlag && (app.CSrecoCheckBox.Value == 1)

                        % Perform the BART compressed sensing reconstruction
                        app.TextMessage('CS reconstruction ...');

                        app.images = CSreco(app);

                    else

                        % Perform regular FFT reconstruction
                        app.TextMessage('FFT reconstruction ...');

                        app.images = FFTreco(app);

                    end

                    % Readout oversampling
                    if isfield(app.parameters,"oversample")
                        if app.parameters.oversample == 1
                            app.TextMessage('Readout oversampling ...');
                            os = round(size(app.images,2)/4);
                            app.images = app.images(:,os+1:end-os,:,:,:);
                        end
                    end

                    % Flip with phase_orientation
                    if app.parameters.PHASE_ORIENTATION && app.dataType == "SR"
                        app.images = flip(app.images,2);
                    end

                    if ~app.parameters.PHASE_ORIENTATION && app.dataType == "IR"
                        app.images = flip(app.images,2);
                    end

                    % Denoising
                    if app.DeNoiseCheckBox.Value
                        app.TextMessage('PCA denoising ...');
                        app.images = PCAdenoise(app.images,app.DeNoiseWindowEditField.Value);
                    end

                    % Gibbs ringing suppression
                    if app.DeRingCheckBox.Value
                        app.TextMessage('Gibbs ringing suppression ...');
                        app.images = unRing(app.images);
                    end

                    % Normalize to convenient range
                    app.images = app.images*32767/max(app.images(:));

                    % Success
                    app.TextMessage('Ready ...');
                    app.validRecoFlag = true;
                    app.validRegFlag = false;
                    app.validFitFlag = false;
                    
                    % Show the images
                    PlotImageFig(app);
                    PlotMaps(app);

                    % Reset buttons
                    app.SetLights([1,1,0,0,0,0,0]);
                    app.SetButtons([1,1,1,1,1,0,0,1]);
                    app.SetImageSelectionButtons(1);
                    app.SetFitButtons(1);
                    app.SetStatus(0);

                catch ME

                    % Failure
                    app.TextMessage(ME.message);
                    app.validRecoFlag = false;
                    app.validRegFlag = false;
                    app.validFitFlag = false;
                    app.SetLights([1,0,0,0,0,0,0]);
                    app.SetButtons([1,1,1,1,1,0,0,1]);
                    app.SetStatus(2);

                end

            end
                
        end

        % Value changed function: TI0EditField, TIsEditField
        function TIsEditFieldValueChanged(app, event)
            
            app.ti = app.TI0EditField.Value;
            app.parameters.ti = app.TI0EditField.Value;
            app.esp = app.TIsEditField.Value;
            app.tis = app.ti + app.esp*linspace(0,app.nir-1,app.nir)'; % inversion times
            app.TextMessage(strcat("Inversion times = ",num2str(app.tis')," ..."));
            
        end

        % Value changed function: DynamicsEditField1, InversionEditField, 
        % ...and 1 other component
        function SlicesEditFieldValue1Changed(app, event)
            
            % Adjust the slider
            app.SlicesSlider1.Value = app.SlicesEditField1.Value;
            app.InversionSlider.Value = app.InversionEditField.Value;
            app.DynamicsSlider1.Value = app.DynamicsEditField1.Value;
        
            % Plot the raw image 
            PlotImageFig(app);
            
        end

        % Value changed function: SlicesSlider1
        function SlicesSlider1ValueChanged(app, event)
            
            % Adjust the slices edit field value
            app.SlicesEditField1.Value = round(app.SlicesSlider1.Value);
            
            % Continue with the changed slices edit field callback
            SlicesEditFieldValue1Changed(app, event)
            
        end

        % Value changed function: InversionSlider
        function InversionSliderValueChanged(app, event)
            
            % Adjust the echoes edit field value
            app.InversionEditField.Value = round(app.InversionSlider.Value);
            
            % Continue with the changed slices / echoes edit field callback
            SlicesEditFieldValue1Changed(app, event)
            
        end

        % Value changed function: DynamicsSlider1
        function DynamicsSlider1ValueChanged(app, event)
          
            % Adjust the echoes edit field value
            app.DynamicsEditField1.Value = round(app.DynamicsSlider1.Value);
            
            % Continue with the changed slices / echoes edit field callback
            SlicesEditFieldValue1Changed(app, event)
            
        end

        % Button pushed function: RegisterButton
        function RegisterButtonPushed(app, event)

            if app.validRecoFlag

                % Indicate busy
                app.SetLights([1,1,0,0,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.AbortRegButton.Enable = 'on';
                app.SetMapButtons(0);
                app.SetStatus(3);
            
                try

                    % Register all slices to 1st inversion time image
                    switch app.dataType
                        case "IR"
                            registerImagesIR(app);
                        case "SR"
                            registerImagesSR(app);
                    end

                    % Reset m0 and t1map
                    app.m0map = zeros(app.dimx/app.oversample,app.dimy,app.ns,app.nd);
                    app.t1map = zeros(app.dimx/app.oversample,app.dimy,app.ns,app.nd);
                    app.r2map = zeros(app.dimx/app.oversample,app.dimy,app.ns,app.nd);
                    app.mask = ones(app.dimx/app.oversample,app.dimy,app.ns,app.nd);
                    app.threshold = zeros(1,app.ns,app.nd);

                    % Replot the images
                    PlotImageFig(app);

                    % Success
                    app.SetImageSelectionButtons(1);
                    app.SetFitButtons(1);
                    app.AbortRegButton.Enable = 'off';
                    app.validFitFlag = false;
                    app.SetLights([1,1,1,0,0,0,0]);
                    app.SetButtons([1,1,1,1,0,0,0,1]);
                    app.SetStatus(0);

                catch ME

                    % Failure
                    app.TextMessage(ME.message);

                    app.SetImageSelectionButtons(1);
                    app.SetFitButtons(1);
                    app.AbortRegButton.Enable = 'off';
                    app.validFitFlag = false;
                    app.SetLights([1,1,0,0,0,0,0]);
                    app.SetButtons([1,1,1,1,0,0,0,1]);
                    app.SetStatus(2);
   
                end

            end
            
        end

        % Button pushed function: AbortRegButton
        function AbortRegButtonPushed(app, event)
            
            app.abortRegFlag = true;
            drawnow;

        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)
            
            if app.validRecoFlag
                
                % Indicate busy
                app.SetLights([2,2,2,0,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);
                app.TextMessage('Thresholding images ...');
                
                % Determine automatic threshold using the Otsu method for each slice and each dynamic
                if app.dataType == "IR"
                    refImage = 1;
                else
                    refImage = app.nir;
                end

                for dynamic = 1:app.nd
                    for slice = 1:app.ns
                        maxim = squeeze(app.images(refImage,:,:,slice,dynamic));
                        app.threshold(slice,dynamic) = graythresh(mat2gray(app.images(refImage,:,:,slice,dynamic))) * max(maxim(:));
                    end
                end
                
                % Set the automatic mask for all slices
                app.mask = ones(app.dimx/app.oversample,app.dimy,app.ns,app.nd);
                for dynamic = 1:app.nd
                    for slice = 1:app.ns
                        app.mask(:,:,slice,dynamic) = apply_threshold_t1(squeeze(app.images(refImage,:,:,slice,dynamic)),app.threshold(slice,dynamic));
                    end
                end
                
                % Display the threshold value for the current slice
                app.thresholdEditField.Value = round(app.threshold(app.SlicesEditField1.Value,app.DynamicsEditField1.Value));
                
                % Display the mask in the image
                PlotImageFig(app);
                
                % Indicate busy
                app.SetLights([2,2,2,1,0,0,0]);
                app.SetButtons([1,1,1,1,1,0,0,1]);
                app.SetFitButtons(1);
                app.SetImageSelectionButtons(1);
                app.SetSegmentationButtons(1);
                app.SetMapButtons(0);
                app.SetStatus(0);
                
            end
            
        end

        % Value changed function: thresholdEditField
        function thresholdEditFieldValueChanged(app, event)
            
            % Get the current slice and dynamic
            cslice = app.SlicesEditField1.Value;
            cdynamic = app.DynamicsEditField1.Value;
            
            % Change the value of the threshold for this slice and update the slider
            app.threshold(cslice,cdynamic) = app.thresholdEditField.Value;
            app.ThresholdSlider.Value = app.thresholdEditField.Value;
            
            % Apply the new threshold
            app.mask(:,:,cslice,cdynamic) = apply_threshold_t1(squeeze(app.images(1,:,:,cslice,cdynamic)),app.threshold(cslice,cdynamic));
           
            % Display the result;
            PlotImageFig(app)
            
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            
            % Threshold slider changed
            % Adjust Threshold and call threshold change button
            app.thresholdEditField.Value = round(app.ThresholdSlider.Value);
            thresholdEditFieldValueChanged(app, event);
            
        end

        % Button pushed function: ErodeButton
        function ErodeButtonPushed(app, event)
            
            % Morphological erosion
            for slice = 1:app.ns
                app.mask(:,:,slice,app.DynamicsEditField1.Value) = 1-imerode(1-squeeze(app.mask(:,:,slice,app.DynamicsEditField1.Value)),app.structuringElement2D);
            end

            % Display the result;
            PlotImageFig(app);
            
        end

        % Button pushed function: DilateButton
        function DilateButtonPushed(app, event)
        
            % Morphological dilation
            for slice = 1:app.ns
                app.mask(:,:,slice,app.DynamicsEditField1.Value) = 1-imdilate(1-squeeze(app.mask(:,:,slice,app.DynamicsEditField1.Value)),app.structuringElement2D);
            end

            % Display the result;
            PlotImageFig(app);
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            
            % Morphological dilation
            for slice = 1:app.ns
                app.mask(:,:,slice,app.DynamicsEditField1.Value) = 1-imdilate(1-squeeze(app.mask(:,:,slice,app.DynamicsEditField1.Value)),app.structuringElement2D);
                app.mask(:,:,slice,app.DynamicsEditField1.Value) = 1-imerode(1-squeeze(app.mask(:,:,slice,app.DynamicsEditField1.Value)),app.structuringElement2D);
            end

            % Display the result;
            PlotImageFig(app);
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            
            % Morphological dilation
            for slice = 1:app.ns
                app.mask(:,:,slice,app.DynamicsEditField1.Value) = 1-imopen(1-squeeze(app.mask(:,:,slice,app.DynamicsEditField1.Value)),app.structuringElement2D);
            end

            % Display the result;
            PlotImageFig(app);
            
        end

        % Value changed function: FirstSliceEditField, LastSliceEditField
        function FirstSliceEditFieldValueChanged(app, event)
            
            app.FirstSliceEditField.Value(app.FirstSliceEditField.Value > app.ti) = app.ti;
            app.LastSliceEditField.Value(app.LastSliceEditField.Value > app.ti) = app.ti;

            if app.FirstSliceEditField.Value > app.LastSliceEditField.Value
                app.LastSliceEditField.Value = app.FirstSliceEditField.Value;
            end
            
        end

        % Value changed function: FirstDynamicEditField, 
        % ...and 1 other component
        function FirstDynamicEditFieldValueChanged(app, event)
            
            app.LastDynamicEditField.Value(app.LastDynamicEditField.Value > app.nd) = app.nd;
            app.FirstDynamicEditField.Value(app.FirstDynamicEditField.Value > app.nd) = app.nd;
            
            if app.FirstDynamicEditField.Value > app.LastDynamicEditField.Value
                app.LastDynamicEditField.Value = app.FirstDynamicEditField.Value;
            end
            
        end

        % Button pushed function: FitT1Button
        function FitT1ButtonPushed(app, event)
            
            if app.validRecoFlag

                app.SetLights([2,2,2,2,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(1);
                app.SetStatus(3);
                app.TextMessage('Fitting T1 maps ...');
                app.FitProgressGauge.Value = 0;
                app.validFitFlag = false;
                app.abortFitFlag = false;
                drawnow;

                try

                    % TI selection
                    app.MakeTiArray;
                    nrTIs = length(find(app.tiSelection(1:app.nir) == 1));

                    if nrTIs < 3
                        ME = MException('T1mappR:TIerror','ERROR: Number of TIs or TRs should be at least 3 for fitting ...');
                        throw(ME);
                    end

                    app.TextMessage("T1 map fitting ...");

                    % Progress counter reset
                    cnt = 1;
                    totalNumberOfSteps = (app.LastDynamicEditField.Value-app.FirstDynamicEditField.Value+1)*...
                        (app.LastSliceEditField.Value-app.FirstSliceEditField.Value+1);

                    % Timing parameters
                    app.EstimatedFitTimeViewField.Value = 'Calculating ...';
                    elapsedTime = 0;

                    % For selected dynamics
                    dynamic = app.FirstDynamicEditField.Value - 1;

                    while dynamic < app.LastDynamicEditField.Value && ~app.abortFitFlag

                        dynamic = dynamic + 1;

                        % for selected slices
                        slice = app.FirstSliceEditField.Value - 1;

                        while slice < app.LastSliceEditField.Value && ~app.abortFitFlag

                            slice = slice + 1;

                            tic;

                            app.TextMessage(sprintf("Fitting slice %d of %d ...",slice-app.FirstSliceEditField.Value+1,app.LastSliceEditField.Value-app.FirstSliceEditField.Value+1));
                            app.TextMessage(sprintf("Fitting dynamic %d of %d ...",dynamic-app.FirstDynamicEditField.Value+1,app.LastDynamicEditField.Value-app.FirstDynamicEditField.Value+1));
                            drawnow;

                            switch app.dataType

                                case "IR"

                                    % Do the T1 fitting
                                    if app.bartDetectedFlag && (app.ModelBasedCheckBox.Value == 1)
                                        % Model-based fitting
                                        [app.m0map(:,:,slice,dynamic),app.t1map(:,:,slice,dynamic)] = dothemobaT1fit(app,slice,dynamic);
                                        app.r2map(:,:,slice,dynamic) = zeros(size(squeeze(app.r2map(:,:,slice,dynamic))));
                                    else
                                        % Regular least-squares regression
                                        [app.m0map(:,:,slice,dynamic),app.t1map(:,:,slice,dynamic),app.r2map(:,:,slice,dynamic)] = dotheT1fit_slice(squeeze(app.images(:,:,:,slice,dynamic)), squeeze(app.mask(:,:,slice,dynamic)), app.tis, app.Rsquare.Value, app.tiSelection);
                                    end

                                case "SR"

                                    % Regular least-squares regression
                                    [app.m0map(:,:,slice,dynamic),app.t1map(:,:,slice,dynamic),app.r2map(:,:,slice,dynamic)] = dotheSRT1fit_slice(squeeze(app.images(:,:,:,slice,dynamic)), squeeze(app.mask(:,:,slice,dynamic)), app.tis, app.Rsquare.Value, app.tiSelection);

                            end

                            % Auto scale
                            try
                                scale = round(2.5*mean(nonzeros(abs(app.t1map(:).*app.mask(:)))));
                                scale(scale<5)=5;
                            catch
                                scale = 100;
                            end
                            app.T1ScaleEditField.Value = scale;

                            % Plot the maps thusfar
                            app.validFitFlag = true;
                            PlotMaps(app);
                            app.validFitFlag = false;

                            % Update progresss bar
                            app.FitProgressGauge.Value = round(100 * cnt/totalNumberOfSteps);
                            drawnow;

                            % Update the timing indicator
                            elapsedTime = elapsedTime + toc;
                            estimatedtotaltime = elapsedTime * totalNumberOfSteps / cnt;
                            timeRemaining = estimatedtotaltime * (totalNumberOfSteps - cnt) / totalNumberOfSteps;
                            timeRemaining(timeRemaining<0) = 0;
                            app.EstimatedFitTimeViewField.Value = strcat(datestr(seconds(timeRemaining),'MM:SS')," min:sec"); %#ok<*DATST>
                            drawnow;

                            cnt = cnt + 1;

                        end

                    end

                    % Success
                    app.validFitFlag = true;
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetFitButtons(1);
                    app.SetLights([1,1,2,1,1,0,0]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetStatus(0);

                catch ME

                    % Failure
                    app.TextMessage(ME.message);

                    app.validFitFlag = false;
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetFitButtons(1);
                    app.SetLights([2,2,2,2,1,0,0]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetStatus(2);

                end

            end

        end

        % Button pushed function: AbortFitButton
        function AbortFitButtonPushed(app, event)
            
            app.abortFitFlag = true; 
        
        end

        % Window scroll wheel function: T1mappUIFigure
        function T1MappUIFigureWindowScrollWheel(app, event)

            if app.ns > 1

                verticalScrollCount = event.VerticalScrollCount;

                % Get the Position of the GUI and the position of the mouse
                set(0,'units','pixels');
                guiposition = get(app.T1mappUIFigure,'Position');
                cursorposition = get(0,'PointerLocation');
                Xpos = cursorposition(1)-guiposition(1);

                if Xpos < 820

                    if app.validRecoFlag

                        if verticalScrollCount > 0
                            newslice = app.SlicesEditField1.Value + 1;
                            newslice(newslice>app.SlicesEditField1.Limits(2)) = app.SlicesEditField1.Limits(2);
                        else
                            newslice = app.SlicesEditField1.Value - 1;
                            newslice(newslice<1) = 1;
                        end

                        app.SlicesEditField1.Value = newslice;
                        app.SlicesEditFieldValue1Changed;

                    end

                else

                    if ~app.roiFlag

                        if app.validFitFlag

                            if verticalScrollCount > 0
                                newslice = app.SlicesEditField2.Value + 1;
                                newslice(newslice>app.SlicesEditField2.Limits(2)) = app.SlicesEditField2.Limits(2);
                            else
                                newslice = app.SlicesEditField2.Value - 1;
                                newslice(newslice<1) = 1;
                            end

                            app.SlicesEditField2.Value = newslice;
                            app.SlicesEditField3.Value = newslice;
                            app.SlicesEditField2ValueChanged;
                            app.SlicesEditField3ValueChanged;

                        end

                    end

                end

                drawnow;

            end

        end

        % Value changed function: SlicesEditField2
        function SlicesEditField2ValueChanged(app, event)
            
            % Change the slider value according to the edit number
            app.SlicesSlider2.Value = round(app.SlicesEditField2.Value);
            app.SlicesEditField3.Value = app.SlicesEditField2.Value;
            app.SlicesSlider3.Value = app.SlicesSlider2.Value;
            
            % Plot the T1-map for the new slice
            PlotMaps(app);
            
        end

        % Value changed function: SlicesSlider2
        function SlicesSlider2ValueChanged(app, event)
            
            % Change the Slice nr value according to the slider
            app.SlicesEditField2.Value = round(app.SlicesSlider2.Value);
            app.SlicesEditField3.Value = round(app.SlicesSlider2.Value);
            app.SlicesSlider3.Value = app.SlicesSlider2.Value;
            
            % Plot the T1-map for the new slice
            PlotMaps(app);
            
        end

        % Value changed function: SlicesEditField3
        function SlicesEditField3ValueChanged(app, event)
            
            % Change the slider value according to the edit number
            app.SlicesSlider3.Value = round(app.SlicesEditField3.Value);
            app.SlicesEditField2.Value = app.SlicesEditField3.Value;
            app.SlicesSlider2.Value = app.SlicesSlider3.Value;
            
            % Plot the T1-map for the new slice
            PlotMaps(app);
            
        end

        % Value changed function: SlicesSlider3
        function SlicesSlider3ValueChanged(app, event)
            
            % Change the Slice nr value according to the slider
            app.SlicesEditField3.Value = round(app.SlicesSlider3.Value);
            app.SlicesEditField2.Value = round(app.SlicesSlider3.Value);
            app.SlicesSlider2.Value = app.SlicesSlider3.Value;
            
            % Plot the T1-map for the new slice
            PlotMaps(app);
            
        end

        % Value changed function: DynamicsEditField2
        function DynamicsEditField2ValueChanged(app, event)
            
            % Change the dynamics values according to the edit number
            app.DynamicsSlider2.Value = app.DynamicsEditField2.Value;
            app.DynamicsSlider3.Value = app.DynamicsEditField2.Value;
            app.DynamicsEditField3.Value = app.DynamicsEditField2.Value;
            
            % Plot the T1-map for the new dynamic
            PlotMaps(app);
            
        end

        % Value changed function: DynamicsSlider2
        function DynamicsSlider2ValueChanged(app, event)
            
            % Change the dynamics nr value according to the slider
            app.DynamicsEditField2.Value = round(app.DynamicsSlider2.Value);
            app.DynamicsEditField3.Value = round(app.DynamicsSlider2.Value);
            app.DynamicsSlider3.Value = app.DynamicsSlider2.Value;
            
            % Plot the T1-map for the new dynamic
            PlotMaps(app);
            
        end

        % Value changed function: DynamicsEditField3
        function DynamicsEditField3ValueChanged(app, event)
                        
            % Change the dynamics values according to the edit number
            app.DynamicsSlider3.Value = app.DynamicsEditField3.Value;
            app.DynamicsSlider2.Value = app.DynamicsEditField3.Value;
            app.DynamicsEditField2.Value = app.DynamicsEditField3.Value;
            
            % Plot the T1-map for the new dynamic
            PlotMaps(app);
            
        end

        % Value changed function: DynamicsSlider3
        function DynamicsSlider3ValueChanged(app, event)
                        
            % Change the dynamics nr value according to the slider
            app.DynamicsEditField3.Value = round(app.DynamicsSlider3.Value);
            app.DynamicsEditField2.Value = round(app.DynamicsSlider3.Value);
            app.DynamicsSlider2.Value = app.DynamicsSlider3.Value;
            
            % Plot the T1-map for the new dynamic
            PlotMaps(app);
            
        end

        % Value changed function: T1ScaleEditField
        function T1ScaleEditFieldValueChanged(app, event)
            
            % Plot the T1 map with the new scale
            PlotMaps(app);
            
        end

        % Button pushed function: T1ScaleAutoButton
        function T1ScaleAutoButtonPushed(app, event)
            
            % Estimate a suitable scale for the T1 map
            % Auto-scale = 2 x mean of the T1 values in the image
            scale = round(2*mean(nonzeros(squeeze(app.t1map(:,:,app.SlicesEditField2.Value,app.DynamicsEditField2.Value))))); 
            scale = 100*ceil(scale/100);
            scale(isnan(scale)) = 2500;
            scale(scale>5000) = 5000;
            scale(scale<5)=5;

            app.T1ScaleEditField.Value = scale;
            
            % Plot the map
            PlotMaps(app);
            
        end

        % Selection changed function: T1ColormapButtonGroup
        function T1ColormapButtonGroupSelectionChanged2(app, event)
            
            if app.grayButton.Value == 1 
                app.t1cmap = gray; 
            end
            
            if app.autumnButton.Value == 1
                app.t1cmap = autumn; 
            end
            
            if app.winterButton.Value == 1
                app.t1cmap = winter; 
            end
            
            if app.parulaButton.Value == 1
                app.t1cmap = parula; 
            end
            
            if app.lipariButton.Value == 1
                app.t1cmap = lipariColorMap(); 
            end
            
            % Background should always be black
            app.t1cmap(1,:) = [0 0 0];
            
            % Plot the T1 map with the new colormap
            PlotMaps(app);
            
        end

        % Selection changed function: M0ColorMapButtonGroup
        function M0ColorMapButtonGroupSelectionChanged(app, event)
            
            if app.grayButton2.Value == 1 
                app.m0cmap = gray; 
            end
            
            if app.autumnButton2.Value == 1
                app.m0cmap = autumn; 
            end
            
            if app.winterButton2.Value == 1
                app.m0cmap = winter; 
            end
            
            if app.parulaButton2.Value == 1
                app.m0cmap = parula; 
            end
            
            if app.lipariButton2.Value == 1
                app.m0cmap = lipariColorMap(); 
            end
            
            % Background should always be black
            app.m0cmap(1,:) = [0 0 0];
            
            % Plot the M0 map with the new colormap
            PlotMaps(app);
            
        end

        % Selection changed function: R2ColorMapButtonGroup
        function R2ColorMapButtonGroupSelectionChanged(app, event)
              
            if app.grayButton3.Value == 1 
                app.r2cmap = gray; 
            end
            
            if app.autumnButton3.Value == 1
                app.r2cmap = autumn; 
            end
            
            if app.winterButton3.Value == 1
                app.r2cmap = winter; 
            end
            
            if app.parulaButton3.Value == 1
                app.r2cmap = parula; 
            end
            
            if app.lipariButton3.Value == 1
                app.r2cmap = lipariColorMap(); 
            end
            
            % Background should always be black
            app.r2cmap(1,:) = [0 0 0];
            
            % Plot the R2 map with the new colormap
            PlotMaps(app);
            
            
        end

        % Image clicked function: T1Image
        function T1ImageClicked(app, event)

            if app.validFitFlag

                % Get the Position of the GUI and the position of the mouse click
                set(0,'units','pixels');
                guiposition = get(app.T1mappUIFigure,'Position');
                cursorposition = get(0,'PointerLocation');

                % Position of the cursor click in the image window
                imagepos = [862,412]; % position of bottom-left corner of image in the app (862,412)
                Xpos = cursorposition(1)-guiposition(1)-imagepos(1);
                Ypos = cursorposition(2)-guiposition(2)-imagepos(2);

                % Image aspect ratio
                if app.parameters.PHASE_ORIENTATION
                    aratio = app.AspectRatioViewField.Value;
                    xsize = app.dimy;
                    ysize = app.dimx/app.oversample;
                else
                    aratio = 1/(app.AspectRatioViewField.Value);
                    xsize = app.dimx;
                    ysize = app.dimy/app.oversample;
                end

                % Correct for non-square image
                imsize = 313;
                if aratio >= 1
                    xcor = (Xpos/imsize) * xsize;
                    ycor = (Ypos/imsize) * ysize * aratio - (0.5 * (aratio-1) * ysize);
                else
                    xcor = (Xpos/imsize) * xsize * (1/aratio) - (0.5 * ((1/aratio)-1) * xsize);
                    ycor = (Ypos/imsize) * ysize;
                end

                % Round to nearest integer
                xcor = round(xcor);
                ycor = round(ycor);

                % Current slice
                zcor = app.SlicesEditField2.Value;

                % Keep the values within matrix boundaries
                xcor(xcor<1) = 1;
                ycor(ycor<1) = 1;
                xcor(xcor>xsize) = xsize;
                ycor(ycor>ysize) = ysize;
                app.XEditField.Value = xcor;
                app.YEditField.Value = ycor;

                % ROI or cross-hair display
                if app.roiFlag

                    % Include points within Radius
                    se = strel("disk",app.BrushEditField.Value-1);
                    [nzrow,nzcol] = find(se.Neighborhood==1);
                    nzc = [nzrow,nzcol] - [app.BrushEditField.Value-1,app.BrushEditField.Value-1];

                    % Paint ROI
                    for i = 1:size(nzc,1)
                        if (xcor+nzc(i,1)>0) && (ycor+nzc(i,2)>0) && (xcor+nzc(i,1)<=xsize) && (ycor+nzc(i,2)<=ysize)
                            app.roiPoints = [app.roiPoints; [xcor+nzc(i,1) ycor+nzc(i,2) zcor]];
                        end
                    end
                    app.roiPoints = unique(app.roiPoints,'rows');
                    zColumn = app.roiPoints(:,3);
                    plotPoints = app.roiPoints(zColumn==zcor, :);
                    app.PlotPoint(plotPoints(:,1),plotPoints(:,2),ysize);

                else

                    % Plot a cross
                    app.roiPoints = [xcor ycor zcor];
                    app.PlotCross(app.roiPoints(1),app.roiPoints(2),ysize);

                end

                % Show the resulting T1 values and fits
                app.CalculateRoiT1;

            end
               
        end

        % Image clicked function: M0Image
        function M0ImageClicked(app, event)
            
            app.T1ImageClicked;

        end

        % Button pushed function: ROIbutton, ROIbutton2
        function ROIbuttonButtonPushed(app, event)
             
            % Toggle between ROI drawing and cross-hair drawing
             
             if strcmp(app.ROIbutton.Text,'ROI')
                 
                 app.ROIbutton.Text = 'Cross';
                 app.ROIbutton2.Text = 'Cross';
                 app.TextMessage('ROI drawing selected ...');
                 app.EraseButton.Visible = 'on';
                 app.EraseButton2.Visible = 'on';
                 app.BrushEditField.Visible = 'on';
                 app.BrushEditFieldLabel.Visible = 'on';
                 app.BrushEditField2.Visible = 'on';
                 app.BrushEditField2Label.Visible = 'on';
                 app.T1ViewField2Label.Visible = 'on';
                 app.T1ViewField2.Visible = 'on';
                 app.T1PixelValueLabel.Text = 'Pixel averaged';
                 app.T1ROIAveragedLabel.Visible = 'on';
                 app.roiFlag = true;
              
             else  
                 
                 app.ROIbutton.Text = 'ROI';
                 app.ROIbutton2.Text = 'ROI';
                 app.TextMessage('Cross-hair selected ...');
                 app.EraseButton.Visible = 'off';
                 app.EraseButton2.Visible = 'off';
                 app.BrushEditField.Visible = 'off';
                 app.BrushEditFieldLabel.Visible = 'off';
                 app.BrushEditField2.Visible = 'off';
                 app.BrushEditField2Label.Visible = 'off';
                 app.T1ViewField2Label.Visible = 'off';
                 app.T1ViewField2.Visible = 'off';
                 app.T1PixelValueLabel.Text = 'Pixel value';
                 app.T1ROIAveragedLabel.Visible = 'off';
                 app.roiFlag = false;
                 
             end
            
             app.roiPoints = [];
             delete(app.roiT1);
             delete(app.crossT1);
             app.PlotMaps;
             
        end

        % Button pushed function: EraseButton, EraseButton2
        function EraseButtonPushed(app, event)
            
            app.roiPoints = [];
            delete(app.roiT1);
            app.PlotMaps;
            
        end

        % Value changed function: WindowEditField
        function WindowEditFieldValueChanged(app, event)

            app.WindowSlider.Value = app.WindowEditField.Value;
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;

        end

        % Value changed function: LevelEditField
        function LevelEditFieldValueChanged(app, event)

            app.LevelSlider.Value = app.LevelEditField.Value;
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;

        end

        % Value changed function: WindowSlider
        function WindowSliderValueChanged(app, event)

            app.WindowEditField.Value = round(app.WindowSlider.Value);
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;

        end

        % Value changed function: LevelSlider
        function LevelSliderValueChanged(app, event)

            app.LevelEditField.Value = round(app.LevelSlider.Value);
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;

        end

        % Value changed function: AutoLWButton
        function AutoLWButtonValueChanged(app, event)

            if app.AutoLWButton.Value == 1
                app.PlotImageFig;
            end

        end

        % Value changed function: BrushEditField
        function BrushEditFieldValueChanged(app, event)

            app.BrushEditField2.Value = app.BrushEditField.Value;

        end

        % Value changed function: BrushEditField2
        function BrushEditField2ValueChanged(app, event)

            app.BrushEditField.Value = app.BrushEditField2.Value;
            
        end

        % Button pushed function: ExportRoiDataButton
        function ExportRoiDataButtonPushed(app, event)

            try

                if app.validFitFlag && ~isempty(app.roiPoints)

                    app.T1mappUIFigure.Visible = 'off';
                    app.roiExportPath = uigetdir(app.roiExportPath,'Raw data export path ...');
                    app.T1mappUIFigure.Visible = 'on';

                    if ~ischar(app.roiExportPath)
                        app.roiExportPath = '';
                        ME = MException('T1mapp:userCancel','User cancel ...');
                        throw(ME);
                    end
                    
                    rdata = [app.tis app.roiIntDataSigned'];
                    writematrix(rdata,strcat(app.roiExportPath,filesep,'roiData.csv'));
                    app.TextMessage('Exporting image intensities from ROI ...');

                end

                app.SetStatus(0);

            catch ME

                app.TextMessage(ME.message);
                app.SetStatus(2);

            end

        end

        % Button pushed function: ExportDCMButton
        function ExportDCMButtonPushed(app, event)
            
            if app.validFitFlag

                % Indicate busy
                app.SetLights([1,1,2,1,1,0,2]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);

                try

                    % Search for dicom file
                    app.TextMessage('DICOM export ...');

                    % Assuming that MRD and Data directory share the same base-directory
                    % Example: base / MRD / 343 / 8455 /

                    % Base / data / 343 / DICOM / 8455 /
                    dcmSpath{1} = replace(app.mrdImportPath,"MRD","Data");
                    try
                        k = strfind(dcmSpath{1},"Data");
                        fs = strfind(dcmSpath{1}(k:end),filesep);
                        dcmSpath{1}= dcmSpath{1}(1:k+fs(3)-1);
                        dcmSpath{1} = insertAfter(dcmSpath{1},k+fs(2)-1,strcat("DICOM",filesep));
                        dcmIndx{1} = 2;
                    catch
                        dcmSpath{1} = app.mrdImportPath;
                    end

                    % Base / data / 343 / DICOM / 8455 / 1 /
                    dcmSpath{2} = strcat(dcmSpath{1},'1',filesep);
                    dcmIndx{2} = 3;

                    % Base / data / 343 / DICOM / 8455 / 2 /
                    dcmSpath{3} = strcat(dcmSpath{1},'2',filesep);
                    dcmIndx{3} = 3;

                    % Base / data / 343 / DICOM / 8455 / 3 /
                    dcmSpath{4} = strcat(dcmSpath{1},'3',filesep);
                    dcmIndx{4} = 3;

                    % Check if one of the directories contains dicom files
                    dcmExist = false;
                    for i=1:length(dcmSpath)
                        if ~isempty(dir(strcat(dcmSpath{i},"*.dcm")))
                            app.dcmPath = dcmSpath{i};
                            app.TextMessage(strcat("Found DICOM information in ",app.dcmPath, " ..."));
                            filesepIdx = strfind(app.dcmPath, filesep);
                            app.dicomExportPath = app.dcmPath(1:filesepIdx(end-dcmIndx{i}));
                            dcmExist = true;
                            break;
                        end
                    end

                    if dcmExist

                        % Dicom export using Dicom information
                        try
                            % Check if directory is actually writable
                            mkdir(strcat(app.dicomExportPath,filesep,"testdir"));
                            rmdir(strcat(app.dicomExportPath,filesep,"testdir"));
                        catch
                            app.T1mappUIFigure.Visible = 'off';
                            app.dicomExportPath = uigetdir(app.dicomExportPath,'DICOM export path');
                            app.T1mappUIFigure.Visible = 'on';
                            drawnow;
                            if ~ischar(app.dicomExportPath)
                                ME = MException('T1mapp:userCancel','User cancel ...');
                                throw(ME);
                            end
                        end
                        app.TextMessage(strcat("DICOM export directory = ",app.dicomExportPath," ..."));
                        app.TextMessage('Export with DICOM information ...');
                        app.exportDir = export_dicom_t1_dcm(app);

                    else

                        % Ask for dicom export directory
                        app.T1mappUIFigure.Visible = 'off';
                        app.dicomExportPath = uigetdir(app.dicomExportPath,'DICOM export path');
                        app.T1mappUIFigure.Visible = 'on';
                        drawnow;
                        if ~ischar(app.dicomExportPath)
                            ME = MException('T1mapp:userCancel','User cancel ...');
                            throw(ME);
                        end

                        % Dicom export without dicom information
                        app.exportDir = strcat(app.dicomExportPath,filesep,"DICOM",filesep);
                        app.TextMessage(strcat("DICOM export directory = ",app.exportDir," ..."));
                        app.exportDir = export_dicom_t1(app);
                        app.TextMessage('WARNING: Export without DICOM information ...');

                    end

                    % NIFTI export
                    try
                        export_nifti_t1(app);
                    catch ME
                        app.TextMessage(ME.message);
                        app.TextMessage("ERROR: NIfti export failed ...");
                    end


                    % Success
                    app.SetStatus(0);
                    app.SetLights([1,1,2,1,1,1,2]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);


                catch ME

                    % Failure
                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong during DICOM export ...');
                    app.dicomExportPath = '';
                    app.SetStatus(2);
                    app.SetLights([1,1,2,1,1,0,2]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);

                end

            end

        end

        % Button pushed function: ExportGIFButton
        function ExportGIFButtonPushed(app, event)
  
            if app.validFitFlag

                % Indicate busy
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);

                try

                    try
                        gifExportDir = replace(app.mrdImportPath, "MRD", "Data");
                        k = strfind(gifExportDir,"Data");
                        fs = strfind(gifExportDir(k:end),filesep);
                        gifExportDir = gifExportDir(1:k+fs(2)-1);
                        % Check if directory is actually writable
                        mkdir(strcat(gifExportDir,filesep,"testdir"));
                        rmdir(strcat(gifExportDir,filesep,"testdir"));
                    catch
                        app.T1mappUIFigure.Visible = 'off';
                        app.gifExportPath = uigetdir(app.gifExportPath,'GIF export path');
                        app.T1mappUIFigure.Visible = 'on';
                        if ~ischar(app.gifExportPath)
                            ME = MException('T1map:userCancel','User cancel ...');
                            throw(ME);
                        end
                        gifExportDir = app.gifExportPath;
                    end
                    gifExportDir = strcat(gifExportDir,filesep,"GIF",filesep);

                    % Export to animated gif
                    app.TextMessage('GIF export ...');
                    app.TextMessage(strcat("Gif export directory = ",gifExportDir," ..."));

                    export_gif_t1(gifExportDir,app.t1map,app.m0map,app.r2map,app.tag,app.T1ScaleEditField.Value,app.t1cmap,app.m0cmap,app.r2cmap,app.AspectRatioViewField.Value,app.parameters,app.Rsquare.Value);

                    % Success
                    app.TextMessage('GIF export ready ...');
                     app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetLights([1,1,2,1,1,2,1]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetStatus(0);

                catch ME

                    % Failure
                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong during GIF export ...');
                    app.gifExportPath = '';
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetLights([1,1,2,1,1,2,0]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetStatus(2);

                end

            end

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
         
            % Remove subfolders from search path
            if (~isdeployed)
                info = dir(which('T1mappR.mlapp'));
                app.appDnir = info.folder;
                rmpath(genpath(app.appDnir));
                cd(app.oldDir);
            end
            
            % Exit
            app.delete;
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.T1mappUIFigure = uifigure('Visible', 'off');
            app.T1mappUIFigure.Name = 'LLT1mapp';
            app.T1mappUIFigure.Icon = fullfile(pathToMLAPP, 'T1icon.png');
            app.T1mappUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @T1MappUIFigureWindowScrollWheel, true);
            app.MRSImage = uiimage(app.T1mappUIFigure);
            app.TMappingLabel = uilabel(app.T1mappUIFigure);
            app.TMappingLabel.Text = 'T  Mapping';
            app.TulpLogo = uiimage(app.T1mappUIFigure);
            app.T1Label = uilabel(app.T1mappUIFigure);
            app.T1Label.Text = '1';
            app.TabGroup1 = uitabgroup(app.T1mappUIFigure);
            app.DashboardTab = uitab(app.TabGroup1);
            app.DashboardTab.Title = 'Dashboard';
            app.LoadMRDfileButton = uibutton(app.DashboardTab, 'push');
            app.LoadMRDfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMRDfileButtonPushed, true);
            app.LoadMRDfileButton.Enable = 'off';
            app.LoadMRDfileButton.Tooltip = {'Load MRD data'};
            app.LoadMRDfileButton.Text = 'Load MRD';
            app.Lamp1 = uilamp(app.DashboardTab);
            app.RecoImagesButton = uibutton(app.DashboardTab, 'push');
            app.RecoImagesButton.ButtonPushedFcn = createCallbackFcn(app, @RecoImagesButtonPushed, true);
            app.RecoImagesButton.Enable = 'off';
            app.RecoImagesButton.Tooltip = {'Reconstruct images'};
            app.RecoImagesButton.Text = 'Reco images';
            app.Lamp2 = uilamp(app.DashboardTab);
            app.CSrecoCheckBoxLabel = uilabel(app.DashboardTab);
            app.CSrecoCheckBoxLabel.Enable = 'off';
            app.CSrecoCheckBoxLabel.Tooltip = {'Compressed sensing reconstruction'};
            app.CSrecoCheckBoxLabel.Text = 'CS reco';
            app.CSrecoCheckBox = uicheckbox(app.DashboardTab);
            app.CSrecoCheckBox.Tooltip = {'Check for compressed sensing reconstruction'};
            app.CSrecoCheckBox.Enable = 'off';
            app.CSrecoCheckBox.Text = '';
            app.RegProgressLabel = uilabel(app.DashboardTab);
            app.RegProgressLabel.Text = 'Progress';
            app.RegProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.RegProgressGauge.MajorTicks = [0 25 50 75 100];
            app.RegisterButton = uibutton(app.DashboardTab, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterButtonPushed, true);
            app.RegisterButton.Enable = 'off';
            app.RegisterButton.Tooltip = {'Image registration'};
            app.RegisterButton.Text = 'Register';
            app.Lamp3 = uilamp(app.DashboardTab);
            app.RegistrationDropDown = uidropdown(app.DashboardTab);
            app.RegistrationDropDown.Items = {'Translation', 'Rigid', 'Affine', 'B-Spline'};
            app.RegistrationDropDown.Enable = 'off';
            app.RegistrationDropDown.Tooltip = {'Registration image transformation method'};
            app.RegistrationDropDown.Value = 'Affine';
            app.EstimatedRegTimeViewField = uieditfield(app.DashboardTab, 'text');
            app.EstimatedRegTimeViewField.Editable = 'off';
            app.AbortRegButton = uibutton(app.DashboardTab, 'push');
            app.AbortRegButton.ButtonPushedFcn = createCallbackFcn(app, @AbortRegButtonPushed, true);
            app.AbortRegButton.Enable = 'off';
            app.AbortRegButton.Tooltip = {'Abort registration'};
            app.AbortRegButton.Text = 'Abort';
            app.MaskButton = uibutton(app.DashboardTab, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Enable = 'off';
            app.MaskButton.Tooltip = {'Image masking to exclude background pixels'};
            app.MaskButton.Text = 'Mask';
            app.Lamp4 = uilamp(app.DashboardTab);
            app.FitProgressLabel = uilabel(app.DashboardTab);
            app.FitProgressLabel.Text = 'Progress';
            app.FitProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.FitProgressGauge.MajorTicks = [0 25 50 75 100];
            app.FitT1Button = uibutton(app.DashboardTab, 'push');
            app.FitT1Button.ButtonPushedFcn = createCallbackFcn(app, @FitT1ButtonPushed, true);
            app.FitT1Button.Enable = 'off';
            app.FitT1Button.Tooltip = {'Start the T1 fitting'};
            app.FitT1Button.Text = 'Fit T1';
            app.Lamp5 = uilamp(app.DashboardTab);
            app.ModelBasedCheckBoxLabel = uilabel(app.DashboardTab);
            app.ModelBasedCheckBoxLabel.Enable = 'off';
            app.ModelBasedCheckBoxLabel.Tooltip = {'Model-based fitting using the Bart toolbox'};
            app.ModelBasedCheckBoxLabel.Text = 'model-based';
            app.ModelBasedCheckBox = uicheckbox(app.DashboardTab);
            app.ModelBasedCheckBox.Tooltip = {'Check for model-based fitting'};
            app.ModelBasedCheckBox.Enable = 'off';
            app.ModelBasedCheckBox.Text = '';
            app.EstimatedFitTimeViewField = uieditfield(app.DashboardTab, 'text');
            app.EstimatedFitTimeViewField.Editable = 'off';
            app.AbortFitButton = uibutton(app.DashboardTab, 'push');
            app.AbortFitButton.ButtonPushedFcn = createCallbackFcn(app, @AbortFitButtonPushed, true);
            app.AbortFitButton.Enable = 'off';
            app.AbortFitButton.Tooltip = {'Abort the T1 fitting'};
            app.AbortFitButton.Text = 'Abort';
            app.ExportDCMButton = uibutton(app.DashboardTab, 'push');
            app.ExportDCMButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDCMButtonPushed, true);
            app.ExportDCMButton.Enable = 'off';
            app.ExportDCMButton.Tooltip = {'Export to DICOM'};
            app.ExportDCMButton.Text = 'Export DCM';
            app.Lamp6 = uilamp(app.DashboardTab);
            app.ExportGIFButton = uibutton(app.DashboardTab, 'push');
            app.ExportGIFButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGIFButtonPushed, true);
            app.ExportGIFButton.Enable = 'off';
            app.ExportGIFButton.Tooltip = {'Export to GIF'};
            app.ExportGIFButton.Text = 'Export GIF';
            app.Lamp7 = uilamp(app.DashboardTab);
            app.ExitButton = uibutton(app.DashboardTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Enable = 'off';
            app.ExitButton.Tooltip = {'Exit the application'};
            app.ExitButton.Text = 'Exit';
            app.TabGroup2 = uitabgroup(app.T1mappUIFigure);
            app.RawImagesTab = uitab(app.TabGroup2);
            app.RawImagesTab.Title = 'Raw images';
            app.RawDataFig = uiaxes(app.RawImagesTab);
            app.RawDataFig.XTick = [];
            app.RawDataFig.XTickLabelRotationMode = 'manual';
            app.RawDataFig.YTick = [];
            app.RawDataFig.YTickLabelRotationMode = 'manual';
            app.RawDataFig.ZTickLabelRotationMode = 'manual';
            app.ImageLabel = uilabel(app.RawImagesTab);
            app.ImageLabel.Text = 'Image';
            app.RawImage = uiimage(app.RawImagesTab);
            app.ImageSelectionPanel = uipanel(app.RawImagesTab);
            app.ImageSelectionPanel.Title = 'Image selection';
            app.SlicesEditField1Label = uilabel(app.ImageSelectionPanel);
            app.SlicesEditField1Label.Text = 'slice';
            app.SlicesEditField1 = uieditfield(app.ImageSelectionPanel, 'numeric');
            app.SlicesEditField1.Limits = [1 Inf];
            app.SlicesEditField1.ValueDisplayFormat = '%.0f';
            app.SlicesEditField1.ValueChangedFcn = createCallbackFcn(app, @SlicesEditFieldValue1Changed, true);
            app.SlicesEditField1.Tooltip = {'Slice number'};
            app.SlicesEditField1.Value = 1;
            app.SlicesSlider1 = uislider(app.ImageSelectionPanel);
            app.SlicesSlider1.Limits = [1 200];
            app.SlicesSlider1.MajorTicks = [];
            app.SlicesSlider1.ValueChangedFcn = createCallbackFcn(app, @SlicesSlider1ValueChanged, true);
            app.SlicesSlider1.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.SlicesSlider1.Tooltip = {'Slice number slider'};
            app.SlicesSlider1.Value = 1;
            app.InversionEditFieldLabel = uilabel(app.ImageSelectionPanel);
            app.InversionEditFieldLabel.Text = 'inversion';
            app.InversionEditField = uieditfield(app.ImageSelectionPanel, 'numeric');
            app.InversionEditField.Limits = [1 Inf];
            app.InversionEditField.ValueDisplayFormat = '%.0f';
            app.InversionEditField.ValueChangedFcn = createCallbackFcn(app, @SlicesEditFieldValue1Changed, true);
            app.InversionEditField.Tooltip = {'Inversion time number'};
            app.InversionEditField.Value = 1;
            app.InversionSlider = uislider(app.ImageSelectionPanel);
            app.InversionSlider.Limits = [1 200];
            app.InversionSlider.MajorTicks = [];
            app.InversionSlider.ValueChangedFcn = createCallbackFcn(app, @InversionSliderValueChanged, true);
            app.InversionSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.InversionSlider.Tooltip = {'Inversion time number slider'};
            app.InversionSlider.Value = 1;
            app.DynamicsEditField1Label = uilabel(app.ImageSelectionPanel);
            app.DynamicsEditField1Label.Text = 'dynamic';
            app.DynamicsEditField1 = uieditfield(app.ImageSelectionPanel, 'numeric');
            app.DynamicsEditField1.Limits = [1 Inf];
            app.DynamicsEditField1.ValueDisplayFormat = '%.0f';
            app.DynamicsEditField1.ValueChangedFcn = createCallbackFcn(app, @SlicesEditFieldValue1Changed, true);
            app.DynamicsEditField1.Tooltip = {'Dynamic number'};
            app.DynamicsEditField1.Value = 1;
            app.DynamicsSlider1 = uislider(app.ImageSelectionPanel);
            app.DynamicsSlider1.Limits = [1 200];
            app.DynamicsSlider1.MajorTicks = [];
            app.DynamicsSlider1.ValueChangedFcn = createCallbackFcn(app, @DynamicsSlider1ValueChanged, true);
            app.DynamicsSlider1.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.DynamicsSlider1.Tooltip = {'Dynamic number slider'};
            app.DynamicsSlider1.Value = 1;
            app.MaskSettingsPanel = uipanel(app.RawImagesTab);
            app.MaskSettingsPanel.Title = 'Mask settings';
            app.thresholdEditFieldLabel = uilabel(app.MaskSettingsPanel);
            app.thresholdEditFieldLabel.Text = 'threshold';
            app.thresholdEditField = uieditfield(app.MaskSettingsPanel, 'numeric');
            app.thresholdEditField.Limits = [0 Inf];
            app.thresholdEditField.ValueDisplayFormat = '%.0f';
            app.thresholdEditField.ValueChangedFcn = createCallbackFcn(app, @thresholdEditFieldValueChanged, true);
            app.thresholdEditField.Tooltip = {'Mask threshold'};
            app.ThresholdSlider = uislider(app.MaskSettingsPanel);
            app.ThresholdSlider.Limits = [1 16384];
            app.ThresholdSlider.MajorTicks = [];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = [1 1001 2001 3001 4001 5001 6001 7001 8001 9001 10001 11001 12001 13001 14001 15001 16384];
            app.ThresholdSlider.Tooltip = {'Mask threshold'};
            app.ThresholdSlider.Value = 1;
            app.ErodeButton = uibutton(app.MaskSettingsPanel, 'push');
            app.ErodeButton.ButtonPushedFcn = createCallbackFcn(app, @ErodeButtonPushed, true);
            app.ErodeButton.Tooltip = {'Mask erosion'};
            app.ErodeButton.Text = 'Erode';
            app.DilateButton = uibutton(app.MaskSettingsPanel, 'push');
            app.DilateButton.ButtonPushedFcn = createCallbackFcn(app, @DilateButtonPushed, true);
            app.DilateButton.Tooltip = {'Mask dilation'};
            app.DilateButton.Text = 'Dilate';
            app.CloseButton = uibutton(app.MaskSettingsPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Tooltip = {'Mask closing'};
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.MaskSettingsPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Tooltip = {'Mask opening'};
            app.OpenButton.Text = 'Open';
            app.WindowEditFieldLabel = uilabel(app.RawImagesTab);
            app.WindowEditFieldLabel.Text = 'W';
            app.WindowEditField = uieditfield(app.RawImagesTab, 'numeric');
            app.WindowEditField.Limits = [0 Inf];
            app.WindowEditField.ValueDisplayFormat = '%.0f';
            app.WindowEditField.ValueChangedFcn = createCallbackFcn(app, @WindowEditFieldValueChanged, true);
            app.WindowEditField.Tooltip = {'Window'};
            app.LevelEditFieldLabel = uilabel(app.RawImagesTab);
            app.LevelEditFieldLabel.Text = 'L';
            app.LevelEditField = uieditfield(app.RawImagesTab, 'numeric');
            app.LevelEditField.Limits = [0 Inf];
            app.LevelEditField.ValueDisplayFormat = '%.0f';
            app.LevelEditField.ValueChangedFcn = createCallbackFcn(app, @LevelEditFieldValueChanged, true);
            app.LevelEditField.Tooltip = {'Level'};
            app.AutoLWButton = uibutton(app.RawImagesTab, 'state');
            app.AutoLWButton.ValueChangedFcn = createCallbackFcn(app, @AutoLWButtonValueChanged, true);
            app.AutoLWButton.Tooltip = {'Automatic window and level setting'};
            app.AutoLWButton.Text = 'Auto';
            app.AutoLWButton.Value = true;
            app.WindowSlider = uislider(app.RawImagesTab);
            app.WindowSlider.Limits = [0 32768];
            app.WindowSlider.MajorTicks = [];
            app.WindowSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSliderValueChanged, true);
            app.WindowSlider.MinorTicks = [0 2048 4096 6144 8192 10240 12288 14336 16384 18432 20480 22528 24576 26624 28672 30720 32768];
            app.WindowSlider.Tooltip = {'Window'};
            app.WindowSlider.Value = 16384;
            app.LevelSlider = uislider(app.RawImagesTab);
            app.LevelSlider.Limits = [0 32768];
            app.LevelSlider.MajorTicks = [];
            app.LevelSlider.ValueChangedFcn = createCallbackFcn(app, @LevelSliderValueChanged, true);
            app.LevelSlider.MinorTicks = [0 2048 4096 6144 8192 10240 12288 14336 16384 18432 20480 22528 24576 26624 28672 30720 32768];
            app.LevelSlider.Tooltip = {'Level'};
            app.LevelSlider.Value = 16384;
            app.RecoParametersTab = uitab(app.TabGroup2);
            app.RecoParametersTab.Title = 'Reco parameters';
            app.FilteringPanel = uipanel(app.RecoParametersTab);
            app.FilteringPanel.Title = 'Filtering';
            app.DeNoiseCheckBoxLabel = uilabel(app.FilteringPanel);
            app.DeNoiseCheckBoxLabel.Enable = 'off';
            app.DeNoiseCheckBoxLabel.Tooltip = {'PCA denoising filter'};
            app.DeNoiseCheckBoxLabel.Text = 'deNoise';
            app.DeNoiseWindowEditField = uieditfield(app.FilteringPanel, 'numeric');
            app.DeNoiseWindowEditField.Limits = [2 64];
            app.DeNoiseWindowEditField.RoundFractionalValues = 'on';
            app.DeNoiseWindowEditField.ValueDisplayFormat = '%.0f';
            app.DeNoiseWindowEditField.Enable = 'off';
            app.DeNoiseWindowEditField.Tooltip = {'PCA denoising filter width (pixels)'};
            app.DeNoiseWindowEditField.Value = 8;
            app.DeNoiseCheckBox = uicheckbox(app.FilteringPanel);
            app.DeNoiseCheckBox.Tooltip = {'Check for PCA denoising'};
            app.DeNoiseCheckBox.Enable = 'off';
            app.DeNoiseCheckBox.Text = '';
            app.DeNoiseCheckBox.Value = true;
            app.DeRingCheckBoxLabel = uilabel(app.FilteringPanel);
            app.DeRingCheckBoxLabel.Enable = 'off';
            app.DeRingCheckBoxLabel.Tooltip = {'Gibbs ringing suppresion filter'};
            app.DeRingCheckBoxLabel.Text = 'deRing';
            app.DeRingCheckBox = uicheckbox(app.FilteringPanel);
            app.DeRingCheckBox.Tooltip = {'Check to apply Gibbs ringing suppresion filter'};
            app.DeRingCheckBox.Enable = 'off';
            app.DeRingCheckBox.Text = '';
            app.CSregularizationPanel = uipanel(app.RecoParametersTab);
            app.CSregularizationPanel.Title = 'CS regularization';
            app.WVxyzEditFieldLabel = uilabel(app.CSregularizationPanel);
            app.WVxyzEditFieldLabel.Tooltip = {'Wavelet L1-norm regularization in the spatial dimensions'};
            app.WVxyzEditFieldLabel.Text = 'WVxyz';
            app.WVxyzEditField = uieditfield(app.CSregularizationPanel, 'numeric');
            app.WVxyzEditField.Limits = [0 20];
            app.WVxyzEditField.ValueDisplayFormat = '%.4f';
            app.WVxyzEditField.Enable = 'off';
            app.WVxyzEditField.Tooltip = {'Wavelet L1 regularization in spatial domain'};
            app.WVxyzEditField.Value = 0.001;
            app.TVxyzEditFieldLabel = uilabel(app.CSregularizationPanel);
            app.TVxyzEditFieldLabel.Tooltip = {'Total-variation regularization in the spatial dimensions'};
            app.TVxyzEditFieldLabel.Text = 'TVxyz';
            app.TVxyzEditField = uieditfield(app.CSregularizationPanel, 'numeric');
            app.TVxyzEditField.Limits = [0 20];
            app.TVxyzEditField.ValueDisplayFormat = '%.4f';
            app.TVxyzEditField.Enable = 'off';
            app.TVxyzEditField.Tooltip = {'Total Variation regularization in spatial domain'};
            app.TVxyzEditField.Value = 0.001;
            app.LLRxyzEditFieldLabel = uilabel(app.CSregularizationPanel);
            app.LLRxyzEditFieldLabel.Tooltip = {'Locally-Low Rank regularization in the spatial dimensions'};
            app.LLRxyzEditFieldLabel.Text = 'LLRxyz';
            app.LLRxyzEditField = uieditfield(app.CSregularizationPanel, 'numeric');
            app.LLRxyzEditField.Limits = [0 20];
            app.LLRxyzEditField.ValueDisplayFormat = '%.4f';
            app.LLRxyzEditField.Enable = 'off';
            app.LLRxyzEditField.Tooltip = {'Locally-Low Rank regularization in the spatial dimensions'};
            app.LLRxyzEditField.Value = 0.001;
            app.TVtiEditFieldLabel = uilabel(app.CSregularizationPanel);
            app.TVtiEditFieldLabel.Tooltip = {'Total Variation regulartization in TI dimension'};
            app.TVtiEditFieldLabel.Text = 'TVti';
            app.TVtiEditField = uieditfield(app.CSregularizationPanel, 'numeric');
            app.TVtiEditField.Limits = [0 20];
            app.TVtiEditField.ValueDisplayFormat = '%.4f';
            app.TVtiEditField.Enable = 'off';
            app.TVtiEditField.Tooltip = {'Total Variation regularization in TI dimension'};
            app.TVdynEditFieldLabel = uilabel(app.CSregularizationPanel);
            app.TVdynEditFieldLabel.Tooltip = {'Total-variation regularization in the dynamic dimension'};
            app.TVdynEditFieldLabel.Text = 'TVdyn';
            app.TVdynEditField = uieditfield(app.CSregularizationPanel, 'numeric');
            app.TVdynEditField.Limits = [0 20];
            app.TVdynEditField.ValueDisplayFormat = '%.4f';
            app.TVdynEditField.Enable = 'off';
            app.TVdynEditField.Tooltip = {'Total Variation constraint in dynamic dimension'};
            app.FitParametersTab = uitab(app.TabGroup2);
            app.FitParametersTab.Title = 'Fit parameters';
            app.SliceSelectionPanel = uipanel(app.FitParametersTab);
            app.SliceSelectionPanel.Title = 'Slice selection';
            app.FirstSliceEditFieldLabel = uilabel(app.SliceSelectionPanel);
            app.FirstSliceEditFieldLabel.Text = 'first slice';
            app.FirstSliceEditField = uieditfield(app.SliceSelectionPanel, 'numeric');
            app.FirstSliceEditField.Limits = [1 200];
            app.FirstSliceEditField.ValueDisplayFormat = '%.0f';
            app.FirstSliceEditField.ValueChangedFcn = createCallbackFcn(app, @FirstSliceEditFieldValueChanged, true);
            app.FirstSliceEditField.Tooltip = {'FIrst fit slice'};
            app.FirstSliceEditField.Value = 1;
            app.LastSliceEditFieldLabel = uilabel(app.SliceSelectionPanel);
            app.LastSliceEditFieldLabel.Text = 'last slice';
            app.LastSliceEditField = uieditfield(app.SliceSelectionPanel, 'numeric');
            app.LastSliceEditField.Limits = [1 200];
            app.LastSliceEditField.ValueDisplayFormat = '%.0f';
            app.LastSliceEditField.ValueChangedFcn = createCallbackFcn(app, @FirstSliceEditFieldValueChanged, true);
            app.LastSliceEditField.Tooltip = {'Last fit slice'};
            app.LastSliceEditField.Value = 1;
            app.DynamicsSelectionPanel = uipanel(app.FitParametersTab);
            app.DynamicsSelectionPanel.Title = 'Dynamics selection';
            app.FirstDynamicEditFieldLabel = uilabel(app.DynamicsSelectionPanel);
            app.FirstDynamicEditFieldLabel.Text = 'first dynamic';
            app.FirstDynamicEditField = uieditfield(app.DynamicsSelectionPanel, 'numeric');
            app.FirstDynamicEditField.Limits = [1 500];
            app.FirstDynamicEditField.ValueDisplayFormat = '%.0f';
            app.FirstDynamicEditField.ValueChangedFcn = createCallbackFcn(app, @FirstDynamicEditFieldValueChanged, true);
            app.FirstDynamicEditField.Tooltip = {'First fit dynamic'};
            app.FirstDynamicEditField.Value = 1;
            app.LastDynamicEditFieldLabel = uilabel(app.DynamicsSelectionPanel);
            app.LastDynamicEditFieldLabel.Text = 'last dynamic';
            app.LastDynamicEditField = uieditfield(app.DynamicsSelectionPanel, 'numeric');
            app.LastDynamicEditField.Limits = [1 500];
            app.LastDynamicEditField.ValueDisplayFormat = '%.0f';
            app.LastDynamicEditField.ValueChangedFcn = createCallbackFcn(app, @FirstDynamicEditFieldValueChanged, true);
            app.LastDynamicEditField.Tooltip = {'Last fit dynamic'};
            app.LastDynamicEditField.Value = 1;
            app.GoodnessOfFitPanel = uipanel(app.FitParametersTab);
            app.GoodnessOfFitPanel.Title = 'Goodness of fit';
            app.RsquareLabel = uilabel(app.GoodnessOfFitPanel);
            app.RsquareLabel.Text = 'min R-square';
            app.Rsquare = uieditfield(app.GoodnessOfFitPanel, 'numeric');
            app.Rsquare.Limits = [0 1];
            app.Rsquare.ValueDisplayFormat = '%.2f';
            app.Rsquare.Tooltip = {'R-square fitting criterium'};
            app.Rsquare.Value = 0.8;
            app.InversionTimeSelectionPanel = uipanel(app.FitParametersTab);
            app.InversionTimeSelectionPanel.Tooltip = {'Inversion time selection'};
            app.InversionTimeSelectionPanel.Title = 'Inversion time selection';
            app.CheckBox_1 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_1.Text = '1';
            app.CheckBox_1.Value = true;
            app.CheckBox_9 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_9.Text = '9';
            app.CheckBox_9.Value = true;
            app.CheckBox_17 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_17.Text = '17';
            app.CheckBox_17.Value = true;
            app.CheckBox_25 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_25.Text = '25';
            app.CheckBox_25.Value = true;
            app.CheckBox_33 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_33.Text = '33';
            app.CheckBox_33.Value = true;
            app.CheckBox_41 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_41.Text = '41';
            app.CheckBox_41.Value = true;
            app.CheckBox_49 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_49.Text = '49';
            app.CheckBox_49.Value = true;
            app.CheckBox_57 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_57.Text = '57';
            app.CheckBox_57.Value = true;
            app.CheckBox_65 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_65.Text = '65';
            app.CheckBox_65.Value = true;
            app.CheckBox_73 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_73.Text = '73';
            app.CheckBox_73.Value = true;
            app.CheckBox_81 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_81.Text = '81';
            app.CheckBox_81.Value = true;
            app.CheckBox_89 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_89.Text = '89';
            app.CheckBox_89.Value = true;
            app.CheckBox_2 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_2.Text = '2';
            app.CheckBox_2.Value = true;
            app.CheckBox_10 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_10.Text = '10';
            app.CheckBox_10.Value = true;
            app.CheckBox_18 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_18.Text = '18';
            app.CheckBox_18.Value = true;
            app.CheckBox_26 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_26.Text = '26';
            app.CheckBox_26.Value = true;
            app.CheckBox_34 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_34.Text = '34';
            app.CheckBox_34.Value = true;
            app.CheckBox_42 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_42.Text = '42';
            app.CheckBox_42.Value = true;
            app.CheckBox_50 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_50.Text = '50';
            app.CheckBox_50.Value = true;
            app.CheckBox_58 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_58.Text = '58';
            app.CheckBox_58.Value = true;
            app.CheckBox_66 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_66.Text = '66';
            app.CheckBox_66.Value = true;
            app.CheckBox_74 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_74.Text = '74';
            app.CheckBox_74.Value = true;
            app.CheckBox_82 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_82.Text = '82';
            app.CheckBox_82.Value = true;
            app.CheckBox_90 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_90.Text = '90';
            app.CheckBox_90.Value = true;
            app.CheckBox_3 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_3.Text = '3';
            app.CheckBox_3.Value = true;
            app.CheckBox_11 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_11.Text = '11';
            app.CheckBox_11.Value = true;
            app.CheckBox_19 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_19.Text = '19';
            app.CheckBox_19.Value = true;
            app.CheckBox_27 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_27.Text = '27';
            app.CheckBox_27.Value = true;
            app.CheckBox_35 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_35.Text = '35';
            app.CheckBox_35.Value = true;
            app.CheckBox_43 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_43.Text = '43';
            app.CheckBox_43.Value = true;
            app.CheckBox_51 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_51.Text = '51';
            app.CheckBox_51.Value = true;
            app.CheckBox_59 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_59.Text = '59';
            app.CheckBox_59.Value = true;
            app.CheckBox_67 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_67.Text = '67';
            app.CheckBox_67.Value = true;
            app.CheckBox_75 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_75.Text = '75';
            app.CheckBox_75.Value = true;
            app.CheckBox_83 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_83.Text = '83';
            app.CheckBox_83.Value = true;
            app.CheckBox_91 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_91.Text = '91';
            app.CheckBox_91.Value = true;
            app.CheckBox_4 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_4.Text = '4';
            app.CheckBox_4.Value = true;
            app.CheckBox_12 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_12.Text = '12';
            app.CheckBox_12.Value = true;
            app.CheckBox_20 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_20.Text = '20';
            app.CheckBox_20.Value = true;
            app.CheckBox_28 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_28.Text = '28';
            app.CheckBox_28.Value = true;
            app.CheckBox_36 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_36.Text = '36';
            app.CheckBox_36.Value = true;
            app.CheckBox_44 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_44.Text = '44';
            app.CheckBox_44.Value = true;
            app.CheckBox_52 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_52.Text = '52';
            app.CheckBox_52.Value = true;
            app.CheckBox_60 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_60.Text = '60';
            app.CheckBox_60.Value = true;
            app.CheckBox_68 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_68.Text = '68';
            app.CheckBox_68.Value = true;
            app.CheckBox_76 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_76.Text = '76';
            app.CheckBox_76.Value = true;
            app.CheckBox_84 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_84.Text = '84';
            app.CheckBox_84.Value = true;
            app.CheckBox_92 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_92.Text = '92';
            app.CheckBox_92.Value = true;
            app.CheckBox_5 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_5.Text = '5';
            app.CheckBox_5.Value = true;
            app.CheckBox_13 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_13.Text = '13';
            app.CheckBox_13.Value = true;
            app.CheckBox_21 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_21.Text = '21';
            app.CheckBox_21.Value = true;
            app.CheckBox_29 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_29.Text = '29';
            app.CheckBox_29.Value = true;
            app.CheckBox_37 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_37.Text = '37';
            app.CheckBox_37.Value = true;
            app.CheckBox_45 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_45.Text = '45';
            app.CheckBox_45.Value = true;
            app.CheckBox_53 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_53.Text = '53';
            app.CheckBox_53.Value = true;
            app.CheckBox_61 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_61.Text = '61';
            app.CheckBox_61.Value = true;
            app.CheckBox_69 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_69.Text = '69';
            app.CheckBox_69.Value = true;
            app.CheckBox_77 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_77.Text = '77';
            app.CheckBox_77.Value = true;
            app.CheckBox_85 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_85.Text = '85';
            app.CheckBox_85.Value = true;
            app.CheckBox_93 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_93.Text = '93';
            app.CheckBox_93.Value = true;
            app.CheckBox_6 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_6.Text = '6';
            app.CheckBox_6.Value = true;
            app.CheckBox_14 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_14.Text = '14';
            app.CheckBox_14.Value = true;
            app.CheckBox_22 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_22.Text = '22';
            app.CheckBox_22.Value = true;
            app.CheckBox_30 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_30.Text = '30';
            app.CheckBox_30.Value = true;
            app.CheckBox_38 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_38.Text = '38';
            app.CheckBox_38.Value = true;
            app.CheckBox_46 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_46.Text = '46';
            app.CheckBox_46.Value = true;
            app.CheckBox_54 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_54.Text = '54';
            app.CheckBox_54.Value = true;
            app.CheckBox_62 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_62.Text = '62';
            app.CheckBox_62.Value = true;
            app.CheckBox_70 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_70.Text = '70';
            app.CheckBox_70.Value = true;
            app.CheckBox_78 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_78.Text = '78';
            app.CheckBox_78.Value = true;
            app.CheckBox_86 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_86.Text = '86';
            app.CheckBox_86.Value = true;
            app.CheckBox_94 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_94.Text = '94';
            app.CheckBox_94.Value = true;
            app.CheckBox_7 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_7.Text = '7';
            app.CheckBox_7.Value = true;
            app.CheckBox_15 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_15.Text = '15';
            app.CheckBox_15.Value = true;
            app.CheckBox_23 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_23.Text = '23';
            app.CheckBox_23.Value = true;
            app.CheckBox_31 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_31.Text = '31';
            app.CheckBox_31.Value = true;
            app.CheckBox_39 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_39.Text = '39';
            app.CheckBox_39.Value = true;
            app.CheckBox_47 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_47.Text = '47';
            app.CheckBox_47.Value = true;
            app.CheckBox_55 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_55.Text = '55';
            app.CheckBox_55.Value = true;
            app.CheckBox_63 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_63.Text = '63';
            app.CheckBox_63.Value = true;
            app.CheckBox_71 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_71.Text = '71';
            app.CheckBox_71.Value = true;
            app.CheckBox_79 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_79.Text = '79';
            app.CheckBox_79.Value = true;
            app.CheckBox_87 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_87.Text = '87';
            app.CheckBox_87.Value = true;
            app.CheckBox_95 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_95.Text = '95';
            app.CheckBox_95.Value = true;
            app.CheckBox_8 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_8.Text = '8';
            app.CheckBox_8.Value = true;
            app.CheckBox_16 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_16.Text = '16';
            app.CheckBox_16.Value = true;
            app.CheckBox_24 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_24.Text = '24';
            app.CheckBox_24.Value = true;
            app.CheckBox_32 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_32.Text = '32';
            app.CheckBox_32.Value = true;
            app.CheckBox_40 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_40.Text = '40';
            app.CheckBox_40.Value = true;
            app.CheckBox_48 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_48.Text = '48';
            app.CheckBox_48.Value = true;
            app.CheckBox_56 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_56.Text = '56';
            app.CheckBox_56.Value = true;
            app.CheckBox_64 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_64.Text = '64';
            app.CheckBox_64.Value = true;
            app.CheckBox_72 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_72.Text = '72';
            app.CheckBox_72.Value = true;
            app.CheckBox_80 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_80.Text = '80';
            app.CheckBox_80.Value = true;
            app.CheckBox_88 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_88.Text = '88';
            app.CheckBox_88.Value = true;
            app.CheckBox_96 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_96.Text = '96';
            app.CheckBox_96.Value = true;
            app.AppSettingsTab = uitab(app.TabGroup2);
            app.AppSettingsTab.Title = 'App settings';
            app.ColorsPanel = uipanel(app.AppSettingsTab);
            app.ColorsPanel.Title = 'Colors';
            app.ColorsButtonGroup = uibuttongroup(app.ColorsPanel);
            app.ColorsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorsButtonGroupSelectionChanged, true);
            app.ColorsButtonGroup.Tooltip = {'App color scheme selection'};
            app.ClassicButton = uiradiobutton(app.ColorsButtonGroup);
            app.ClassicButton.Text = 'classic';
            app.ClassicButton.Interpreter = 'html';
            app.BlackWhiteButton = uiradiobutton(app.ColorsButtonGroup);
            app.BlackWhiteButton.Text = 'black/white';
            app.BlackWhiteButton.Interpreter = 'html';
            app.BlackWhiteButton.Value = true;
            app.SoundsPanel = uipanel(app.AppSettingsTab);
            app.SoundsPanel.Title = 'Sounds';
            app.AutoSoundsCheckBoxLabel = uilabel(app.SoundsPanel);
            app.AutoSoundsCheckBoxLabel.Tooltip = {'Warning and error sounds on or off'};
            app.AutoSoundsCheckBoxLabel.Text = 'sounds on/off';
            app.AutoSoundsCheckBox = uicheckbox(app.SoundsPanel);
            app.AutoSoundsCheckBox.Tooltip = {'Warning and error sounds on or off'};
            app.AutoSoundsCheckBox.Text = '';
            app.AutoSoundsCheckBox.Value = true;
            app.ImportSettingsPanel = uipanel(app.AppSettingsTab);
            app.ImportSettingsPanel.Title = 'Import settings';
            app.ToggleReorderCheckBoxLabel = uilabel(app.ImportSettingsPanel);
            app.ToggleReorderCheckBoxLabel.Tooltip = {'Check this button if imported images seem not reconstructed properly. This can be caused by a different version of the FLASH sequence.'};
            app.ToggleReorderCheckBoxLabel.Text = 'toggle reorder';
            app.ToggleReorderCheckBox = uicheckbox(app.ImportSettingsPanel);
            app.ToggleReorderCheckBox.Tooltip = {'Check this button if imported images seem not reconstructed properly. This can be caused by a different version of the FLASH sequence.'};
            app.ToggleReorderCheckBox.Text = '';
            app.TabGroup3 = uitabgroup(app.T1mappUIFigure);
            app.T1mapTab = uitab(app.TabGroup3);
            app.T1mapTab.Title = 'T1-map';
            app.T1MapFig = uiaxes(app.T1mapTab);
            app.T1MapFig.XTick = [];
            app.T1MapFig.XTickLabelRotationMode = 'manual';
            app.T1MapFig.YTick = [];
            app.T1MapFig.YTickLabelRotationMode = 'manual';
            app.T1MapFig.ZTickLabelRotationMode = 'manual';
            app.T1linearPlot = uiaxes(app.T1mapTab);
            xlabel(app.T1linearPlot, 'TI')
            ylabel(app.T1linearPlot, 'Signal')
            app.T1linearPlot.XTickLabelRotationMode = 'manual';
            app.T1linearPlot.YTickLabelRotationMode = 'manual';
            app.T1linearPlot.ZTickLabelRotationMode = 'manual';
            app.T1MapLabel = uilabel(app.T1mapTab);
            app.T1MapLabel.Text = 'T1 map';
            app.T1Image = uiimage(app.T1mapTab);
            app.T1Image.ImageClickedFcn = createCallbackFcn(app, @T1ImageClicked, true);
            app.T1ImageSelectionPanel = uipanel(app.T1mapTab);
            app.T1ImageSelectionPanel.Title = 'Image selection';
            app.SlicesEditField2Label = uilabel(app.T1ImageSelectionPanel);
            app.SlicesEditField2Label.Text = 'slice';
            app.SlicesEditField2 = uieditfield(app.T1ImageSelectionPanel, 'numeric');
            app.SlicesEditField2.Limits = [1 Inf];
            app.SlicesEditField2.ValueDisplayFormat = '%.0f';
            app.SlicesEditField2.ValueChangedFcn = createCallbackFcn(app, @SlicesEditField2ValueChanged, true);
            app.SlicesEditField2.Tooltip = {'Slice number'};
            app.SlicesEditField2.Value = 1;
            app.SlicesSlider2 = uislider(app.T1ImageSelectionPanel);
            app.SlicesSlider2.Limits = [1 200];
            app.SlicesSlider2.MajorTicks = [];
            app.SlicesSlider2.ValueChangedFcn = createCallbackFcn(app, @SlicesSlider2ValueChanged, true);
            app.SlicesSlider2.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.SlicesSlider2.Tooltip = {'Slice number slider'};
            app.SlicesSlider2.Value = 1;
            app.DynamicsEditField2Label = uilabel(app.T1ImageSelectionPanel);
            app.DynamicsEditField2Label.Text = 'Dynamic';
            app.DynamicsEditField2 = uieditfield(app.T1ImageSelectionPanel, 'numeric');
            app.DynamicsEditField2.Limits = [1 Inf];
            app.DynamicsEditField2.ValueDisplayFormat = '%.0f';
            app.DynamicsEditField2.ValueChangedFcn = createCallbackFcn(app, @DynamicsEditField2ValueChanged, true);
            app.DynamicsEditField2.Tooltip = {'Dynamic number'};
            app.DynamicsEditField2.Value = 1;
            app.DynamicsSlider2 = uislider(app.T1ImageSelectionPanel);
            app.DynamicsSlider2.Limits = [1 200];
            app.DynamicsSlider2.MajorTicks = [];
            app.DynamicsSlider2.ValueChangedFcn = createCallbackFcn(app, @DynamicsSlider2ValueChanged, true);
            app.DynamicsSlider2.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.DynamicsSlider2.Tooltip = {'Dynamic number slider'};
            app.DynamicsSlider2.Value = 1;
            app.T1ColormapButtonGroup = uibuttongroup(app.T1mapTab);
            app.T1ColormapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @T1ColormapButtonGroupSelectionChanged2, true);
            app.T1ColormapButtonGroup.Title = 'Color map';
            app.T1ScaleMaxLabel = uilabel(app.T1ColormapButtonGroup);
            app.T1ScaleMaxLabel.Text = 'Max';
            app.grayButton = uiradiobutton(app.T1ColormapButtonGroup);
            app.grayButton.Text = 'gray';
            app.grayButton.Interpreter = 'html';
            app.T1ScaleEditField = uieditfield(app.T1ColormapButtonGroup, 'numeric');
            app.T1ScaleEditField.Limits = [1 Inf];
            app.T1ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @T1ScaleEditFieldValueChanged, true);
            app.T1ScaleEditField.Tooltip = {'T1 map scale'};
            app.T1ScaleEditField.Value = 1;
            app.autumnButton = uiradiobutton(app.T1ColormapButtonGroup);
            app.autumnButton.Text = 'autumn';
            app.autumnButton.Interpreter = 'html';
            app.T1ScaleAutoButton = uibutton(app.T1ColormapButtonGroup, 'push');
            app.T1ScaleAutoButton.ButtonPushedFcn = createCallbackFcn(app, @T1ScaleAutoButtonPushed, true);
            app.T1ScaleAutoButton.Tooltip = {'Automatically scale T1 map'};
            app.T1ScaleAutoButton.Text = 'Auto';
            app.winterButton = uiradiobutton(app.T1ColormapButtonGroup);
            app.winterButton.Text = 'winter';
            app.winterButton.Interpreter = 'html';
            app.parulaButton = uiradiobutton(app.T1ColormapButtonGroup);
            app.parulaButton.Text = 'parula';
            app.parulaButton.Interpreter = 'html';
            app.lipariButton = uiradiobutton(app.T1ColormapButtonGroup);
            app.lipariButton.Text = 'lipari';
            app.lipariButton.Interpreter = 'html';
            app.lipariButton.Value = true;
            app.EraseButton = uibutton(app.T1mapTab, 'push');
            app.EraseButton.ButtonPushedFcn = createCallbackFcn(app, @EraseButtonPushed, true);
            app.EraseButton.Visible = 'off';
            app.EraseButton.Tooltip = {'Erase ROI'};
            app.EraseButton.Text = 'Erase';
            app.ROIbutton = uibutton(app.T1mapTab, 'push');
            app.ROIbutton.ButtonPushedFcn = createCallbackFcn(app, @ROIbuttonButtonPushed, true);
            app.ROIbutton.Tooltip = {'Cross-hair / ROI drawing'};
            app.ROIbutton.Text = 'ROI';
            app.BrushEditFieldLabel = uilabel(app.T1mapTab);
            app.BrushEditFieldLabel.Visible = 'off';
            app.BrushEditFieldLabel.Text = 'brush';
            app.BrushEditField = uieditfield(app.T1mapTab, 'numeric');
            app.BrushEditField.Limits = [1 5];
            app.BrushEditField.ValueDisplayFormat = '%.0f';
            app.BrushEditField.ValueChangedFcn = createCallbackFcn(app, @BrushEditFieldValueChanged, true);
            app.BrushEditField.Visible = 'off';
            app.BrushEditField.Tooltip = {'ROI brush size (pixels)'};
            app.BrushEditField.Value = 2;
            app.XEditFieldLabel = uilabel(app.T1mapTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.T1mapTab, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.0f';
            app.XEditField.Editable = 'off';
            app.XEditField.Tooltip = {'X coordinate'};
            app.YEditFieldLabel = uilabel(app.T1mapTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.T1mapTab, 'numeric');
            app.YEditField.ValueDisplayFormat = '%.0f';
            app.YEditField.Editable = 'off';
            app.YEditField.Tooltip = {'Y coordinate'};
            app.T1PixelValueLabel = uilabel(app.T1mapTab);
            app.T1PixelValueLabel.Text = 'pixel value';
            app.T1ViewField1Label = uilabel(app.T1mapTab);
            app.T1ViewField1Label.Text = 'T1 (ms)';
            app.T1ViewField1 = uieditfield(app.T1mapTab, 'text');
            app.T1ViewField1.Editable = 'off';
            app.T1ViewField1.Tooltip = {'T1 time'};
            app.T1ROIAveragedLabel = uilabel(app.T1mapTab);
            app.T1ROIAveragedLabel.Visible = 'off';
            app.T1ROIAveragedLabel.Text = 'ROI averaged';
            app.T1ViewField2Label = uilabel(app.T1mapTab);
            app.T1ViewField2Label.Visible = 'off';
            app.T1ViewField2Label.Text = 'T1 (ms)';
            app.T1ViewField2 = uieditfield(app.T1mapTab, 'text');
            app.T1ViewField2.Editable = 'off';
            app.T1ViewField2.Visible = 'off';
            app.T1ViewField2.Tooltip = {'T1 time'};
            app.ExportRoiDataButton = uibutton(app.T1mapTab, 'push');
            app.ExportRoiDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportRoiDataButtonPushed, true);
            app.ExportRoiDataButton.Tooltip = {'Export T1 curve to CSV file'};
            app.ExportRoiDataButton.Text = 'Export';
            app.M0R2mapTab = uitab(app.TabGroup3);
            app.M0R2mapTab.Title = 'M0 & R^2';
            app.M0MapFig = uiaxes(app.M0R2mapTab);
            app.M0MapFig.XTick = [];
            app.M0MapFig.XTickLabelRotationMode = 'manual';
            app.M0MapFig.YTick = [];
            app.M0MapFig.YTickLabelRotationMode = 'manual';
            app.M0MapFig.ZTickLabelRotationMode = 'manual';
            app.R2MapFig = uiaxes(app.M0R2mapTab);
            app.R2MapFig.XTick = [];
            app.R2MapFig.XTickLabelRotationMode = 'manual';
            app.R2MapFig.YTick = [];
            app.R2MapFig.YTickLabelRotationMode = 'manual';
            app.R2MapFig.ZTickLabelRotationMode = 'manual';
            app.M0MapLabel = uilabel(app.M0R2mapTab);
            app.M0MapLabel.Text = 'M0 map';
            app.M0Image = uiimage(app.M0R2mapTab);
            app.M0Image.ImageClickedFcn = createCallbackFcn(app, @M0ImageClicked, true);
            app.M0ImageSelectionPanel = uipanel(app.M0R2mapTab);
            app.M0ImageSelectionPanel.Title = 'Image selection';
            app.SlicesEditField3Label = uilabel(app.M0ImageSelectionPanel);
            app.SlicesEditField3Label.Text = 'slice';
            app.SlicesEditField3 = uieditfield(app.M0ImageSelectionPanel, 'numeric');
            app.SlicesEditField3.Limits = [1 Inf];
            app.SlicesEditField3.ValueDisplayFormat = '%.0f';
            app.SlicesEditField3.ValueChangedFcn = createCallbackFcn(app, @SlicesEditField3ValueChanged, true);
            app.SlicesEditField3.Tooltip = {'Slice number'};
            app.SlicesEditField3.Value = 1;
            app.SlicesSlider3 = uislider(app.M0ImageSelectionPanel);
            app.SlicesSlider3.Limits = [1 200];
            app.SlicesSlider3.MajorTicks = [];
            app.SlicesSlider3.ValueChangedFcn = createCallbackFcn(app, @SlicesSlider3ValueChanged, true);
            app.SlicesSlider3.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.SlicesSlider3.Tooltip = {'Slice number slider'};
            app.SlicesSlider3.Value = 1;
            app.DynamicsEditField3Label = uilabel(app.M0ImageSelectionPanel);
            app.DynamicsEditField3Label.Text = 'dynamic';
            app.DynamicsEditField3 = uieditfield(app.M0ImageSelectionPanel, 'numeric');
            app.DynamicsEditField3.Limits = [1 Inf];
            app.DynamicsEditField3.ValueDisplayFormat = '%.0f';
            app.DynamicsEditField3.ValueChangedFcn = createCallbackFcn(app, @DynamicsEditField3ValueChanged, true);
            app.DynamicsEditField3.Tooltip = {'Dynamic number'};
            app.DynamicsEditField3.Value = 1;
            app.DynamicsSlider3 = uislider(app.M0ImageSelectionPanel);
            app.DynamicsSlider3.Limits = [1 200];
            app.DynamicsSlider3.MajorTicks = [];
            app.DynamicsSlider3.ValueChangedFcn = createCallbackFcn(app, @DynamicsSlider3ValueChanged, true);
            app.DynamicsSlider3.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.DynamicsSlider3.Tooltip = {'Dynamic number slider'};
            app.DynamicsSlider3.Value = 1;
            app.M0ColorMapButtonGroup = uibuttongroup(app.M0R2mapTab);
            app.M0ColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @M0ColorMapButtonGroupSelectionChanged, true);
            app.M0ColorMapButtonGroup.Title = 'Color map';
            app.grayButton2 = uiradiobutton(app.M0ColorMapButtonGroup);
            app.grayButton2.Text = 'gray';
            app.grayButton2.Interpreter = 'html';
            app.grayButton2.Value = true;
            app.autumnButton2 = uiradiobutton(app.M0ColorMapButtonGroup);
            app.autumnButton2.Text = 'autumn';
            app.autumnButton2.Interpreter = 'html';
            app.winterButton2 = uiradiobutton(app.M0ColorMapButtonGroup);
            app.winterButton2.Text = 'winter';
            app.winterButton2.Interpreter = 'html';
            app.parulaButton2 = uiradiobutton(app.M0ColorMapButtonGroup);
            app.parulaButton2.Text = 'parula';
            app.parulaButton2.Interpreter = 'html';
            app.lipariButton2 = uiradiobutton(app.M0ColorMapButtonGroup);
            app.lipariButton2.Text = 'lipari';
            app.lipariButton2.Interpreter = 'html';
            app.R2MapLabel = uilabel(app.M0R2mapTab);
            app.R2MapLabel.Text = 'R^2 map';
            app.EraseButton2 = uibutton(app.M0R2mapTab, 'push');
            app.EraseButton2.ButtonPushedFcn = createCallbackFcn(app, @EraseButtonPushed, true);
            app.EraseButton2.Visible = 'off';
            app.EraseButton2.Tooltip = {'Erase ROI'};
            app.EraseButton2.Text = 'Erase';
            app.ROIbutton2 = uibutton(app.M0R2mapTab, 'push');
            app.ROIbutton2.ButtonPushedFcn = createCallbackFcn(app, @ROIbuttonButtonPushed, true);
            app.ROIbutton2.Tooltip = {'Cross-hair / ROI drawing'};
            app.ROIbutton2.Text = 'ROI';
            app.R2Image = uiimage(app.M0R2mapTab);
            app.BrushEditField2Label = uilabel(app.M0R2mapTab);
            app.BrushEditField2Label.Visible = 'off';
            app.BrushEditField2Label.Text = 'brush';
            app.BrushEditField2 = uieditfield(app.M0R2mapTab, 'numeric');
            app.BrushEditField2.Limits = [1 5];
            app.BrushEditField2.ValueDisplayFormat = '%.0f';
            app.BrushEditField2.ValueChangedFcn = createCallbackFcn(app, @BrushEditField2ValueChanged, true);
            app.BrushEditField2.Visible = 'off';
            app.BrushEditField2.Tooltip = {'ROI brush size (pixels)'};
            app.BrushEditField2.Value = 2;
            app.R2ColorMapButtonGroup = uibuttongroup(app.M0R2mapTab);
            app.R2ColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @R2ColorMapButtonGroupSelectionChanged, true);
            app.R2ColorMapButtonGroup.Title = 'Color map';
            app.grayButton3 = uiradiobutton(app.R2ColorMapButtonGroup);
            app.grayButton3.Text = 'gray';
            app.grayButton3.Interpreter = 'html';
            app.autumnButton3 = uiradiobutton(app.R2ColorMapButtonGroup);
            app.autumnButton3.Text = 'autumn';
            app.autumnButton3.Interpreter = 'html';
            app.winterButton3 = uiradiobutton(app.R2ColorMapButtonGroup);
            app.winterButton3.Text = 'winter';
            app.winterButton3.Interpreter = 'html';
            app.parulaButton3 = uiradiobutton(app.R2ColorMapButtonGroup);
            app.parulaButton3.Text = 'parula';
            app.parulaButton3.Interpreter = 'html';
            app.lipariButton3 = uiradiobutton(app.R2ColorMapButtonGroup);
            app.lipariButton3.Text = 'lipari';
            app.lipariButton3.Interpreter = 'html';
            app.lipariButton3.Value = true;
            app.TabGroup4 = uitabgroup(app.T1mappUIFigure);
            app.SequenceParametersTab = uitab(app.TabGroup4);
            app.SequenceParametersTab.Title = 'Sequence parameters';
            app.DatafileEditFieldLabel = uilabel(app.SequenceParametersTab);
            app.DatafileEditFieldLabel.Text = 'Datafile';
            app.DatafileEditField = uieditfield(app.SequenceParametersTab, 'text');
            app.DatafileEditField.Editable = 'off';
            app.DatafileEditField.Tooltip = {'Data file name'};
            app.FOVViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.FOVViewFieldLabel.Text = 'FOV (mm)';
            app.FOVViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.FOVViewField.Limits = [0 Inf];
            app.FOVViewField.Editable = 'off';
            app.FOVViewField.Tooltip = {'Field-of-view'};
            app.TR0ViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.TR0ViewFieldLabel.Text = '1st TR (ms)';
            app.TR0ViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.TR0ViewField.Limits = [0 Inf];
            app.TR0ViewField.Editable = 'off';
            app.TR0ViewField.Tooltip = {'First repetition time'};
            app.dimxViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.dimxViewFieldLabel.Text = 'dimx';
            app.dimxViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.dimxViewField.Limits = [0 Inf];
            app.dimxViewField.ValueDisplayFormat = '%.0f';
            app.dimxViewField.Editable = 'off';
            app.dimxViewField.Tooltip = {'Readout matrix size'};
            app.TI0EditFieldLabel = uilabel(app.SequenceParametersTab);
            app.TI0EditFieldLabel.Text = '1st TI (ms)';
            app.TI0EditField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.TI0EditField.Limits = [0 Inf];
            app.TI0EditField.ValueChangedFcn = createCallbackFcn(app, @TIsEditFieldValueChanged, true);
            app.TI0EditField.Editable = 'off';
            app.TI0EditField.Tooltip = {'First inversion time'};
            app.TIsEditFieldLabel = uilabel(app.SequenceParametersTab);
            app.TIsEditFieldLabel.Text = 'last TI (ms)';
            app.TIsEditField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.TIsEditField.Limits = [0 Inf];
            app.TIsEditField.ValueChangedFcn = createCallbackFcn(app, @TIsEditFieldValueChanged, true);
            app.TIsEditField.Editable = 'off';
            app.TIsEditField.Tooltip = {'Last inversion time'};
            app.dimyViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.dimyViewFieldLabel.Text = 'dimy';
            app.dimyViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.dimyViewField.Limits = [0 Inf];
            app.dimyViewField.ValueDisplayFormat = '%.0f';
            app.dimyViewField.Editable = 'off';
            app.dimyViewField.Tooltip = {'Phase-encoding matrix size'};
            app.NrTIsViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.NrTIsViewFieldLabel.Text = '# TIs';
            app.NrTIsViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.NrTIsViewField.Limits = [0 Inf];
            app.NrTIsViewField.ValueDisplayFormat = '%.0f';
            app.NrTIsViewField.Editable = 'off';
            app.NrTIsViewField.Tooltip = {'Number of inversion times'};
            app.NrDynamicsViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.NrDynamicsViewFieldLabel.Text = '# dynamics';
            app.NrDynamicsViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.NrDynamicsViewField.Limits = [0 Inf];
            app.NrDynamicsViewField.ValueDisplayFormat = '%.0f';
            app.NrDynamicsViewField.Editable = 'off';
            app.NrDynamicsViewField.Tooltip = {'Number of dynamics'};
            app.SlicesViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.SlicesViewFieldLabel.Text = '# slices';
            app.SlicesViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.SlicesViewField.Limits = [0 Inf];
            app.SlicesViewField.ValueDisplayFormat = '%.0f';
            app.SlicesViewField.Editable = 'off';
            app.SlicesViewField.Tooltip = {'Number of slices'};
            app.AspectRatioViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.AspectRatioViewFieldLabel.Text = 'aspect ratio';
            app.AspectRatioViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.AspectRatioViewField.Limits = [0.25 4];
            app.AspectRatioViewField.ValueDisplayFormat = '%.2f';
            app.AspectRatioViewField.Editable = 'off';
            app.AspectRatioViewField.Tooltip = {'Image aspect ratio'};
            app.AspectRatioViewField.Value = 1;
            app.SLTViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.SLTViewFieldLabel.Text = 'slice thickness (mm)';
            app.SLTViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.SLTViewField.Limits = [0 Inf];
            app.SLTViewField.Editable = 'off';
            app.SLTViewField.Tooltip = {'Slice thickness'};
            app.TRsEditFieldLabel = uilabel(app.SequenceParametersTab);
            app.TRsEditFieldLabel.Text = 'last TR (ms)';
            app.TRsEditField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.TRsEditField.Limits = [0 Inf];
            app.TRsEditField.Editable = 'off';
            app.TRsEditField.Tooltip = {'Last repetition time'};
            app.NrTRsViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.NrTRsViewFieldLabel.Text = '# TRs';
            app.NrTRsViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.NrTRsViewField.Limits = [0 Inf];
            app.NrTRsViewField.ValueDisplayFormat = '%.0f';
            app.NrTRsViewField.Editable = 'off';
            app.NrTRsViewField.Tooltip = {'Number of repetition times'};
            app.MessageBox = uitextarea(app.T1mappUIFigure);
            app.MessageBox.Editable = 'off';
            app.MessageBox.Tooltip = {'Messages'};
            app.MessageBox.Value = {'Messages ....'};
            app.BackgroundBox = uitextarea(app.T1mappUIFigure);
            app.BackgroundBox.Editable = 'off';
            app.BackgroundBox.Tooltip = {'App status'};
            app.StatusImage = uiimage(app.T1mappUIFigure);
            app.StatusLabel = uilabel(app.T1mappUIFigure);
            app.StatusLabel.Text = 'STATUS';
            app.CreditsLabel = uilabel(app.T1mappUIFigure);
            app.CreditsLabel.Tooltip = {'g.j.strijkers@amsterdamumc.nl'};
            app.CreditsLabel.Text = 'Gustav Strijkers, Amsterdam UMC, Version 1.0.0';
            app.Hyperlink = uihyperlink(app.T1mappUIFigure);
            app.Hyperlink.URL = 'https://github.com/Moby1971/';
            app.Hyperlink.Text = 'GitHub';
            % Show the figure after all components are created
            app.T1mappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = T1mappR

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.T1mappUIFigure)

                % Execute the startup function
                runStartupFcn(app, @StartupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.T1mappUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.T1mappUIFigure)
        end
    end
end